classdef BIND_GUI_3onglets_PresetOKetPluginsOuvreFermeTout < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        BINDGUIFigure                   matlab.ui.Figure
        Menu                            matlab.ui.container.Menu
        SaveMenu                        matlab.ui.container.Menu
        LoadMenu                        matlab.ui.container.Menu
        EnvironnementcompletMenu        matlab.ui.container.Menu
        TripsMenu                       matlab.ui.container.Menu
        SegmentationMenu                matlab.ui.container.Menu
        ExportMenu                      matlab.ui.container.Menu
        PresetdePluginsMenu             matlab.ui.container.Menu
        GuideMenu                       matlab.ui.container.Menu
        bChargement                     matlab.ui.control.Button
        bSegmentation                   matlab.ui.control.Button
        bTraitement                     matlab.ui.control.Button
        Tree                            matlab.ui.container.Tree
        TripRefNode                     matlab.ui.container.TreeNode
        TripsNode                       matlab.ui.container.TreeNode
        TripsSelectNode                 matlab.ui.container.TreeNode
        TripsListNode                   matlab.ui.container.TreeNode
        EventsSituationsNode            matlab.ui.container.TreeNode
        EventsNode                      matlab.ui.container.TreeNode
        SituationsNode                  matlab.ui.container.TreeNode
        DatasNode                       matlab.ui.container.TreeNode
        MetasNode                       matlab.ui.container.TreeNode
        AttributesNode                  matlab.ui.container.TreeNode
        AudioVideoNode                  matlab.ui.container.TreeNode
        ParticipantNode                 matlab.ui.container.TreeNode
        CatalogueNode                   matlab.ui.container.TreeNode
        FonctionsNode                   matlab.ui.container.TreeNode
        meanNode                        matlab.ui.container.TreeNode
        stdNode                         matlab.ui.container.TreeNode
        minNode                         matlab.ui.container.TreeNode
        maxNode                         matlab.ui.container.TreeNode
        NativeothermalabfunctionsNode   matlab.ui.container.TreeNode
        OthermatlabfunctionsintoolboxNode  matlab.ui.container.TreeNode
        accelerationmNode               matlab.ui.container.TreeNode
        crossingLaneAllmNode            matlab.ui.container.TreeNode
        crossingLaneAll2mNode           matlab.ui.container.TreeNode
        crossingLaneBySituationmNode    matlab.ui.container.TreeNode
        DIVmNode                        matlab.ui.container.TreeNode
        DIVv2mNode                      matlab.ui.container.TreeNode
        DMOmNode                        matlab.ui.container.TreeNode
        eventDIVmNode                   matlab.ui.container.TreeNode
        eventTIVmNode                   matlab.ui.container.TreeNode
        firstReactionmNode              matlab.ui.container.TreeNode
        fixationsmNode                  matlab.ui.container.TreeNode
        fixationsAOImNode               matlab.ui.container.TreeNode
        fixitesmNode                    matlab.ui.container.TreeNode
        frequencymNode                  matlab.ui.container.TreeNode
        numberOfSamplesmNode            matlab.ui.container.TreeNode
        numberOfSelfReportsByCLmNode    matlab.ui.container.TreeNode
        numberOfSelfReportsByScenariomNode  matlab.ui.container.TreeNode
        RRintervalsmNode                matlab.ui.container.TreeNode
        situationDurationmNode          matlab.ui.container.TreeNode
        speedJerkmNode                  matlab.ui.container.TreeNode
        SteeringAnglePeakmNode          matlab.ui.container.TreeNode
        steeringAnglesInDegreesmNode    matlab.ui.container.TreeNode
        stopYNmNode                     matlab.ui.container.TreeNode
        successYNmNode                  matlab.ui.container.TreeNode
        timeDetectionmNode              matlab.ui.container.TreeNode
        trackingPerformancemNode        matlab.ui.container.TreeNode
        TRDecelmNode                    matlab.ui.container.TreeNode
        SegmentationNode                matlab.ui.container.TreeNode
        TemporelleNode                  matlab.ui.container.TreeNode
        SimpleNode                      matlab.ui.container.TreeNode
        DoubleNode                      matlab.ui.container.TreeNode
        ConditionnelleNode              matlab.ui.container.TreeNode
        ExportNode                      matlab.ui.container.TreeNode
        SeulNode                        matlab.ui.container.TreeNode
        TousNode                        matlab.ui.container.TreeNode
        bImport                         matlab.ui.control.Button
        bExport                         matlab.ui.control.Button
        panelControl                    matlab.ui.container.Panel
        bReset                          matlab.ui.control.Button
        bSupprimer                      matlab.ui.control.Button
        bVoir                           matlab.ui.control.Button
        panelSeg                        matlab.ui.container.Panel
        bgSeg                           matlab.ui.container.ButtonGroup
        rbVideo                         matlab.ui.control.RadioButton
        rbConditionnelle                matlab.ui.control.RadioButton
        rbTemporelle                    matlab.ui.control.RadioButton
        tsubP                           matlab.ui.container.Panel
        tbtgTable                       matlab.ui.container.ButtonGroup
        ttbEvent                        matlab.ui.control.ToggleButton
        ttbSituation                    matlab.ui.control.ToggleButton
        tddRef                          matlab.ui.control.DropDown
        lt_inf                          matlab.ui.control.Label
        tspnInf1                        matlab.ui.control.Spinner
        lt_sup                          matlab.ui.control.Label
        tspnSup1                        matlab.ui.control.Spinner
        PrsentdansLabel                 matlab.ui.control.Label
        tddSituation                    matlab.ui.control.DropDown
        lt_simple                       matlab.ui.control.Label
        lt_sep1                         matlab.ui.control.Label
        lt_ou                           matlab.ui.control.Label
        lt_sep2                         matlab.ui.control.Label
        lt_double                       matlab.ui.control.Label
        lt_table1                       matlab.ui.control.Label
        tddTable1                       matlab.ui.control.DropDown
        tddAvant                        matlab.ui.control.DropDown
        lt_offset1                      matlab.ui.control.Label
        tspnInf2                        matlab.ui.control.Spinner
        lt_table2                       matlab.ui.control.Label
        tddTable2                       matlab.ui.control.DropDown
        tddApres                        matlab.ui.control.DropDown
        lt_offset2                      matlab.ui.control.Label
        tspnSup2                        matlab.ui.control.Spinner
        tcbRepeter                      matlab.ui.control.CheckBox
        lt_sep3                         matlab.ui.control.Label
        NomdelasituationLabel           matlab.ui.control.Label
        tefNom                          matlab.ui.control.EditField
        tcbAppliquerTrip                matlab.ui.control.CheckBox
        tbCreer                         matlab.ui.control.Button
        lt_sep3_2                       matlab.ui.control.Label
        lt_sep2_2                       matlab.ui.control.Label
        csubP                           matlab.ui.container.Panel
        lc_choix                        matlab.ui.control.Label
        cddChoix                        matlab.ui.control.DropDown
        cbtgTable                       matlab.ui.container.ButtonGroup
        ctbData                         matlab.ui.control.ToggleButton
        ctbEvent                        matlab.ui.control.ToggleButton
        lc_data                         matlab.ui.control.Label
        cddData                         matlab.ui.control.DropDown
        cddCond                         matlab.ui.control.DropDown
        cefNum                          matlab.ui.control.NumericEditField
        cefText                         matlab.ui.control.EditField
        cbPlus                          matlab.ui.control.Button
        cbVider                         matlab.ui.control.Button
        clbVar                          matlab.ui.control.ListBox
        ccbInverser                     matlab.ui.control.CheckBox
        lc_nom                          matlab.ui.control.Label
        cefNom                          matlab.ui.control.EditField
        ccbAppliquerTrip                matlab.ui.control.CheckBox
        cbCreer                         matlab.ui.control.Button
        lc_variable                     matlab.ui.control.Label
        cddVar                          matlab.ui.control.DropDown
        connecteurExemple               matlab.ui.control.Label
        ConnecteurLabel                 matlab.ui.control.Label
        connecteurs                     matlab.ui.control.EditField
        OUButton                        matlab.ui.control.Button
        ETButton                        matlab.ui.control.Button
        SituationLabel                  matlab.ui.control.Label
        cddSituation                    matlab.ui.control.DropDown
        vsubP                           matlab.ui.container.Panel
        vbChargerProtocole              matlab.ui.control.Button
        vbCreerProtocole                matlab.ui.control.Button
        vtaNom                          matlab.ui.control.TextArea
        vbEditerProtocole               matlab.ui.control.Button
        vbLancerCodage                  matlab.ui.control.Button
        FiltrageEventsSituationsButton  matlab.ui.control.Button
        panelTrait                      matlab.ui.container.Panel
        UITable                         matlab.ui.control.Table
        ButtonGroup                     matlab.ui.container.ButtonGroup
        DataButton                      matlab.ui.control.ToggleButton
        SituationButton                 matlab.ui.control.ToggleButton
        VariableDropDownLabel           matlab.ui.control.Label
        VariableDropDown                matlab.ui.control.DropDown
        FonctionDropDownLabel           matlab.ui.control.Label
        FonctionDropDown                matlab.ui.control.DropDown
        NomEditFieldLabel               matlab.ui.control.Label
        NomEditField                    matlab.ui.control.EditField
        AppliquerlaslectionCheckBox     matlab.ui.control.CheckBox
        Button                          matlab.ui.control.Button
        TextArea                        matlab.ui.control.TextArea
        DropDownLabel                   matlab.ui.control.Label
        DropDown                        matlab.ui.control.DropDown
        panelExport                     matlab.ui.container.Panel
        le_donnees                      matlab.ui.control.Label
        elbType                         matlab.ui.control.ListBox
        elbTable                        matlab.ui.control.ListBox
        elbVar                          matlab.ui.control.ListBox
        ebAjouter                       matlab.ui.control.Button
        elbCol                          matlab.ui.control.ListBox
        le_fichier                      matlab.ui.control.Label
        ebUp                            matlab.ui.control.Button
        ebDown                          matlab.ui.control.Button
        ebDelete                        matlab.ui.control.Button
        ls_situation                    matlab.ui.control.Label
        elbSituation                    matlab.ui.control.ListBox
        le_sousEchantillon              matlab.ui.control.Label
        espnSousEchantillon             matlab.ui.control.Spinner
        ebPlus                          matlab.ui.control.Button
        elbFichier                      matlab.ui.control.ListBox
        le_fichiers                     matlab.ui.control.Label
        ebExporter                      matlab.ui.control.Button
        ebExporterTous                  matlab.ui.control.Button
        ebAnnuler                       matlab.ui.control.Button
        ebDestination                   matlab.ui.control.Button
        ebSupprimer                     matlab.ui.control.Button
        ebRenommer                      matlab.ui.control.Button
        ebApercu                        matlab.ui.control.Button
        le_format                       matlab.ui.control.Label
        eddFormat                       matlab.ui.control.DropDown
        ConcatTrip                      matlab.ui.control.CheckBox
        ConcatSituationEvents           matlab.ui.control.CheckBox
        ConcatnationLabel               matlab.ui.control.Label
        Button2                         matlab.ui.control.Button
        PanelFiltrage                   matlab.ui.container.Panel
        listeFiltrage1                  matlab.ui.control.ListBox
        tableFiltrage1                  matlab.ui.control.Table
        label                           matlab.ui.control.Label
        ButtonGroupFiltrage             matlab.ui.container.ButtonGroup
        EventsFiltrageButton            matlab.ui.control.ToggleButton
        SituationsFiltrageButton        matlab.ui.control.ToggleButton
        AppliquerButton                 matlab.ui.control.Button
        listeFiltrage2                  matlab.ui.control.ListBox
        tableFiltrage2                  matlab.ui.control.Table
        Title_liste1                    matlab.ui.control.Label
        Title_liste2                    matlab.ui.control.Label
        Title_table1                    matlab.ui.control.Label
        Title_table2                    matlab.ui.control.Label
        nbpris1                         matlab.ui.control.TextArea
        nbpris2                         matlab.ui.control.TextArea
        cochertousButton                matlab.ui.control.Button
        dcochertousButton               matlab.ui.control.Button
        cochertousButton_2              matlab.ui.control.Button
        dcochertousButton_2             matlab.ui.control.Button
        PanelPluginDispos               matlab.ui.container.Panel
        ListeDispoTable                 matlab.ui.control.Table
        ListBox                         matlab.ui.control.ListBox
        ButtonAjouter                   matlab.ui.control.Button
        panelVisu                       matlab.ui.container.Panel
        tgVisu                          matlab.ui.container.TabGroup
        tabPlugins                      matlab.ui.container.Tab
        lbPlugins                       matlab.ui.control.ListBox
        EnregistrerenpresetButton       matlab.ui.control.Button
        EditField                       matlab.ui.control.EditField
        ChoisirunnomTextAreaLabel       matlab.ui.control.Label
        ChoisirunnomTextArea            matlab.ui.control.TextArea
        tabPreset                       matlab.ui.container.Tab
        VoirPreset                      matlab.ui.control.Button
        lbPreset                        matlab.ui.control.ListBox
        FermerTout                      matlab.ui.control.Button
        aLogoPict                       matlab.ui.control.UIAxes
    end

    % Abrév :
    % subP = Sub-Panel; bg = ButtonGroup; b = Button; dd = DropDown;
    % ef = EditField; spn = Spinner; tg = TabGroup;
    % tdd = temporal Drop Down
    
    properties (Access = public)
        
        filename; caller; lastMainPath = ''; lastEnvPath = ''; date_time = '';
        trip; existTripRef = 0; tripRef; selectedTripNames = {}; selectedTripPaths = {};
        structScnLoad = struct(); structScnList = struct(); structScnSelect = struct();
        
        oldTrip; oldChoix = ''; oldTable = ''; oldTable1 = ''; oldTable2 = ''; tableIsBase; forceIsBase;
        c_ef; create; tc; structCondition = struct(); condMinStep; isUniform = 1;
        
        exportType; exportCols; exportFolder = '';
        structCol = struct(); export = struct(); structFiltrageEvent = struct(); structFiltrageSituation = struct();
        struct_select_liste = struct();
        struct_save = struct();
        %         nomficsave = '';
        envFile = '';
        pauseValue = 0.05;
        protoPath;
        VisuApp;
        
        
         %%ajout Anaïs:
        env=struct();
        presetValueClick;
        presetPathClick;
        magneto;
        
        
    end
    
    methods (Access = private)
        %         -------------------------------------------------------------------------------
        function showControlePanel(app)
            node = app.Tree.SelectedNodes;
            if length(node) <= 1
                app.bVoir.Text = '👁️'; app.bVoir.FontSize = 14;
                app.bReset.Text = '✏️'; app.bReset.FontSize = 14;
                app.bSupprimer.Enable = 'off'; app.bReset.Enable = 'off'; app.bVoir.Enable = 'off';
                CondNone = isempty(node) || node == app.TripRefNode || node == app.FonctionsNode || strcmp(app.Tree.Enable,'off');
                if ~CondNone
                    %% Conditions d'activation des boutons
                    condbSupprimer = node == app.TripsSelectNode && ~isempty(app.TripsSelectNode.Children) || ...
                        node == app.TripsListNode && ~isempty(app.TripsListNode.Children) || ...
                        node == app.TripsNode && or(~isempty(app.TripsSelectNode.Children), ~isempty(app.TripsListNode.Children)) || ...
                        node.Parent.Parent == app.TripsNode || node.Parent.Parent.Parent == app.TripsNode || ...
                        node.Parent.Parent == app.EventsSituationsNode || node.Parent.Parent.Parent == app.EventsSituationsNode || ...
                        node.Parent == app.DatasNode || node.Parent.Parent == app.DatasNode || node.Parent == app.SimpleNode || ...
                        node.Parent == app.DoubleNode || node.Parent == app.ConditionnelleNode || node.Parent == app.SeulNode || ...
                        node.Parent == app.TousNode;
                    condbVoir_preview = node.Parent.Parent == app.EventsSituationsNode || node.Parent == app.DatasNode || ...
                        node.Parent == app.MetasNode;
                    condbReset_edit = node.Parent.Parent == app.EventsSituationsNode || node.Parent.Parent.Parent == app.EventsSituationsNode || ...
                        node.Parent == app.DatasNode || node.Parent.Parent == app.DatasNode;
                    condbVoir_validate = node == app.TripsListNode && ~isempty(app.TripsListNode.Children) || ...
                        node.Parent == app.TripsListNode || node.Parent.Parent == app.TripsListNode || ...
                        node.Parent.Parent == app.TripsSelectNode || node.Parent.Parent == app.FonctionsNode || ...
                        node.Parent == app.SimpleNode || node.Parent == app.DoubleNode ||node.Parent == app.ConditionnelleNode;
                    condbReset_revert = node == app.TripsSelectNode && ~isempty(app.TripsSelectNode.Children) || ...
                        node.Parent.Parent == app.TripsSelectNode && app.existTripRef == 1;
                    cond_execute = node.Parent == app.SimpleNode || node.Parent == app.DoubleNode ||node.Parent == app.ConditionnelleNode || ...
                        node.Parent == app.SeulNode || node.Parent == app.TousNode;
                    %% Application des conditions d'activation des boutons
                    if condbSupprimer, app.bSupprimer.Enable = 'on'; end
                    if condbVoir_preview, app.bVoir.Enable = 'on'; end
                    if condbReset_edit, app.bReset.Enable = 'on'; end
                    if condbVoir_validate, app.bVoir.Text = '✔'; app.bVoir.FontSize = 18; app.bVoir.Enable = 'on'; end
                    if condbReset_revert, app.bReset.Text = '↻'; app.bReset.FontSize = 18; app.bReset.Enable = 'on'; end
                    if cond_execute, app.bVoir.Text = '⚙️'; app.bVoir.FontSize = 18; app.bVoir.Enable = 'on'; end
                    %if cond_execute, app.bvoir = 'okay';app.bVoir.Enable = 'on';end️️️
                end
            end
        end
        
        %         -------------------------------------------------------------------------------
        
        function refreshTripsNode(app)
            emptyTripsNode(app, app.TripsListNode)
            emptyTripsNode(app, app.TripsSelectNode)
            fillTripsNodeFromStruct(app, app.TripsSelectNode, app.structScnSelect);
            fillTripsNodeFromStruct(app, app.TripsListNode, app.structScnList);
            if isempty(app.TripsSelectNode.Children) && isempty(app.TripsListNode.Children)
                app.bChargement.BackgroundColor = [0.0118 0.3412 0.6]; app.bChargement.FontColor = [1 1 1];
                collapse(app.Tree);
                app.Tree.Enable = 'off';
            else
                %node = app.tripRef;
                app.bChargement.BackgroundColor = [1 1 1]; app.bChargement.FontColor = [.01 .34 .6];
                collapse(app.Tree); expand(app.TripsNode,'all');
                app.Tree.Enable = 'on';
            end
            if app.existTripRef
                app.bSegmentation.Enable = 'on'; app.bTraitement.Enable = 'on';
                app.lbPlugins.Enable = 'on';  app.bExport.Enable = 'on';
                %app.lbPreset.Enable = 'on';
            else
                app.bSegmentation.Enable = 'off'; app.bTraitement.Enable = 'off';
                app.lbPlugins.Enable = 'off';  app.bExport.Enable = 'off';
                %app.lbPreset.Enable = 'off';
            end
            showControlePanel(app);
        end
        
        %         -------------------------------------------------------------------------------
        
        function emptyTripsNode(~, node)
            if ~isempty(node.Children)
                for i = 1:length(node.Children)
                    delete(node.Children(1));
                end
            end
        end
        
        %         -------------------------------------------------------------------------------
        
        function fillTripsNodeFromStruct(app, node, struct)
            scn = fieldnames(struct);
            for i=1:length(scn)
                scn_node = uitreenode('Parent', node, 'Text', scn{i});
                trips = fieldnames(struct.(scn{i}));
                for j=1:length(trips)
                    tripPath = struct.(scn{i}).(trips{j});
                    if strcmp(trips{j}, app.TripRefNode.Text)
                        trip_node = uitreenode('Parent', scn_node, 'Text', ['(réf) ' trips{j}], 'Tag', tripPath);
                        instantiateTrip(app, trip_node); % trip_node.Tooltip = {'test'};
                    else
                        uitreenode('Parent', scn_node, 'Text', trips{j}, 'Tag', tripPath);
                        %                         if strcmp(node.Text, 'Sélection') && length(scn) == 1
                        %                             if isempty(app.TripRefNode.Text)
                        %                                 instantiateTrip(app, trip_node);
                        %                             end
                        %                         end
                    end
                end
            end
        end
        
        %         -------------------------------------------------------------------------------
        
        function instantiateTrip(app, node)
            d = uiprogressdlg(app.BINDGUIFigure, 'Message', 'Instanciation du trip de référence'); pause(app.pauseValue);
            
            % Changement du TRIP de référence
            if app.existTripRef
                if exist('app.tripRef', 'var')
                    if not(isempty(app.tripRef.Text))
                        app.oldTrip = app.tripRef.Text;
                    end
                    app.tripRef.Text = app.tripRef.Text(7:end);
                    app.cddData.Items = {''}; app.cddSituation.Items = {''};
                    app.tddRef.Items = {''}; app.tddTable1.Items = {''}; app.tddTable2.Items = {''};
                end
            end
            % Sélection du trip de référence et son instanciation
            app.tripRef = node;
            app.TripRefNode.Text = node.Text;
            refreshTree(app, d);
            
            if ~startsWith(node.Text, '(réf) ')
                node.Text = ['(réf) ', node.Text];
            end
            app.existTripRef = 1;
            
            d.Value = 1; d.Message = 'Instanciation terminée'; pause(app.pauseValue); close(d);
            
            for i = 1:length(app.DatasNode.Children)
                app.cddData.Items{end+1} = app.DatasNode.Children(i).Text;
            end
            
            for i = 1:length(app.SituationsNode.Children)
                app.cddSituation.Items{end+1} = app.SituationsNode.Children(i).Text;
            end
            app.tddRef.Items = {''}; app.tddTable1.Items = {''}; app.tddTable2.Items = {''};
            for i = 1:length(app.EventsNode.Children)
                app.tddRef.Items{end+1} = app.EventsNode.Children(i).Text;
                app.tddTable1.Items{end+1} = app.EventsNode.Children(i).Text;
                app.tddTable2.Items{end+1} = app.EventsNode.Children(i).Text;
            end
        end
        
        %         -------------------------------------------------------------------------------
        
        function showSegmentationPanel(app)
            app.panelTrait.Visible = 'off';
            app.panelSeg.Visible = 'on';
        end
        
        %         -------------------------------------------------------------------------------
        
        function showTraitementPanel(app)
            app.panelSeg.Visible = 'off';
            app.panelTrait.Visible = 'on';
        end
        
        %         -------------------------------------------------------------------------------
        
        function showConditionnellePanel(app)
            % Intervertir entre Text et Numeric
            if length(app.cddCond.Value) > 1
                app.cefNum.Visible = 'off'; app.cefNum.Value = 0;
                app.cefText.Visible = 'on';
                app.c_ef = app.cefText;
            elseif length(app.cddCond.Value) == 1
                app.cefText.Visible = 'off'; app.cefText.Value = '';
                app.cefNum.Visible = 'on';
                app.c_ef = app.cefNum;
            else
                app.cefNum.Visible = 'off'; app.cefNum.Value = 0;
                app.cefText.Visible = 'on'; app.cefText.Value = '';
            end
            
            app.cddVar.Enable = 'off'; app.lc_variable.Enable = 'off';
            app.cddCond.Enable = 'off'; app.c_ef.Enable = 'off'; app.cbPlus.Enable = 'off';
            if ~isempty(app.cddData.Value)
                app.cddVar.Enable = 'on'; app.lc_variable.Enable = 'on';
                if ~isempty(app.cddVar.Value)
                    app.cddCond.Enable = 'on';
                    if ~isempty(app.cddCond.Value)
                        app.c_ef.Enable = 'on'; app.cbPlus.Enable = 'off';
                        if strcmp(app.cddCond.Value, 'NULL') || strcmp(app.cddCond.Value, 'non NULL')
                            app.c_ef.Enable = 'off'; app.c_ef.Value = '';
                        end
                        if ~isempty(app.c_ef.Value) || strcmp(app.cddCond.Value, 'NULL') || strcmp(app.cddCond.Value, 'non NULL')
                            app.cbPlus.Enable = 'on';
                        end
                    end
                else % isempty(app.cddVar.Value)
                    app.cddCond.Value = '';
                end
            else % isempty(app.cddData.Value)
                app.cddVar.Value = ''; app.cddCond.Value = '';
            end
            
            %            if length(app.clbVar.Items)<=1
            %                app.connecteurExemple.Visible = 'off';
            %                app.connecteurs.Visible = 'off';
            %                app.ConnecteurLabel.Visible = 'off';
            %            else
            %                app.connecteurExemple.Visible = 'on';
            %                app.connecteurs.Visible = 'on';
            %                app.ConnecteurLabel.Visible = 'on';
            %            end
        end
        
        %         -------------------------------------------------------------------------------
        
        function showTemporellePanel(app)
            % Cas simple
            if ~isempty(app.tddRef.Value)
                % vérouille toute la partie double
                app.tddTable1.Value = ''; app.tddTable1.Enable = 'off'; app.lt_table1.Enable = 'off';
                app.tddTable2.Value = ''; app.tddTable2.Enable = 'off'; app.lt_table2.Enable = 'off';
                app.tddAvant.Value = ''; app.tddAvant.Enable = 'off';
                app.tddApres.Value = ''; app.tddApres.Enable = 'off';
                app.tspnInf2.Limits = [-Inf Inf]; app.tspnInf2.Value = 0; app.tspnInf2.Enable = 'off'; app.lt_offset1.Enable = 'off';
                app.tspnSup2.Limits = [-Inf Inf]; app.tspnSup2.Value = 0; app.tspnSup2.Enable = 'off'; app.lt_offset2.Enable = 'off';
                app.lt_double.Enable = 'off'; app.tefNom.Value = ''; app.tcbRepeter.Value = 0;
                liminf = app.tspnInf1.Limits(1);
                limsup = app.tspnSup1.Limits(2);
                app.tspnInf1.Value = liminf +1  ; app.tspnSup1.Value = limsup -1;
                app.tspnInf1.Value = 0; app.tspnSup1.Value = 0;
                
                app.tspnInf1.Enable = 'on'; app.lt_inf.Enable = 'on';
                app.tspnSup1.Enable = 'on'; app.lt_sup.Enable = 'on';
                app.ttbEvent.Enable = 'off'; app.ttbSituation.Enable = 'off';
                app.tddSituation.Enable = 'on'; app.PrsentdansLabel.Enable = 'on';
                app.lt_simple.Enable = 'on';
            else
                app.tddTable1.Enable = 'on'; app.lt_table1.Enable = 'on'; app.lt_double.Enable = 'on';
            end
            
            % Cas double
            if ~isempty(app.tddTable1.Value)
                app.ttbEvent.Value = false; app.ttbSituation.Value = false;
                app.tddRef.Value = ''; app.tddRef.Enable = 'off'; % désactive la liste déroulante du simple
                app.tspnInf1.Limits = [-Inf Inf]; app.tspnInf1.Value = 0; app.tspnInf1.Enable = 'off'; app.lt_inf.Enable = 'off'; % désactive le spiner inf de simple
                app.tspnSup1.Limits = [-Inf Inf]; app.tspnSup1.Value = 0; app.tspnSup1.Enable = 'off'; app.lt_sup.Enable = 'off'; % désactive le spiner sup de simple
                app.ttbEvent.Enable = 'off'; app.ttbSituation.Enable = 'off'; %désactive les boutons event et situation
                
                app.tddSituation.Enable = 'on'; app.PrsentdansLabel.Enable = 'off'; %désactive la liste de situations de simple et son titre
                app.lt_simple.Enable = 'off';  app.tefNom.Value = ''; %désactive le mot simple et vide le nom du fichier de la situation
                
                app.tddTable2.Enable = 'on'; app.lt_table2.Enable = 'on'; % active la liste de situations et son tire
                app.tddAvant.Enable = 'on'; % active la deuxieme liste de la première table
                app.tspnInf2.Enable = 'off'; app.lt_offset1.Enable = 'off'; % désactive le spinner et son titre table 1
                app.tspnSup2.Enable = 'off'; app.lt_offset2.Enable = 'off'; % désactive le spinner et son titre table 2
                app.tcbRepeter.Enable = 'off'; % désactive la répétition
                app.lt_double.Enable = 'on'; % active le texte "double" dans simple ou double
                
                if ~isempty(app.tddAvant.Value) % si la deuxieme liste de la premiere table n'est pas vide
                    app.tspnInf2.Enable = 'on'; app.lt_offset1.Enable = 'on'; % active le spinner de la premiere table
                else
                    app.tspnInf2.Limits = [-Inf Inf]; app.tspnInf2.Value = 0; % remet a 0 la valeur du spiner
                end
                
                if ~isempty(app.tddTable2.Value) % si la 1ere liste de la table 2 n'est pas vide
                    app.tddApres.Enable = 'on'; % active la 2eme liste de la table 2
                    if ~isempty(app.tddApres.Value) % si la deuxieme liste de la deuxieme table n'est pas vide
                        app.tspnSup2.Enable = 'on'; app.lt_offset2.Enable = 'on'; app.tcbRepeter.Enable = 'on'; % active le deuxieme spinner et la répétition
                    else
                        app.tspnSup2.Limits = [-Inf Inf]; app.tspnSup2.Value = 0; % remise a 0 du spinner 2
                    end
                else % isempty(app.tddTable2.Value)
                    app.tddApres.Value = ''; app.tspnSup2.Limits = [-Inf Inf]; app.tspnSup2.Value = 0;
                end
            else % isempty(app.tddTable1.Value)
                app.tddRef.Enable = 'on'; app.ttbEvent.Enable = 'on'; app.ttbSituation.Enable = 'on'; app.lt_simple.Enable = 'on';
            end
            
            % Par défaut
            if isempty(app.tddRef.Value) && isempty(app.tddTable1.Value)
                app.tspnInf1.Limits = [-Inf Inf]; app.tspnInf1.Value = 0;  app.tspnInf1.Enable = 'off'; app.lt_inf.Enable = 'off';
                app.tspnSup1.Limits = [-Inf Inf]; app.tspnSup1.Value = 0;  app.tspnSup1.Enable = 'off'; app.lt_sup.Enable = 'off';
                app.tddSituation.Enable = 'off'; app.PrsentdansLabel.Enable = 'off';
                
                app.tddTable2.Value = ''; app.tddTable2.Enable = 'off'; app.lt_table2.Enable = 'off';
                app.tddAvant.Value = ''; app.tddAvant.Enable = 'off';
                app.tddApres.Value = ''; app.tddApres.Enable = 'off';
                app.tspnInf2.Limits = [-Inf Inf]; app.tspnInf2.Value = 0; app.tspnInf2.Enable = 'off'; app.lt_offset1.Enable = 'off';
                app.tspnSup2.Limits = [-Inf Inf]; app.tspnSup2.Value = 0; app.tspnSup2.Enable = 'off'; app.lt_offset2.Enable = 'off';
                app.tcbRepeter.Enable = 'off';
                
                app.tefNom.Value = '';
            end
            showbCreer(app);
        end
        
        %         -------------------------------------------------------------------------------
        
        function showbCreer(app)
            if ~isempty(app.clbVar.Items)
                app.ccbInverser.Enable = 'on';
            else
                app.ccbInverser.Enable = 'off';
            end
            if app.rbConditionnelle.Value
                if strcmp(app.cefNom.Value, '')
                    app.cbCreer.Enable = 'off'; app.ccbAppliquerTrip.Enable = 'off';
                else
                    app.cbCreer.Enable = 'on'; app.ccbAppliquerTrip.Enable = 'on';
                end
            end
            if app.rbTemporelle.Value
                if strcmp(app.tefNom.Value, '')
                    app.tbCreer.Enable = 'off'; app.tcbAppliquerTrip.Enable = 'off';
                else
                    app.tbCreer.Enable = 'on'; app.tcbAppliquerTrip.Enable = 'on';
                end
            end
        end
        
        %         -------------------------------------------------------------------------------
        
        function results = checkchar(app,data)
            results  = true;
            for i = 1:length(data)
                if ischar(data{i})
                    results = false;
                    return
                end
            end
        end
        %         -------------------------------------------------------------------------------
        
        function results = condition(app, data, num_cond)
            %             results = {};
            %             data_use = data';
            %             if ischar(cell2mat(data))
            %                 data_use(mask_isempty) = '';
            %                 data_test = char(data_use);
            %             else
            %                 data_use(mask_isempty) = {NaN};
            %             end
            cond = ['cond' num2str(num_cond)];
            mask_isempty = cellfun(@isempty, data);
            if checkchar(app,data)
                data = cell2mat(data);
            else
                data(mask_isempty)= {''};
            end
            ef = app.structCondition.(cond).value;
            switch app.structCondition.(cond).condition
                case '<'
                    mask_cond = (data < ef)';
                case '≤'
                    mask_cond = (data <= ef)';
                case '='
                    mask_cond = (data == ef)';
                case '≠'
                    mask_cond = (data ~= ef)';
                case '>'
                    mask_cond = (data > ef)';
                case '≥'
                    mask_cond = (data >= ef)';
                case 'est égal à'
                    mask_cond = (strcmp(data, ef))';
                case 'n''est pas égal à'
                    mask_cond = (~strcmp(data, ef))';
                case 'contient'
                    mask_cond = (contains(data, ef))';
                case 'ne contient pas'
                    mask_cond = (~contains(data, ef))';
                case 'commence par'
                    mask_cond = (startsWith(data, ef))';
                case 'ne commence pas par'
                    mask_cond = (~startsWith(data, ef))';
                case 'finit par'
                    mask_cond = (endsWith(data, ef))';
                case 'ne finit pas par'
                    mask_cond = (~endsWith(data, ef))';
                case 'NULL'
                    mask_cond = (mask_isempty)';
                case 'non NULL'
                    mask_cond = (~mask_isempty)';
            end
            results = mask_cond;
        end
        
        %         -------------------------------------------------------------------------------
        
        function result = checkIfExists(app, trip_toload, arg)
            meta = trip_toload.getMetaInformations();
            result = 0;
            if isempty(arg)
                if ~isempty(app.tddRef.Value)
                    if app.ttbEvent.Value == true
                        table_toload = meta.getEventsNamesList();
                    else
                        table_toload = meta.getSituationsNamesList();
                    end
                    
                    if any(strcmp(table_toload, app.tddRef.Value))
                        result = 1;
                    end
                elseif ~isempty(app.tddAvant.Value) && ~isempty(app.tddApres.Value)
                    table_toload = meta.getEventsNamesList();
                    if any(strcmp(table_toload, app.tddTable1.Value)) && any(strcmp(table_toload, app.tddTable2.Value))
                        name1 = meta.getEventVariablesNamesList(app.tddTable1.Value);
                        name2 = meta.getEventVariablesNamesList(app.tddTable2.Value);
                        var1_toload = trip_toload.getAllEventOccurences(app.tddTable1.Value).getVariableValues(name1{2});
                        var2_toload = trip_toload.getAllEventOccurences(app.tddTable2.Value).getVariableValues(name2{2});
                        
                        %if any(contains(var1_toload, app.tddAvant.Value)) && any(contains(var2_toload, app.tddApres.Value))
                        result = 1;
                        % end
                    end
                end
            else
                if strcmp(app.structCondition.(arg).tableType, 'data')
                    table_toload = meta.getDatasNamesList();
                    if any(strcmp(table_toload, app.structCondition.(arg).tableName))
                        var_toload = meta.getDataVariablesNamesList(app.structCondition.(arg).tableName);
                        if any(strcmp(var_toload, app.structCondition.(arg).varName))
                            result = 1;
                        end
                    end
                else
                    table_toload = meta.getEventsNamesList();
                    if any(strcmp(table_toload, app.structCondition.(arg).tableName))
                        var_toload = meta.getEventVariablesNamesList(app.structCondition.(arg).tableName);
                        if any(strcmp(var_toload, app.structCondition.(arg).varName))
                            result = 1;
                        end
                    end
                end
            end
        end
        
        %         -------------------------------------------------------------------------------
        
        function result = createField(app, trip_toload, item)
            if isempty(item)
                result.tableName = app.cddData.Value;
                result.varName = app.cddVar.Value;
                %                 result.varType = trip_toload.getDataVariableType(result.tableName,result.varName);
                
                if app.ctbData.Value
                    result.tableType = 'data';
                    record = trip_toload.getAllDataOccurences(result.tableName);
                else
                    result.tableType = 'event';
                    record = trip_toload.getAllEventOccurences(result.tableName);
                end
                
                result.data = record.getVariableValues(result.varName);
                result.timecode = record.getVariableValues('timecode');
                result.condition = app.cddCond.Value;
                result.value = app.c_ef.Value;
                result.name = app.clbVar.Items{end};
                result.nbPas = length(result.data);
            else
                result = app.structCondition.(item);
                clear result.data result.timecode
                
                if strcmp(result.tableType, 'data')
                    record = trip_toload.getAllDataOccurences(result.tableName);
                else
                    record = trip_toload.getAllEventOccurences(result.tableName);
                end
                
                result.data = record.getVariableValues(result.varName);
                result.timecode = record.getVariableValues('timecode');
            end
        end
        
        %         -------------------------------------------------------------------------------
        
        function result = checkIfIsUniform(~, struct_toload)
            is_uniform = 1;
            for i = 1:length(struct_toload)-1
                tc1 = cell2mat(struct_toload.(['cond' num2str(i)]).timecode);
                tc2 = cell2mat(struct_toload.(['cond' num2str(i+1)]).timecode);
                
                if length(tc1) == length(tc2)
                    if ~all(tc1 == tc2)
                        is_uniform = 0;
                        break
                    end
                else
                    is_uniform = 0;
                end
            end
            result = is_uniform;
        end
        
        %         -------------------------------------------------------------------------------
        
        function result = uniform(app, trip_toload, struct_toload)
            for i = 1:numel(fieldnames(struct_toload))
                cond = ['cond' num2str(i)];
                for j = 1:struct_toload.(app.condMinStep).nbPas
                    timecode = struct_toload.(app.condMinStep).timecode{j};
                    clear struct_toload.(cond).data struct_toload.(cond).timecode
                    if strcmp(struct_toload.(cond).tableType, 'data')
                        record = trip_toload.getDataOccurenceNearTime(struct_toload.(cond).tableName, timecode);
                    else
                        record = trip_toload.getEventOccurenceNearTime(struct_toload.(cond).tableName, timecode);
                    end
                    struct_toload.(cond).data{j} = record.getVariableValues(struct_toload.(cond).varName);
                end
            end
            result = struct_toload;
        end
        
        %         -------------------------------------------------------------------------------
        
        
        %         function results = creerTable_tmp(app,table)
        %             startTimecode =[];
        %             mask = table.Items(3);
        %             for i = 1:length(table)
        %                 if table(i:3) == true
        %                     startTimecode
        %                 end
        %             end
        %             result ;
        %         end
        
        %         -------------------------------------------------------------------------------
        
        function results = segmentationTemp_casSimple(app, tc_toload)
            if app.ttbEvent.Value == true % Pour un EVENT
                if isempty(app.tddSituation.Value)  % non present dans la situation
                    startTimecodes = num2cell(tc_toload{1} + app.create{1});
                    endTimecodes = num2cell(tc_toload{1} + app.create{2});
                else  % present dans la situation
                    record_presentDans = app.trip.getAllSituationOccurences(app.tddSituation.Value);
                    allStarts_presentDans = record_presentDans.getVariableValues('startTimecode');
                    allStarts_presentDans = cell2mat(allStarts_presentDans);
                    allEnds_presentDans = record_presentDans.getVariableValues('endTimecode');
                    allEnds_presentDans = cell2mat(allEnds_presentDans);
                    
                    startTimecodes = num2cell(tc_toload{1} + app.create{1});
                    endTimecodes = num2cell(tc_toload{1} + app.create{2});
                    est_dans = false;
                    for i=1:length(allStarts_presentDans)
                        if (startTimecodes{1} > allStarts_presentDans(i) && endTimecodes{1} < allEnds_presentDans(i))
                            est_dans = true;
                        end
                    end
                    if ~(est_dans)
                        if app.tcbRepeter.Value
                            %                                     uiconfirm(app.BINDGUIFigure, ...
                            %                                         'Le resultat est vide, Voulez-vous créer la situation ?', 'Résultat Vide', 'Options', {'Non', 'Oui'}, ...
                            %                                         'DefaultOption', 2, 'CancelOption', 1, 'Icon', 'info', 'CloseFcn', @(src,event)uniform_CloseFcn(app,event));
                            startTimecodes = nan;
                            endTimecodes = nan;
                        else
                            startTimecodes = nan;
                            endTimecodes = nan;
                        end
                    end
                end
            elseif app.ttbSituation.Value == true
                if isempty(app.tddSituation.Value)
                    startTimecodes = num2cell(tc_toload{1} + app.create{1});
                    endTimecodes = num2cell(tc_toload{2} + app.create{2});
                else
                    record_presentDans = app.trip.getAllSituationOccurences(app.tddSituation.Value);
                    allStarts_presentDans = record_presentDans.getVariableValues('startTimecode');
                    allStarts_presentDans = cell2mat(allStarts_presentDans);
                    allEnds_presentDans = record_presentDans.getVariableValues('endTimecode');
                    allEnds_presentDans = cell2mat(allEnds_presentDans);
                    startTimecodes = {};
                    endTimecodes = {};
                    create = cell2mat(app.create);
                    k=0;
                    
                    for i = 1:length(allStarts_presentDans)
                        recorded = app.trip.getSituationOccurencesInTimeInterval(app.tddRef.Value,allStarts_presentDans(i),allEnds_presentDans(i));
                        startTimecode_value = cell2mat(recorded.getVariableValues('startTimecode'));
                        endTimecode_value = cell2mat(recorded.getVariableValues('endTimecode'));
                        for j = 1:length(startTimecode_value)
                            startTimecodes{k+j} = startTimecode_value(j) + create(1);
                            endTimecodes{k+j} = endTimecode_value(j) + create(2);
                        end
                        k = length(startTimecodes);
                    end
                end
            end
            results{1} = startTimecodes;
            results{2} = endTimecodes;
        end
        
        %         -------------------------------------------------------------------------------
        
        function [startTimecodes, endTimecodes] = segmentationTemp_casDouble(app,trip_toload, tc_toload,  inc, d, d_nb, d_text, percent)
            
            inc = inc + percent; d.Message = [d_text 'Application sur les prochains segments'];
            if ismembertol(inc,1), inc = 1; end
            d.Value = inc; pause(app.pauseValue);
            percent_ = percent ./ (length(app.tddAvant.Items)-1);
            c_before = find(contains(app.tddAvant.Items, app.tddAvant.Value), 1) - 1;
            tmp_cbefore = c_before ;
            c_after = find(contains(app.tddApres.Items, app.tddApres.Value), 1) - 1;
            for i = c_before:length(app.tddAvant.Items) - 1
                indice = i - tmp_cbefore +1;
                inc = inc + percent_; d.Value = inc;
                table{1} = app.tddTable1.Value;
                table{2} = c_before;
                startTimecodes(indice) = getTC(app, trip_toload, table);
                
                table{1} = app.tddTable2.Value;
                table{2} = c_after;
                endTimecodes(indice) = getTC(app, trip_toload, table);
                %                 if ~isempty(app.tddSituation.Value)
                %                     record_presentDans = app.trip.getAllSituationOccurences(app.tddSituation.Value);
                %                     allStarts_presentDans = record_presentDans.getVariableValues('startTimecode');
                %                     allStarts_presentDans = cell2mat(allStarts_presentDans);
                %                     allEnds_presentDans = record_presentDans.getVariableValues('endTimecode');
                %                     allEnds_presentDans = cell2mat(allEnds_presentDans);
                %
                %                     startTimecodes(indice) = startTimecodes(indice) + app.create{1};
                %                     endTimecodes(indice) = endTimecodes(indice) + app.create{2};
                %                     est_dans(indice) = false;
                %                     for j = 1:length(allStarts_presentDans)
                %                         if (startTimecodes(indice) >= allStarts_presentDans(j) && endTimecodes(indice) <= allEnds_presentDans(j))
                %                             est_dans(indice) = true;
                %                         end
                %                     end
                %                     verifie_endTable = verifie_endTable + 1;
                %                     if ~(est_dans(indice))
                %                         startTimecodes(indice) = nan;
                %                         endTimecodes(indice) = nan;
                %                         %                                 if verifie_endTable == (length(app.tddAvant.Items) - c_before)
                %                         %                                     uiconfirm(app.BINDGUIFigure, ...
                %                         %                                         'Le resultat est vide, Voulez-vous créer la situation ?', 'Résultat Vide', 'Options', {'Non', 'Oui'}, ...
                %                         %                                         'DefaultOption', 2, 'CancelOption', 1, 'Icon', 'info', 'CloseFcn', @(src,event)uniform_CloseFcn(app,event));
                %                         %
                %                         %                                 end
                %                     end
                %                 end
                if c_after + 1 <= length(app.tddApres.Items)-1
                    c_before = c_before + 1;
                    c_after = c_after + 1;
                else
                    break
                end
            end
        end
        
        %         -------------------------------------------------------------------------------
        
        function results = appliquer_prochainSeg(app,trip_toload, tc_toload,  inc, d, d_nb, d_text, percent)
            %comprendre
            table1 = creeTabIndice(app, app.tddAvant.Items, find(contains(app.tddAvant.Items, app.tddAvant.Value), 1));
            table2 = creeTabIndice(app, app.tddApres.Items, find(contains(app.tddApres.Items, app.tddApres.Value), 1));
            
            if isfield(app.structFiltrageEvent,app.tddTable1.Value) && isfield(app.structFiltrageEvent,app.tddTable2.Value)
                if isfield(app.structFiltrageEvent.(app.tddTable1.Value), 'filtrage') && isfield(app.structFiltrageEvent.(app.tddTable2.Value), 'filtrage')
                    
                    table1 = app.tddTable1.Value;
                    timecode1 = trip_toload.getAllEventOccurences(table1).getVariableValues('timecode');
                    table2 = app.tddTable2.Value;
                    timecode2 = trip_toload.getAllEventOccurences(table2).getVariableValues('timecode');
                    
                    tc_toload_modifier1 = cell2mat(timecode1);
                    tc_toload_filtrage1 = tc_toload_modifier1(app.structFiltrageEvent.(table1).filtrage(:,1) == 1);
                    tc_toload_filtrage1 = tc_toload_filtrage1 + app.tspnInf2.Value;
                    tc_toload_filtrage1 = mat2cell(tc_toload_filtrage1,1);
                    
                    tc_toload_modifier2 = cell2mat(timecode2);
                    tc_toload_filtrage2 = tc_toload_modifier2(app.structFiltrageEvent.(table2).filtrage(:,1) == 1);
                    tc_toload_filtrage2 = tc_toload_filtrage2 + app.tspnInf2.Value;
                    tc_toload_filtrage2 = mat2cell(tc_toload_filtrage2,1);
                    
                    if app.structFiltrageEvent.(table1).validate == app.structFiltrageEvent.(table2).validate
                        startTimecodes = tc_toload_filtrage1;
                        endTimecodes = tc_toload_filtrage2;
                    else
                        text = 'Impossible de répéter sur les prochains segments puisque la taille des tableaux sont differents. Veuillez filtrer les tables.';
                        close(d);
                        uialert(app.BINDGUIFigure, text, ' ', 'Icon', 'error');
                        FiltrageEventsSituationsButtonPushed(app, app.ButtonGroupFiltrage.SelectedObject);
                        return;
                    end
                else
                    if length(table1) == length(table2)
                        [startTimecodes, endTimecodes] = segmentationTemp_casDouble(app,trip_toload, tc_toload,  inc, d, d_nb, d_text, percent);
                        
                    else
                        text = 'Impossible de répéter sur les prochains segments puisque la taille des tableaux sont differents. Veuillez filtrer les tables.';
                        close(d);
                        uialert(app.BINDGUIFigure, text, ' ', 'Icon', 'error');
                        FiltrageEventsSituationsButtonPushed(app, app.ButtonGroupFiltrage.SelectedObject);
                        return;
                        
                        
                    end
                end
            elseif length(table1) == length(table2)
                [startTimecodes, endTimecodes] = segmentationTemp_casDouble(app,trip_toload, tc_toload,  inc, d, d_nb, d_text, percent);
                
            else
                text = 'Impossible de répéter sur les prochains segments puisque la taille des tableaux sont differents. Veuillez filtrer les tables.';
                close(d);
                uialert(app.BINDGUIFigure, text, ' ', 'Icon', 'error');
                FiltrageEventsSituationsButtonPushed(app, app.ButtonGroupFiltrage.SelectedObject);
                return;
                
            end
            results{1} = startTimecodes;
            results{2} = endTimecodes;
        end
        
        %         -------------------------------------------------------------------------------
        
        function names = createName (app, startTimecodes, inc, percent, d, d_text)
            if length(startTimecodes) > 1
                percent_ = percent ./ length(startTimecodes);
                for i = 1:length(startTimecodes)
                    %                     inc = inc + percent_; d.Value = inc;
                    % Gestion des noms si génération de plus de 99 situations
                    if length(startTimecodes) > 99
                        names{i} = [app.tefNom.Value '_' num2str(i,'%03d')];
                    else
                        names{i} = [app.tefNom.Value '_' num2str(i,'%02d')];
                    end
                end
            else
                names{1} = app.tefNom.Value;
            end
            inc = inc + percent; d.Message = [d_text 'Insertion dans le TRIP'];
            if (inc>=1), inc = 1; end
            %             d.Value = inc; pause(app.pauseValue);
        end
        
        %         -------------------------------------------------------------------------------
        
        function [startTimecodes, endTimecodes] = segmentation(app,tc_toload)
            allTimecode = segmentationTemp_casSimple(app, tc_toload);
            % recupère les starts et ends timecodes
            %transformation en matrice car on a
            typeIn = allTimecode{1};
            type = whos('typeIn');
            if strcmp(type.class, 'cell')
                startTimecodes = cell2mat(allTimecode{1});
                endTimecodes = cell2mat(allTimecode{2});
                startTimecodes = num2cell(startTimecodes);
                endTimecodes =  num2cell(endTimecodes);
            else
                startTimecodes = num2cell(allTimecode{1});
                endTimecodes =  num2cell(allTimecode{2});
            end
            %transformation en cellules pour les rentrer dans le
            %trip
            
        end
        
        %         -------------------------------------------------------------------------------
        
        function segmentationTemporelle(app, trip_toload, tc_toload, d, d_nb, d_text)
            if app.tcbRepeter.Value, nb = 4; else, nb = 3; end
            
            percent = d_nb / nb; inc = d.Value; d.Message = [d_text 'Segmentation']; pause(app.pauseValue);% fenetre chargement
            % Segmentation
            
            if ~isempty(app.tddRef.Value) %cas simple
                %if (~strcmp(cell2mat(app.listeFiltrage1.Value), app.tddRef.Value) || isempty(app.listeFiltrage1.Value))
                if app.ttbEvent.Value
                    if ~isfield(app.structFiltrageEvent, app.tddRef.Value)
                        %verifie si filtrage n'existe pas
                        %if ~isfield(app.structFiltrageEvent.(app.tddRef.Value), 'filtrage')
                        % utilisation de la fonction de segmentation segmentation
                        [startTimecodes, endTimecodes] = segmentation(app,tc_toload);
                        names = createName(app, startTimecodes, inc, percent, d, d_text);
                    elseif all(app.structFiltrageEvent.(app.tddRef.Value).filtrage, 1)
                        [startTimecodes, endTimecodes] = segmentation(app,tc_toload);
                        names = createName(app, startTimecodes, inc, percent, d, d_text);
                        %transformation en cellules pour les rentrer dans le
                        %trip
                        
                    else%a faire avec le filtre
                        %modification des tc pour avoir une matrice et non un cell_array
                        tc_toload_modifier = cell2mat(tc_toload);
                        tc_toload_filtrage = tc_toload_modifier(app.structFiltrageEvent.(app.tddRef.Value).filtrage(:,1) == 1);
                        tc_toload_filtrage = mat2cell(tc_toload_filtrage,1);
                        [startTimecodes, endTimecodes] = segmentation(app,tc_toload_filtrage);
                        names = createName(app, startTimecodes, inc, percent, d, d_text);
                    end
                else
                    if ~isfield(app.structFiltrageSituation, app.tddRef.Value)
                        %verifie si filtrage n'existe pas
                        %if ~isfield(app.structFiltrageEvent.(app.tddRef.Value), 'filtrage')
                        % utilisation de la fonction de segmentation segmentation
                        [startTimecodes, endTimecodes] = segmentation(app,tc_toload);
                        names = createName(app, startTimecodes, inc, percent, d, d_text);
                    elseif all(app.structFiltrageSituation.(app.tddRef.Value).filtrage, 1)
                        [startTimecodes, endTimecodes] = segmentation(app,tc_toload);
                        names = createName(app, startTimecodes, inc, percent, d, d_text);
                        %transformation en cellules pour les rentrer dans le
                        %trip
                        
                    else%a faire avec le filtre
                        %modification des tc pour avoir une matrice et non un cell_array
                        tc_toload_modifier_start = cell2mat(tc_toload(1));
                        tc_toload_modifier_end = cell2mat(tc_toload(2));
                        tc_toload_filtrage_start = tc_toload_modifier_start(app.structFiltrageSituation.(app.tddRef.Value).filtrage(:,1) == 1);
                        tc_toload_filtrage_end = tc_toload_modifier_end(app.structFiltrageSituation.(app.tddRef.Value).filtrage(:,1) == 1);
                        for i = 1:length(tc_toload_filtrage_start)
                            tc_toload_filtrage(1,i) = tc_toload_filtrage_start(i);
                            tc_toload_filtrage(2,i) = tc_toload_filtrage_end(i);
                        end
                        tc_toload_filtrage_cell(1) = mat2cell(tc_toload_filtrage(1,:),1);
                        tc_toload_filtrage_cell(2) = mat2cell(tc_toload_filtrage(2,:),1);
                        [startTimecodes, endTimecodes] = segmentation(app,tc_toload_filtrage_cell);
                        names = createName(app, startTimecodes, inc, percent, d, d_text);
                    end
                end
            else %cas double
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                if isempty(app.tddSituation.Value)
                    if ~app.tcbRepeter.Value
                        startTimecodes = num2cell(tc_toload{1} + app.create{1});
                        endTimecodes = num2cell(tc_toload{2} + app.create{2});
                        %verification du filtrage
                    else%if ~isfield(app.structFiltrageEvent.(app.tddTable1.Value)) && ~isfield(app.structFiltrageEvent.(app.tddTable2.Value))
                        try
                            allTimecode = appliquer_prochainSeg(app,trip_toload,tc_toload, inc, d, d_nb, d_text, percent);
                        catch
                            return;
                        end
                        % recupère les starts et ends timecodes
                        %transformation en cellules pour les rentrer dans le
                        %trip
                        
                        typeIn = allTimecode{1};
                        type = whos('typeIn');
                        if strcmp(type.class, 'cell')
                            startTimecodes = cell2mat(allTimecode{1});
                            endTimecodes = cell2mat(allTimecode{2});
                            startTimecodes = num2cell(startTimecodes);
                            endTimecodes =  num2cell(endTimecodes);
                        else
                            startTimecodes = num2cell(allTimecode{1});
                            endTimecodes =  num2cell(allTimecode{2});
                        end
                    end
                    
                    names = createName(app, startTimecodes, inc, percent, d, d_text);
                else
                    try
                        record_presentDans = trip_toload.getAllSituationOccurences(app.tddSituation.Value);
                    catch
                        text = strcat('La situation : ', app.tddSituation.Value, ' ', "n'existe pas dans un des trips");
                        uialert(app.BINDGUIFigure, text, ' ', 'Icon', 'error');
                        return
                    end
                    allStarts_presentDans = record_presentDans.getVariableValues('startTimecode');
                    allStarts_presentDans = cell2mat(allStarts_presentDans);
                    allEnds_presentDans = record_presentDans.getVariableValues('endTimecode');
                    allEnds_presentDans = cell2mat(allEnds_presentDans);
                    
                    startTimecodes = num2cell(tc_toload{1} + app.create{1});
                    endTimecodes = num2cell(tc_toload{2} + app.create{2});
                    if ~app.tcbRepeter.Value
                        est_dans = false;
                        for i=1:length(allStarts_presentDans)
                            if (startTimecodes{1} >= allStarts_presentDans(i) && endTimecodes{1} <= allEnds_presentDans(i))
                                est_dans = true;
                            end
                        end
                        if ~(est_dans)
                            startTimecodes = nan;
                            endTimecodes = nan;
                        end
                    else
                        try
                            allTimecode = appliquer_prochainSeg(app,trip_toload,tc_toload, inc, d, d_nb, d_text, percent);
                        catch
                            return;
                        end
                        % recupère les starts et ends timecodes
                        %transformation en cellules pour les rentrer dans le
                        %trip
                        est_dans = false(length(startTimecodes),1);
                        typeIn = allTimecode{1};
                        type = whos('typeIn');
                        if strcmp(type.class, 'cell')
                            startTimecodes = cell2mat(allTimecode{1});
                            endTimecodes = cell2mat(allTimecode{2});
                            startTimecodes = num2cell(startTimecodes);
                            endTimecodes =  num2cell(endTimecodes);
                        else
                            startTimecodes = num2cell(allTimecode{1});
                            endTimecodes =  num2cell(allTimecode{2});
                        end
                        for i=1:length(allStarts_presentDans)
                            for j = 1:length(startTimecodes)
                                if (startTimecodes{j} >= allStarts_presentDans(i) && endTimecodes{j} <= allEnds_presentDans(i))
                                    est_dans(j) = true;
                                end
                            end
                        end
                        startTimecodes = startTimecodes(est_dans);
                        endTimecodes = endTimecodes(est_dans);


                    end
                    inc = inc + percent; d.Message = [d_text 'Génération des noms'];
                    %                     d.Value = inc;
                    pause(app.pauseValue);
                    percent_ = percent ./ length(tc_toload{1});
                    % Génération des noms
                    names = createName(app, startTimecodes, inc, percent_, d, d_text);
                    % Ajout dans le TRIP
                    
                end
                
            end
            
            
            if isequal(startTimecodes, endTimecodes)
                addEventTable2Trip(trip_toload, app.tefNom.Value, 'comment', 'Ajouté à partir de BIND_GUI');
                addEventVariable2Trip(trip_toload,app.tefNom.Value,'name','TEXT', 'comment', 'Ajouté à partir de BIND_GUI');
                trip_toload.setBatchOfTimeEventVariablePairs(app.tefNom.Value, 'name', [startTimecodes(:), names(:)]');
                %uialert(app.BINDGUIFigure, 'On est dans le cas event pour creer une situation, veuillez choisir des offsets differents.', ' ', 'Icon', 'error');
                
            elseif ~trouveUnEgale(app, startTimecodes,endTimecodes)
                addSituationTable2Trip(trip_toload, app.tefNom.Value, 'comment', 'Ajouté à partir de BIND_GUI');
                addSituationVariable2Trip(trip_toload, app.tefNom.Value, 'name', 'TEXT', 'comment', 'Ajouté à partir de BIND_GUI');
                trip_toload.setBatchOfTimeSituationVariableTriplets(app.tefNom.Value, 'name', ...
                    [startTimecodes(:), endTimecodes(:), names(:)]');
            else
                text = 'Il exist un timecode de début qui est superieur ou égale à un timecode de fin. Veuillez filtrer les tables.';
                close(d);
                uialert(app.BINDGUIFigure, text, ' ', 'Icon', 'error');
                FiltrageEventsSituationsButtonPushed(app, app.ButtonGroupFiltrage.SelectedObject);
                return;
            end
        end
        
        function result = trouveUnEgale (app, startTimecodes, endTimecodes)
            result = false;
            %verif type
            type = class(startTimecodes);
            
            if iscell(startTimecodes)%~(isnan(startTimecodes))
                for i = 1:length(startTimecodes)
                    if startTimecodes{i} >= endTimecodes{i}
                        result = true;
                        return;
                    end
                end
            end
        end
        
        %         -------------------------------------------------------------------------------
        
        function segmentationConditionnelle(app, trip_toload, struct_toload, d, d_nb, d_text)
            c_startTimecodes = []; c_endTimecodes = [];
            %              name1 = meta.getEventVariablesNamesList(app.tddTable1.Value);
            %              name2 = meta.getEventVariablesNamesList(app.tddTable2.Value);
            %              var1_toload = trip_toload.getAllEventOccurences(app.tddTable1.Value).getVariableValues(name1{2});
            %              var2_toload = trip_toload.getAllEventOccurences(app.tddTable2.Value).getVariableValues(name2{2});
            if ~isempty(app.cddSituation.Value), nb = 6; else, nb = 5; end
            percent = d_nb / nb;
            %             inc = d.Value;
            d.Message = [d_text 'Segmentation']; pause(app.pauseValue);
            % Segmentation
            for i = 1:numel(fieldnames(struct_toload))
                cond = ['cond' num2str(i)];
                data = struct_toload.(cond).data; % Récupère les donnée de la condition i
                my_field = strcat('C',num2str(i));
                masks.(my_field) = condition(app, data, i);
                if isfield(app.structFiltrageEvent,struct_toload.(cond).tableName)
                    nameEvent = struct_toload.(cond).tableName;
                    masks.(my_field) = and(masks.(my_field),app.structFiltrageEvent.(nameEvent).filtrage);
                end
                %masks{end+1} = condition(app, data, i);
                if all(masks.(my_field) == 0)
                    text = ['Aucun segment n''a été trouvé pour ' struct_toload.(cond).name '.'];
                    uialert(app.BINDGUIFigure, text, ' ', 'Icon', 'info');
                    continue;
                end
            end
            %             inc = inc + percent;
            %             if (inc >=1)
            %                 inc = 1;
            %             end
            d.Message = [d_text 'Fusion'];
            %             d.Value = inc;
            pause(app.pauseValue);
            % Fusion des masks (a changer)
            %             master_mask = masks{1};
            %             for i = 2:length(masks)
            %                 master_mask = and(master_mask, masks{i});
            %             end
            %             if app.ccbInverser.Value
            %                 master_mask = ~master_mask;
            %             end
            
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % ici appel de la fonction evaluation.
            %master_mask = fusion_mask(app,masks,app.connecteurs.Value);
            try
                master_mask = evaluation(app,masks,app.connecteurs.Value);
                timecode = cell2mat(struct_toload.(app.condMinStep).timecode)';
                % Sous-segmentation avec la situation
                if ~isempty(app.cddSituation.Value)
                    %inc = inc + percent;
                    d.Message = [d_text 'Sous-segmentation'];
                    %                     d.Value = inc;
                    pause(app.pauseValue);
                    record = trip_toload.getAllSituationOccurences(app.cddSituation.Value);
                    data_situation = record.buildCellArrayWithVariables(record.getVariableNames)';
                    tc_situation = [];
                    if strcmp(struct_toload.(cond).tableType, 'data')
                        for i = 1:size(data_situation,1)
                            tc_situation = [tc_situation, trip_toload.getDataOccurencesInTimeInterval(struct_toload.(app.condMinStep).tableName, ...
                                cell2mat(data_situation(i,1)), cell2mat(data_situation(i,2))).getVariableValues('timecode')];
                        end
                    else
                        for i = 1:size(data_situation,1)
                            tc_situation = [tc_situation, trip_toload.getEventOccurencesInTimeInterval(struct_toload.(app.condMinStep).tableName, ...
                                cell2mat(data_situation(i,1)), cell2mat(data_situation(i,2))).getVariableValues('timecode')];
                        end
                    end
                    mask_situation = ismember(timecode, cell2mat(tc_situation)');
                    if ~all(mask_situation == 0)
                        %timecode = timecode(mask_situation);
                        master_mask = and(master_mask, mask_situation);
                        if all(master_mask == 0)
                            text = ['Aucun segment ne respecte toutes les conditions sur la situation ' app.cddSituation.Value '.'];
                            close(d);
                            uialert(app.BINDGUIFigure, text, ' ', 'Icon', 'info');
                            return;
                        end
                    else
                        text = ['Aucun sous-segment n''a été trouvé pour la situation ' app.cddSituation.Value '.'];
                        %close(d);
                        uialert(app.BINDGUIFigure, text, ' ', 'Icon', 'info');
                        return;
                    end
                end
                
                %inc = inc + percent;
                d.Message = [d_text 'Récupération des timecodes'];
                %                 d.Value = inc;
                pause(app.pauseValue);
                % Récupération des timecodes
                if strcmp(app.cddChoix.Value, 'Event')
                    c_timecodes = timecode(master_mask);
                    nb_name = length(c_timecodes);
                else
                    mask_diff = zeros(length(master_mask),1);
                    if master_mask(1) == 1; mask_diff(1) = 1; end
                    mask_diff(2:end) = diff(master_mask);
                    mask_tc = find(mask_diff);
                    if ~isempty(mask_tc)
                        for i = 1:length(mask_tc)
                            mask_mod(i) = logical(mod(i,2));
                        end
                        start_tc = timecode(mask_tc(mask_mod));
                        end_tc = timecode(mask_tc(logical(1-mask_mod)));
                        if mask_mod(end) == 1
                            end_tc(end+1) = timecode(end);
                        end
                    end
                    c_startTimecodes = num2cell(start_tc);
                    c_endTimecodes = num2cell(end_tc);
                    nb_name = length(c_startTimecodes);
                end
                %                 inc = inc + percent;
                %                 if (inc >=1), inc = 1; end
                d.Message = [d_text 'Génération des noms'];
                %                 d.Value = inc;
                pause(app.pauseValue);
                percent_ = percent ./ nb_name;
                if iscellstr(data)
                    names = data(master_mask);
                else
                    for i = 1:nb_name
                        %inc = inc + percent_; d.Value = inc;
                        %                 if struct_toload.(cond).comment
                        %                     if strcmp(struct_toload.(cond).tableType, 'data')
                        %                         names{i} = cell2mat(trip_toload.getDataOccurenceAtTime(struct_toload.(cond).tableName, ...
                        %                             c_timecodes(i)).getVariableValues(struct_toload.(cond).varName));
                        %                     else
                        %                         names{i} = cell2mat(trip_toload.getEventOccurenceAtTime(struct_toload.(cond).tableName, ...
                        %                             c_timecodes(i)).getVariableValues(struct_toload.(cond).varName));
                        %                     end
                        %                 else
                        if nb_name > 99
                            names{i} = [app.cefNom.Value '_' num2str(i,'%03d')];
                        else
                            names{i} = [app.cefNom.Value '_' num2str(i,'%02d')];
                        end
                    end
                    
                end
                
                %                 inc = inc + percent; d.Message = [d_text 'Insertion dans le TRIP'];
                %                 if ismembertol(inc,1,10^6), inc = 1; end
                %d.Value = inc;
                %                 pause(app.pauseValue);
                % Ajout dans le TRIP
                if strcmp(app.cddChoix.Value, 'Event')
                    addEventTable2Trip(trip_toload, app.cefNom.Value, 'comment', 'Ajouté à partir de BIND_GUI');
                    addEventVariable2Trip(trip_toload,app.cefNom.Value,'name','TEXT', 'comment', 'Ajouté à partir de BIND_GUI');
                    trip_toload.setBatchOfTimeEventVariablePairs(app.cefNom.Value, 'name', [num2cell(c_timecodes(:)), names(:)]');
                else
                    addSituationTable2Trip(trip_toload, app.cefNom.Value, 'comment', 'Ajouté à partir de BIND_GUI');
                    addSituationVariable2Trip(trip_toload,app.cefNom.Value,'name','TEXT', 'comment', 'Ajouté à partir de BIND_GUI');
                    trip_toload.setBatchOfTimeSituationVariableTriplets(app.cefNom.Value, 'name', ...
                        [c_startTimecodes(:), c_endTimecodes(:), names(:)]');
                end
            catch
                text = 'Il manque des données apparament.';
                %close(d);
                uialert(app.BINDGUIFigure, text, ' ', 'Icon', 'error');
                %continue
                %return
            end
            
        end
        
        %         -------------------------------------------------------------------------------
        
        function results = getTC(app, trip_toload, table)
            if ~iscell(table)
                if strcmp(table, 'event')
                    results{1} = cell2mat(trip_toload.getAllEventOccurences(app.tddRef.Value).getVariableValues('timecode'));
                elseif strcmp(table, 'situation')
                    results{1} = cell2mat(trip_toload.getAllSituationOccurences(app.tddRef.Value).getVariableValues('startTimecode'));
                    results{2} = cell2mat(trip_toload.getAllSituationOccurences(app.tddRef.Value).getVariableValues('endTimecode'));
                else
                    
                end
            else
                timecode = trip_toload.getAllEventOccurences(table{1}).getVariableValues('timecode');
                if (length(timecode) >= table{1,2})
                    results = timecode{table{1,2}};
                else
                    results = timecode{length(timecode)};
                end
            end
        end
        
        %         -------------------------------------------------------------------------------
        
        function result = buildArray(~, trip_toload, type, table, vars)
            meta = trip_toload.getMetaInformations();
            switch type
                case 'data'
                    if isempty(vars)
                        vars = meta.getDataVariablesNamesList(table);
                    end
                    record = trip_toload.getAllDataOccurences(table);
                case 'event'
                    if isempty(vars)
                        vars = meta.getEventVariablesNamesList(table);
                    end
                    record = trip_toload.getAllEventOccurences(table);
                case 'situation'
                    if isempty(vars)
                        vars = meta.getSituationVariablesNamesList(table);
                    end
                    record = trip_toload.getAllSituationOccurences(table);
            end
            result = record.buildCellArrayWithVariables(vars)';
        end
        
        %         -------------------------------------------------------------------------------
        
        function result = getVars(~, trip_toload, type, table, vars)
            meta = trip_toload.getMetaInformations();
            switch type
                case 'data'
                    if isempty(vars)
                        vars = meta.getDataVariablesNamesList(table);
                    end
                case 'event'
                    if isempty(vars)
                        vars = meta.getEventVariablesNamesList(table);
                    end
                case 'situation'
                    if isempty(vars)
                        vars = meta.getSituationVariablesNamesList(table);
                    end
            end
            result = vars;
        end
        
        %         -------------------------------------------------------------------------------
        
        function result = buildHeader(~, tables, vars)
            if length(tables) == 1 && length(vars) > 1
                tmp_tables = cell(1, length(vars)); tmp_tables(:) = tables;
                tables = tmp_tables;
            end
            sep = cell(1, length(vars)); sep(:) = {'.'};
            result = cellfun(@strcat, tables, sep, vars, 'UniformOutput', false);
        end
        
        %         -------------------------------------------------------------------------------
        
        function refreshTree(app, d)
            delete(app.trip);
            delete(app.EventsNode.Children);
            delete(app.SituationsNode.Children);
            delete(app.DatasNode.Children);
            delete(app.AttributesNode.Children);
            delete(app.AudioVideoNode.Children);
            delete(app.ParticipantNode.Children);
            
            app.trip = fr.lescot.bind.kernel.implementation.SQLiteTrip(app.tripRef.Tag, 0.04, false);
            
            % Chargement des data et des events/situations en fonction du trip de référence
            meta = app.trip.getMetaInformations();
            data_tables = meta.getDatasNamesList();
            event_tables = meta.getEventsNamesList();
            situation_tables = meta.getSituationsNamesList();
            attributes_tables = meta.getTripAttributesList();
            audio_video_tables = meta.getVideoFiles();
            participant_meta = meta.getParticipant();
            participant_tables = participant_meta.getAttributesList();
            
            d.Message = 'Chargement des données'; pause(app.pauseValue);
            for i = 1:length(data_tables)
                d.Message = ['Chargement des données : ' data_tables{i}];
                data_cols = meta.getDataVariablesNamesList(data_tables{i});
                data_node = uitreenode('Parent', app.DatasNode, 'Text', data_tables{i});
                for j = 1:length(data_cols)
                    uitreenode('Parent', data_node, 'Text', data_cols{j});
                end
            end
            inc = 1/6;
            d.Value = d.Value + inc;
            
            d.Message = 'Chargement des évènements'; pause(app.pauseValue);
            for i = 1:length(event_tables)
                d.Message = ['Chargement des évènements : ' event_tables{i}];
                event_cols = meta.getEventVariablesNamesList(event_tables{i});
                event_node = uitreenode('Parent', app.EventsNode, 'Text', event_tables{i});
                for j = 1:length(event_cols)
                    uitreenode('Parent', event_node, 'Text', event_cols{j});
                end
            end
            d.Value = d.Value + inc;
            
            d.Message = 'Chargement des situations'; pause(app.pauseValue);
            app.cddSituation.Items = {''}; app.tddSituation.Items = {''};
            for i = 1:length(situation_tables)
                d.Message = ['Chargement des situations : ' situation_tables{i}];
                situation_cols = meta.getSituationVariablesNamesList(situation_tables{i});
                situation_node = uitreenode('Parent', app.SituationsNode, 'Text', situation_tables{i});
                app.cddSituation.Items{end+1} = situation_tables{i}; app.tddSituation.Items{end+1} = situation_tables{i};
                for j = 1:length(situation_cols)
                    uitreenode('Parent', situation_node, 'Text', situation_cols{j});
                end
            end
            d.Value = d.Value + inc;
            
            d.Message = 'Chargement des attributs du Trip'; pause(app.pauseValue);
            for i = 1:length(attributes_tables)
                d.Message = ['Chargement des Attributes : ' attributes_tables{i}];
                uitreenode('Parent', app.AttributesNode, 'Text', attributes_tables{i});
            end
            d.Value = d.Value + inc;
            
            d.Message = 'Chargement des attributs du participant'; pause(app.pauseValue);
            for i = 1:length(participant_tables)
                participant_cols = participant_meta.getAttribute(participant_tables{i});
                participant_node = uitreenode('Parent', app.ParticipantNode, 'Text', participant_tables{i});
                uitreenode('Parent', participant_node, 'Text', participant_cols);
            end
            d.Value = d.Value + inc;
            
            d.Message = 'Chargement des médias (audio/vidéo)';
            for i = 1:length(audio_video_tables)
                d.Message = ['Chargement des médias (audio/vidéo) : ' audio_video_tables{i}.getFileName];
                uitreenode('Parent', app.AudioVideoNode, 'Text', audio_video_tables{i}.getDescription());
            end
            d.Value = d.Value + inc;
            
            tc_bg_SelectionChanged(app);
        end
        
        %         -------------------------------------------------------------------------------
        
        function deleteInTree(app, trip_todelete, node, list)
            switch node.Parent
                case app.EventsNode
                    app.tableIsBase = removeEventsTables(trip_todelete, list, app.forceIsBase);
                case app.SituationsNode
                    app.tableIsBase = removeSituationsTables(trip_todelete, list, app.forceIsBase);
                case app.DatasNode
                    app.tableIsBase = removeDataTables(trip_todelete, list, app.forceIsBase);
            end
            switch node.Parent.Parent
                case app.EventsNode
                    app.tableIsBase = removeEventVariables(trip_todelete, list, app.forceIsBase);
                case app.SituationsNode
                    app.tableIsBase = removeSituationVariables(trip_todelete, list, app.forceIsBase);
                case app.DatasNode
                    app.tableIsBase = removeDataVariables(trip_todelete, list, app.forceIsBase);
            end
            if app.tableIsBase && ~app.forceIsBase
                force = uiconfirm(app.BINDGUIFigure, {'La table est protégée ! Voulez-vous forcer la protection ?'}, 'Table protégée', ...
                    'Options', {'Non', 'Oui'}, 'DefaultOption', 1, 'CancelOption', 1, 'Icon', 'Warning');
                if strcmp(force, 'Oui')
                    app.forceIsBase = true;
                end
            end
        end
        
        %         -------------------------------------------------------------------------------
        
        % compare le nom donné avec les nom de situations ou d'events retourne faux si il n'est pas contenu
        function results = compareCreatedNames(app,name)
            results = false;
            if app.rbTemporelle.Value % si on est dans le cas temporel
                if ~isempty(app.tddRef.Value)  % si le champs nom n'est pas vide
                    %                     if strcmp(app.tbtgTable.SelectedObject.Text, 'Situation') %si on a choisit de créer une situation
                    for i = 1:length(app.SituationsNode.Children) % parcourt de tous les elements du noeud Situations
                        if strcmp(app.SituationsNode.Children(i).Text, name) % comparaison entre le nom et les nom des différentes situations
                            results = true;
                        end
                    end
                    %                     else
                    %                         for i = 1:length(app.EventsNode.Children) % si on a choisit de créer un event
                    %                             if strcmp(app.EventsNode.Children(i).Text, name) %parcourt de tous les elements du noeud Events
                    %                                 results = true;
                    %                             end
                    %                         end
                    %                     end
                end
                % même chose si on est dans le cas conditionnel
            elseif app.rbConditionnelle.Value
                if ~isempty(app.cefNom.Value)
                    if strcmp(app.cddChoix.Value, 'Situation')
                        for i = 1:length(app.SituationsNode.Children)
                            if strcmp(app.SituationsNode.Children(i).Text, name)
                                results = true;
                            end
                        end
                    else
                        for i = 1:length(app.EventsNode.Children)
                            if strcmp(app.EventsNode.Children(i).Text, name)
                                results = true;
                            end
                        end
                    end
                end
            end
            
        end %end function compareCreatedNames
        
        function results = creeTabIndice(app,tabItems,indice)
            for i = indice:length(tabItems)
                nouvelIndice = i - indice + 1; % pour commencer à écrire dans le tableau à l'indice 1
                results(nouvelIndice) = tabItems(i);
            end
        end
        
        %         -------------------------------------------------------------------------------
        
        function showFiltragePanel(app,selectedButton)
            if ~isempty(app.tddTable1.Value) || ~isempty(app.tddRef.Value)
                remplissage(app,selectedButton);
                selection(app);
            else
                remplissage(app,selectedButton);
            end
        end
        
        %         -------------------------------------------------------------------------------
        
        function selection(app)
            if ~isempty(app.tddRef.Value)
                if app.SituationsFiltrageButton.Value && app.ttbSituation.Value
                    index = find(contains(app.listeFiltrage1.Items,app.tddRef.Value ));
                    app.listeFiltrage1.Value = app.listeFiltrage1.Items{index};
                    listeFiltrageValueChanged(app,app.listeFiltrage1.Value);
                elseif app.EventsFiltrageButton.Value && app.ttbEvent.Value
                    index = find(contains(app.listeFiltrage1.Items,app.tddRef.Value ));
                    app.listeFiltrage1.Value = app.listeFiltrage1.Items{index};
                    listeFiltrageValueChanged(app,app.listeFiltrage1.Value);
                end
            elseif isempty(app.tddTable2.Value)
                index = find(contains(app.listeFiltrage1.Items,app.tddTable1.Value));
                app.listeFiltrage1.Value = app.listeFiltrage1.Items{index};
                listeFiltrageValueChanged(app,app.listeFiltrage1.Value);
            elseif ~isempty(app.tddTable2.Value) && app.EventsFiltrageButton.Value
                index = find(contains(app.listeFiltrage1.Items,app.tddTable1.Value ));
                app.listeFiltrage1.Value = app.listeFiltrage1.Items{index};
                index = find(contains(app.listeFiltrage2.Items,app.tddTable2.Value ));
                app.listeFiltrage2.Value = app.listeFiltrage2.Items{index};
                
                listeFiltrageValueChanged(app,app.listeFiltrage2.Value);
                
            end
        end
        
        %         -------------------------------------------------------------------------------
        
        function remplissage(app,selectedButton)
            switch selectedButton
                case app.EventsFiltrageButton %cas où on choisit de filtrer des events
                    app.label.Text = "① choisir les événements à filtrer"; %changement du text
                    app.listeFiltrage1.Items = {}; %on vide les tables
                    app.listeFiltrage1.ItemsData = {};
                    app.listeFiltrage2.Items = {};
                    app.listeFiltrage2.ItemsData = {};
                    app.nbpris1.Value = '';
                    app.nbpris2.Value = '';
                    set(app.tableFiltrage1, 'data', {});
                    set(app.tableFiltrage2, 'data', {});
                    for i = 1:length(app.EventsNode.Children) %on rempli les tables
                        app.listeFiltrage1.Items{i} = app.EventsNode.Children(i).Text;
                        app.structFiltrageEvent.(app.listeFiltrage1.Items{i}).data = getTC_Filtrage(app, app.trip, app.ButtonGroupFiltrage.SelectedObject.Text, app.EventsNode.Children(i));
                        %                             app.listeFiltrage1.ItemsData{i} = getTC_Filtrage(app, app.trip, app.ButtonGroupFiltrage.SelectedObject.Text, app.EventsNode.Children(i));
                        app.listeFiltrage2.Items{i} = app.EventsNode.Children(i).Text;
                        app.structFiltrageEvent.(app.listeFiltrage2.Items{i}).data = getTC_Filtrage(app, app.trip, app.ButtonGroupFiltrage.SelectedObject.Text, app.EventsNode.Children(i));
                        %                             app.listeFiltrage2.ItemsData{i} = getTC_Filtrage(app, app.trip, app.ButtonGroupFiltrage.SelectedObject.Text, app.EventsNode.Children(i));
                    end %positionnement des tables
                    app.tableFiltrage1.Position = [20,112,303,185];
                    app.listeFiltrage1.Position = [136,327,149,190];
                    app.Title_liste1.Position = [137,519,148,23];
                    app.Title_table1.Position = [20,299,301,23];
                    app.nbpris1.Position = [75,86,28,20];
                    app.tableFiltrage1.ColumnName = {'Ignore';'Timecode';'Name'};
                    app.tableFiltrage2.Visible = 'on';
                    app.listeFiltrage2.Visible = 'on';
                    app.Title_liste2.Visible = 'on';
                    app.Title_table2.Visible = 'on';
                    app.nbpris2.Visible = 'on';
                case app.SituationsFiltrageButton %cas où on choisit de filtrer des situation
                    app.label.Text = "① choisir les situations à filtrer";%changement du text
                    app.listeFiltrage1.Items = {};%on vide les tables
                    app.listeFiltrage1.ItemsData = {};
                    app.listeFiltrage2.Items = {};
                    app.listeFiltrage2.ItemsData = {};
                    app.nbpris1.Value = '';
                    app.nbpris2.Value = '';
                    set(app.tableFiltrage1, 'data', {});
                    set(app.tableFiltrage2, 'data', {});
                    for i = 1:length(app.SituationsNode.Children)%on rempli la table
                        app.listeFiltrage1.Items{i} = app.SituationsNode.Children(i).Text;
                        app.structFiltrageSituation.(app.listeFiltrage1.Items{i}).data = getTC_Filtrage(app, app.trip, app.ButtonGroupFiltrage.SelectedObject.Text, app.SituationsNode.Children(i));
                        %                             app.listeFiltrage1.ItemsData{i} = getTC_Filtrage(app, app.trip, app.ButtonGroupFiltrage.SelectedObject.Text, app.SituationsNode.Children(i));
                    end %positionnement des tables
                    app.tableFiltrage1.Position = [125,112,400,185];%[167,112,303,185]
                    app.listeFiltrage1.Position = [246,327,149,190];
                    app.Title_liste1.Position = [246,519,148,23];
                    app.Title_table1.Position = [167,299,301,23];
                    app.nbpris1.Position = [180,86,28,20];
                    app.tableFiltrage1.ColumnName = {'Ignore';'Start_Timecode';'End_Timecode';'Name'};
                    app.tableFiltrage2.Visible = 'off';
                    app.listeFiltrage2.Visible = 'off';
                    app.Title_liste2.Visible = 'off';
                    app.Title_table2.Visible = 'off';
                    app.nbpris2.Visible = 'off';
            end
        end
        
        %         -------------------------------------------------------------------------------
        

        function results = getTC_Filtrage(app, trip_toload, cas, items)
            if  strcmp(cas,'Events')
                meta = trip_toload.getMetaInformations();
                name = meta.getEventVariablesNamesList(items.Text);
                %                 timescode = trip_toload.getAllEventOccurences(items.Text).getVariableValues('timecode');
                %                 names_Event = trip_toload.getAllEventOccurences(items.Text).getVariableValues('name');
                timescode = trip_toload.getAllEventOccurences(items.Text).getVariableValues(name{1});
                names_Event = trip_toload.getAllEventOccurences(items.Text).getVariableValues(name{2});
                for i = 1:length(names_Event)
                    results{i,1} = timescode{i};
                    results{i,2} = names_Event{i};
                end
            else
                meta = trip_toload.getMetaInformations();
                name = meta.getSituationVariablesNamesList(items.Text);
                names_Situation = trip_toload.getAllSituationOccurences(items.Text).getVariableValues(name{3});
                start_Timecode = trip_toload.getAllSituationOccurences(items.Text).getVariableValues(name{1});
                end_Timecode = trip_toload.getAllSituationOccurences(items.Text).getVariableValues(name{2});
                for i = 1:length(names_Situation)
                    results{i,1} = start_Timecode{i};
                    results{i,2} = end_Timecode{i};
                    results{i,3} = names_Situation{i};
                end
            end
        end
        
        %         -------------------------------------------------------------------------------
        
        function results = eval_struct_str(app,field)
            results = eval(field);
        end
        
        %         -------------------------------------------------------------------------------
        
        function results = fusion_mask(app,operations)
            results = eval(operations);
        end
        
        %         -------------------------------------------------------------------------------
        
        function results = fusion_connecteur(app, operator, value)
            nombre = numel(value) ;
            
            if nombre >= 2
                i = 1;
                conditions = '';
                nombreChiffre1 = numel(regexp(value{1}(1:8),'[0-9]','match'));
                nombreChiffre2 = numel(regexp(value{2}(1:8),'[0-9]','match'));
                
                conditions = strcat(operator , ' (' , conditions , value{1}(2:(2+nombreChiffre1)) , ' , ',...
                    value{2}(2:(2+nombreChiffre2)) , ' )');
                i = i + 2;
                if ~(nombre == 2)
                    while(i < nombre)
                        nombreChiffrei = numel(regexp(value{i}(1:8),'[0-9]','match'));
                        conditions = strcat(operator , ' (' , conditions , ' ,' ,  value{i}(2:(2+nombreChiffrei)),' )');
                        i =  i + 1;
                    end
                    nombreChiffren = numel(regexp(value{nombre}(1:8),'[0-9]','match'));
                    if ~strcmp(app.connecteurs.Value,'')
                        conditions = strcat(operator , ' (' ,conditions , ' , ' , value{nombre}(2:(2+nombreChiffren)),' )');
                    else
                        conditions = strcat(conditions , ' , ' , value{nombre}(2:(2+nombreChiffren)));
                    end
                end
            elseif numel(value) == 1
                conditions = app.connecteurs.Value;
                nombreChiffre1 = numel(regexp(value{1}(1:8),'[0-9]','match'));
                conditions = strcat(operator , ' (' , conditions ,' ,',  value{1}(2:(2+nombreChiffre1)) , ' )');
            end
            results = conditions;
        end % end fusion_connecteur
        
        
        %         -------------------------------------------------------------------------------
        
        function master_mask = evaluation(app,masks,operations)
            ET = @and;
            OU = @or;
            NON = @not;
            XOR = @xor;
            try
                master_mask = eval(strrep(operations, 'C', 'masks.C'));
            catch ME
                text = ['Probleme avec les connecteurs, Impossible de segmenter'];
                uialert(app.BINDGUIFigure, text, ' ', 'Icon', 'error');
            end
        end
        
        %         -------------------------------------------------------------------------------
        
        function update_DropDownliste(app)
            app.tddTable1.Items = {''};
            app.tddTable2.Items = {''};
            app.tddRef.Items = {''};
            for i = 1:length(app.EventsNode.Children)
                app.tddTable1.Items{end+1} = app.EventsNode.Children(i).Text;
                app.tddTable2.Items{end+1} = app.EventsNode.Children(i).Text;
            end
            switch app.tbtgTable.SelectedObject
                case app.ttbEvent
                    for i = 1:length(app.EventsNode.Children)
                        app.tddRef.Items{end+1} = app.EventsNode.Children(i).Text;
                    end
                case app.ttbSituation
                    for i = 1:length(app.SituationsNode.Children)
                        app.tddRef.Items{end+1} = app.SituationsNode.Children(i).Text;
                    end
            end
        end
        
        %         -------------------------------------------------------------------------------
        function exportConcatenation(app, d)
            error ='';
            date_time = char(datetime('now','Format','yyMMdd_HHmm'));
            %pbm
            trip_files = app.selectedTripPaths;
            data_all = {};
            %iteration sur les trip
            for i_trip = 1:length(trip_files)
                trip_file = trip_files{i_trip};
                %chergement du trip
                localTrip = fr.lescot.bind.kernel.implementation.SQLiteTrip(trip_file, 0.04, false);
                %recuperation des meta informations du trip
                meta = localTrip.getMetaInformations();
                try
                    participant_tables = localTrip.getAttribute('participant_id');
                catch
                    participant_tables = {};
                end
                idScn = scenario(app, localTrip);
                
                [~, tripName, ~] = fileparts(trip_file);
                %                 fileNames_list = fieldnames(app.elbFichier.Value);
                fileNames_list = fieldnames(app.export);
                namefait = false;
                %iteration sur les fichier a créer
                for i_fic = 1:length(fileNames_list)
                    file_name = strcat(fileNames_list{i_fic});
                    ajoutDansStruct(app,localTrip,fileNames_list{i_fic});
                    struct = app.export.(fileNames_list{i_fic});
                    text = strcat('Export du trip : ', trip_file, ' de la situation/Event', " ", file_name);
                    d.Message = text;
                    %si on a pas de situation presentDans
                    if isempty(struct.situation)
                        data = {};
                        %iteration sur toutes les tables a exporter
                        for i = 1:numel(fieldnames(struct.struct))
                            cols = {};
                            tripNameRec = {};
                            col = ['col' num2str(i)];
                            file_name_exp = struct.struct.(col).table{:};
                            %iteration sur toutes les variables à exporter
                            if ~strcmp(struct.mode,'point')
                                for j = 1:length(struct.struct.(col).var)
                                    cols = [cols, {[struct.struct.(col).table{:} '.' struct.struct.(col).var{j}]}];
                                end
                            else
                                for j = 1:length(struct.struct.(col).var)
                                    cols = [cols, {[struct.struct.(col).table{j} '.' struct.struct.(col).var{j}]}];
                                end
                            end
                            %creation des colonnes obligatoires
                            for l = 1 :size(struct.struct.(col).data,1)
                                tripNameRec{l,1} = tripName;
                                if isempty(participant_tables)
                                    tripNameRec{l,2} = 'No Participant Name';
                                else
                                    tripNameRec{l,2} = participant_tables(1);
                                end
                                tripNameRec{l,3} = idScn;
                                tripNameRec{l,4} = file_name_exp;
                            end
                            if strcmp(struct.type,'type')
                                switch struct.type
                                    case 'data'
                                        timecode = localTrip.getAllDataOccurences(struct.struct.(col).table{1}).getVariableValues('timecode');
                                        timecode = flip(rot90(timecode));
                                        tripNameRec = [tripNameRec, timecode];
                                        %                                     tripNameRec{l,5} = timecode{:};
                                        
                                        cols = ['Timecode',cols];
                                    case 'event'
                                        timecode = localTrip.getAllEventOccurences(struct.struct.(col).table{1}).getVariableValues('timecode');
                                        timecode = flip(rot90(timecode));
                                        tripNameRec = [tripNameRec, timecode];
                                        cols = ['Timecode',cols];
                                    case 'situation'
                                        startTimecode = localTrip.getAllSituationOccurences(struct.struct.(col).table{1}).getVariableValues('startTimecode');
                                        startTimecode = flip(rot90(startTimecode));
                                        endTimecode = localTrip.getAllSituationOccurences(struct.struct.(col).table{1}).getVariableValues('endTimecode');
                                        endTimecode = flip(rot90(endTimecode));
                                        tripNameRec = [tripNameRec, startTimecode, endTimecode];
                                        cols = ['startTimecode','endTimecode',cols];
                                end
                            end
                            %mise en forme des donnees
                            if namefait
                                if numel(fieldnames(struct.struct))>1
                                    temp_data = [ tripNameRec, struct.struct.(col).data];
                                    data = [ data; temp_data];
                                else
                                    data = [ tripNameRec, data, struct.struct.(col).data];
                                end
                                
                            else
                                data = [ tripNameRec, data, struct.struct.(col).data];
                                namefait = true;
                            end
                        end
                        %on a des situations présentDans
                    else %disp('situation')
                        [data,cols] = ExportConcatPresentDans(app, struct,localTrip,tripName,namefait,participant_tables,idScn,file_name);
                    end
                    try
                        data_all = [data_all ; data];
                        success = true;
                    catch
                        if ~contains(error,struct.struct.(col).table{:})
                            error = strcat(error, " ",struct.struct.(col).table{:});
                        end
                        success = false;
                    end
                end
                %supression du trip de l'iteration
                delete(localTrip);
            end
            %mise en forme des donnees avec les nioms des colonnes
            if isempty(error)
                cols = ['Trip Name' , 'Particpant','Scenario', 'File Name', cols];
                alldata =  [cols; data_all];
            else
                alldata = data_all;
            end
            disp(['exporting : ' regexprep(file_name, ' ', '_') ' for ' tripName])
            %                         fr.lescot.bind.export.ExportTrip2Files.ExportTripTableWithStruct2File(trip_file, file_name, struct.type, ...
            %                             cols, data, app.espnSousEchantillon.Value, app.eddFormat.Value, date_time, app.exportFolder)
            
            %mise en place du chemin pour l'export
            null = false;
            if app.exportFolder == 0
                null = true;
            end
            getParentFolder = strfind(trip_file, "\");
            getParentFolder = trip_file(1:getParentFolder(end));
            vide = strcmp(app.exportFolder, '');
            if null || vide
                mkdir (fullfile(getParentFolder,'~DATA_EXPORT'));
                filepathname = fullfile(getParentFolder,'~DATA_EXPORT\');
            else
                filepathname = strcat(app.exportFolder,'\');
            end
            %fin mise en place du chemin pour l'export
            
            %export
            creeExport(app, alldata, file_name, filepathname,d);
            if ~isempty(error)
                text = sprintf(strcat('probleme les tables ne sont pas consistantes en concatenation , ceci est valable pour :'...
                    , error, ".", ' \n veuillez ne pas concatener avec event/situation.'));
                uialert(app.BINDGUIFigure,text,'problem');
            end
        end
        
        %         -------------------------------------------------------------------------------
        
        function concatTrips(app, d)
            
            date_time = char(datetime('now','Format','yyMMdd_HHmm'));
            trip_files = app.selectedTripPaths;
            fileNames_list = fieldnames(app.export);
            
            
            for i_fic = 1:length(fileNames_list)
                file_name = strcat(fileNames_list{i_fic});
                struct = app.export.(fileNames_list{i_fic});
                data_all = {};
                for i_trip = 1:length(trip_files)
                    trip_file = trip_files{i_trip};
                    [~, tripName, ~] = fileparts(trip_file);
                    text = strcat('Export du de la situation/Event :'," ", trip_file, ' trip', " ", file_name);
                    d.Message = text;
                    %d.Message = strcat('Export pour le trip : ', trip_file);
                    localTrip = fr.lescot.bind.kernel.implementation.SQLiteTrip(trip_file, 0.04, false);
                    meta = localTrip.getMetaInformations();
                    try
                        participant_tables = localTrip.getAttribute('participant_id');
                    catch
                        participant_tables = {};
                    end
                    idScn = scenario(app, localTrip);
                    ajoutDansStruct(app,localTrip,fileNames_list{i_fic});
                    struct = app.export.(fileNames_list{i_fic});
                    %                 fileNames_list = fieldnames(app.elbFichier.Value);
                    namefait = false;
                    if isempty(struct.situation)
                        data = {};
                        cols = {};
                        tripNameRec = {};
                        for i = 1:numel(fieldnames(struct.struct))
                            col = ['col' num2str(i)];
                            if ~strcmp(struct.mode,'point')
                                for j = 1:length(struct.struct.(col).var)
                                    cols = [cols, {[struct.struct.(col).table{:} '.' struct.struct.(col).var{j}]}];
                                end
                            else
                                for j = 1:length(struct.struct.(col).var)
                                    cols = [cols, {[struct.struct.(col).table{j} '.' struct.struct.(col).var{j}]}];
                                end
                            end
                            for l = 1 :size(struct.struct.(col).data,1)
                                tripNameRec{l,1} = tripName;
                                if isempty(participant_tables)
                                    tripNameRec{l,2} = 'No Participant Name';
                                else
                                    tripNameRec{l,2} = participant_tables;
                                end
                                tripNameRec{l,3} = idScn;
                                tripNameRec{l,4} = file_name;
                            end
                            switch struct.type
                                case 'data'
                                    timecode = localTrip.getAllDataOccurences(struct.struct.(col).table{1}).getVariableValues('timecode');
                                    timecode = flip(rot90(timecode));
                                    tripNameRec = [tripNameRec, timecode];
                                    %                                     tripNameRec{l,5} = timecode{:};
                                    
                                    cols = ['Timecode',cols];
                                case 'event'
                                    timecode = localTrip.getAllEventOccurences(struct.struct.(col).table{1}).getVariableValues('timecode');
                                    timecode = flip(rot90(timecode));
                                    tripNameRec = [tripNameRec, timecode];
                                    cols = ['Timecode',cols];
                                case 'situation'
                                    startTimecode = localTrip.getAllSituationOccurences(struct.struct.(col).table{1}).getVariableValues('startTimecode');
                                    startTimecode = flip(rot90(startTimecode));
                                    endTimecode = localTrip.getAllSituationOccurences(struct.struct.(col).table{1}).getVariableValues('endTimecode');
                                    endTimecode = flip(rot90(endTimecode));
                                    tripNameRec = [tripNameRec, startTimecode, endTimecode];
                                    cols = ['startTimecode','endTimecode',cols];
                            end
                            if namefait
                                if numel(fieldnames(struct.struct))>1
                                    temp_data = [ tripNameRec, struct.struct.(col).data];
                                    data = [ data; temp_data];
                                else
                                    data = [ tripNameRec, data, struct.struct.(col).data];
                                end
                                
                            else
                                data = [ tripNameRec, data, struct.struct.(col).data];
                                namefait = true;
                            end
                        end
                    else
                        disp('situation')
                        [data,cols] = ExportConcatPresentDans(app, struct,localTrip,tripName,namefait,participant_tables,idScn,file_name);
                        
                        %                       a faire bien sur
                    end
                    data_all = [data_all ; data];
                    delete(localTrip);
                end
                cols = ['Trip Name' , 'Particpant','Scenario', 'File Name', cols];
                alldata =  [cols; data_all];
                disp(['exporting : ' regexprep(file_name, ' ', '_') ' for ' tripName])
                %                         fr.lescot.bind.export.ExportTrip2Files.ExportTripTableWithStruct2File(trip_file, file_name, struct.type, ...
                %                             cols, data, app.espnSousEchantillon.Value, app.eddFormat.Value, date_time, app.exportFolder)
                null = false;
                if app.exportFolder == 0
                    null = true;
                end
                getParentFolder = strfind(trip_file, "\");
                getParentFolder = trip_file(1:getParentFolder(end));
                vide = strcmp(app.exportFolder, '');
                if null || vide
                    mkdir(fullfile(getParentFolder,'~DATA_EXPORT'));
                    filepathname = fullfile(getParentFolder,'~DATA_EXPORT\');
                else
                    filepathname = strcat(app.exportFolder,'\');
                end
                
                %                      disp(['exporting : ' regexprep(file_name, ' ', '_') ' for ' tripName])
                %                      fr.lescot.bind.export.ExportTrip2Files.ExportTripTableWithStruct2File(trip_file, file_name, struct.type, ...
                %                          cols, data, app.espnSousEchantillon.Value, app.eddFormat.Value, date_time, app.exportFolder)
                creeExport(app, alldata, file_name, filepathname,d);
                
            end
            
        end
        %         -------------------------------------------------------------------------------
        function concatsimple(app,d)
            error = '';
            date_time = char(datetime('now','Format','yyMMdd_HHmm'));
            trip_file = app.trip.getTripPath;
            fileNames_list = fieldnames(app.export);
            data_all = {};
            
            [~, tripName, ~] = fileparts(trip_file);
            localTrip = fr.lescot.bind.kernel.implementation.SQLiteTrip(trip_file, 0.04, false);
            meta = localTrip.getMetaInformations();
            try
                participant_tables = localTrip.getAttribute('participant_id');
            catch
                participant_tables = {};
            end
            idScn = scenario(app, localTrip);
            
            namefait = false;
            data_all = {};
            for i_fic = 1:length(fileNames_list)
                file_name = strcat(fileNames_list{i_fic});
                ajoutDansStruct(app,localTrip,fileNames_list{i_fic});
                struct = app.export.(fileNames_list{i_fic});
                text = strcat('Export du trip : ', trip_file, ' de la situation/Event', " ", file_name);
                d.Message = text;
                if isempty(struct.situation)
                    data = {};
                    cols = {};
                    tripNameRec = {};
                    for i = 1:numel(fieldnames(struct.struct))
                        col = ['col' num2str(i)];
                        if ~strcmp(struct.mode,'point')
                            for j = 1:length(struct.struct.(col).var)
                                cols = [cols, {[struct.struct.(col).table{:} '.' struct.struct.(col).var{j}]}];
                            end
                        else
                            for j = 1:length(struct.struct.(col).var)
                                cols = [cols, {[struct.struct.(col).table{j} '.' struct.struct.(col).var{j}]}];
                            end
                        end
                        for l = 1 :size(struct.struct.(col).data,1)
                            tripNameRec{l,1} = tripName;
                            if isempty(participant_tables)
                                tripNameRec{l,2} = 'No Participant Name';
                            else
                                tripNameRec{l,2} = participant_tables(1);
                            end
                            tripNameRec{l,3} = idScn;
                            tripNameRec{l,4} = file_name;
                        end
                        switch struct.type
                            case 'data'
                                timecode = localTrip.getAllDataOccurences(struct.struct.(col).table{1}).getVariableValues('timecode');
                                timecode = flip(rot90(timecode));
                                tripNameRec = [tripNameRec, timecode];
                                %                                     tripNameRec{l,5} = timecode{:};
                                
                                cols = ['Timecode',cols];
                            case 'event'
                                timecode = localTrip.getAllEventOccurences(struct.struct.(col).table{1}).getVariableValues('timecode');
                                timecode = flip(rot90(timecode));
                                tripNameRec = [tripNameRec, timecode];
                                cols = ['Timecode',cols];
                            case 'situation'
                                startTimecode = localTrip.getAllSituationOccurences(struct.struct.(col).table{1}).getVariableValues('startTimecode');
                                startTimecode = flip(rot90(startTimecode));
                                endTimecode = localTrip.getAllSituationOccurences(struct.struct.(col).table{1}).getVariableValues('endTimecode');
                                endTimecode = flip(rot90(endTimecode));
                                tripNameRec = [tripNameRec, startTimecode, endTimecode];
                                cols = ['startTimecode','endTimecode',cols];
                        end
                        if namefait
                            if numel(fieldnames(struct.struct))>1
                                temp_data = [ tripNameRec, struct.struct.(col).data];
                                data = [ data; temp_data];
                            else
                                data = [ tripNameRec, data, struct.struct.(col).data];
                            end
                            
                        else
                            data = [ tripNameRec, data, struct.struct.(col).data];
                            namefait = true;
                        end
                        %                             if namefait
                        %                                 data = [ tripNameRec, data, struct.struct.(col).data];
                        %                             else
                        %                                 data = [ tripNameRec, data, struct.struct.(col).data];
                        %                                 namefait = true;
                        %                             end
                    end
                else
                    disp('situation')
                    [data,cols] = ExportConEveSitPresentDans(app, struct,localTrip,tripName,namefait,participant_tables,idScn,file_name);
                    %                       a faire bien sur
                end
                try
                    data_all = [data_all ; data];
                    success = true;
                catch
                    if ~contains(error,struct.struct.(col).table{:})
                        error = strcat(error, " ",struct.struct.(col).table{:});
                    end
                    success = false;
                end
                
                if success
                    cols = ['Trip Name' , 'Particpant','Scenario', 'File Name', cols];
                    alldata =  [cols; data_all];
                else
                    alldata = data_all;
                end
                %file_name = strcat(file_name,'_',num2str(i_trip));
                file_name = strcat(file_name);
                disp(['exporting : ' regexprep(file_name, ' ', '_') ' for ' tripName])
                %                         fr.lescot.bind.export.ExportTrip2Files.ExportTripTableWithStruct2File(trip_file, file_name, struct.type, ...
                %                             cols, data, app.espnSousEchantillon.Value, app.eddFormat.Value, date_time, app.exportFolder)
                null = false;
                if app.exportFolder == 0
                    null = true;
                end
                getParentFolder = strfind(trip_file, "\");
                getParentFolder = trip_file(1:getParentFolder(end));
                vide = strcmp(app.exportFolder, '');
                if null || vide
                    mkdir(fullfile(getParentFolder,'~DATA_EXPORT'));
                    filepathname = fullfile(getParentFolder,'~DATA_EXPORT\');
                else
                    filepathname = strcat(app.exportFolder,'\');
                end
                
                %                      disp(['exporting : ' regexprep(file_name, ' ', '_') ' for ' tripName])
                %                      fr.lescot.bind.export.ExportTrip2Files.ExportTripTableWithStruct2File(trip_file, file_name, struct.type, ...
                %                          cols, data, app.espnSousEchantillon.Value, app.eddFormat.Value, date_time, app.exportFolder)
                
            end
            creeExport(app, alldata, file_name, filepathname);
            delete(localTrip);
            if ~isempty(error)
                text = sprintf(strcat('probleme les tables ne sont pas consistantes en concatenation , ceci est valable pour :'...
                    , error, ".", ' \n veuillez ne pas concatener avec event/situation.'));
                uialert(app.BINDGUIFigure,text,'problem');
            end
        end
        
        
        function concatEventSituation(app, d)
            error = '';
            date_time = char(datetime('now','Format','yyMMdd_HHmm'));
            trip_files = app.selectedTripPaths;
            fileNames_list = fieldnames(app.export);
            data_all = {};
            
            for i_trip = 1:length(trip_files)
                trip_file = trip_files{i_trip};
                [~, tripName, ~] = fileparts(trip_file);
                localTrip = fr.lescot.bind.kernel.implementation.SQLiteTrip(trip_file, 0.04, false);
                meta = localTrip.getMetaInformations();
                try
                    participant_tables = localTrip.getAttribute('participant_id');
                catch
                    participant_tables = {};
                end
                idScn = scenario(app, localTrip);
                
                namefait = false;
                data_all = {};
                for i_fic = 1:length(fileNames_list)
                    file_name = strcat(fileNames_list{i_fic});
                    ajoutDansStruct(app,localTrip,fileNames_list{i_fic});
                    struct = app.export.(fileNames_list{i_fic});
                    text = strcat('Export du trip : ', trip_file, ' de la situation/Event', " ", file_name);
                    d.Message = text;
                    if isempty(struct.situation)
                        data = {};
                        cols = {};
                        tripNameRec = {};
                        for i = 1:numel(fieldnames(struct.struct))
                            col = ['col' num2str(i)];
                            if ~strcmp(struct.mode,'point')
                                for j = 1:length(struct.struct.(col).var)
                                    cols = [cols, {[struct.struct.(col).table{:} '.' struct.struct.(col).var{j}]}];
                                end
                            else
                                for j = 1:length(struct.struct.(col).var)
                                    cols = [cols, {[struct.struct.(col).table{j} '.' struct.struct.(col).var{j}]}];
                                end
                            end
                            for l = 1 :size(struct.struct.(col).data,1)
                                tripNameRec{l,1} = tripName;
                                if isempty(participant_tables)
                                    tripNameRec{l,2} = 'No Participant Name';
                                else
                                    tripNameRec{l,2} = participant_tables(1);
                                end
                                tripNameRec{l,3} = idScn;
                                tripNameRec{l,4} = file_name;
                            end
                            switch struct.type
                                case 'data'
                                    timecode = localTrip.getAllDataOccurences(struct.struct.(col).table{1}).getVariableValues('timecode');
                                    timecode = flip(rot90(timecode));
                                    tripNameRec = [tripNameRec, timecode];
                                    %                                     tripNameRec{l,5} = timecode{:};
                                    
                                    cols = ['Timecode',cols];
                                case 'event'
                                    timecode = localTrip.getAllEventOccurences(struct.struct.(col).table{1}).getVariableValues('timecode');
                                    timecode = flip(rot90(timecode));
                                    tripNameRec = [tripNameRec, timecode];
                                    cols = ['Timecode',cols];
                                case 'situation'
                                    startTimecode = localTrip.getAllSituationOccurences(struct.struct.(col).table{1}).getVariableValues('startTimecode');
                                    startTimecode = flip(rot90(startTimecode));
                                    endTimecode = localTrip.getAllSituationOccurences(struct.struct.(col).table{1}).getVariableValues('endTimecode');
                                    endTimecode = flip(rot90(endTimecode));
                                    tripNameRec = [tripNameRec, startTimecode, endTimecode];
                                    cols = ['startTimecode','endTimecode',cols];
                            end
                            if namefait
                                if numel(fieldnames(struct.struct))>1
                                    temp_data = [ tripNameRec, struct.struct.(col).data];
                                    data = [ data; temp_data];
                                else
                                    data = [ tripNameRec, data, struct.struct.(col).data];
                                end
                                
                            else
                                data = [ tripNameRec, data, struct.struct.(col).data];
                                namefait = true;
                            end
                            %                             if namefait
                            %                                 data = [ tripNameRec, data, struct.struct.(col).data];
                            %                             else
                            %                                 data = [ tripNameRec, data, struct.struct.(col).data];
                            %                                 namefait = true;
                            %                             end
                        end
                    else
                        disp('situation')
                        [data,cols] = ExportConEveSitPresentDans(app, struct,localTrip,tripName,namefait,participant_tables,idScn,file_name);
                        %                       a faire bien sur
                    end
                    try
                        data_all = [data_all ; data];
                        success = true;
                    catch
                        if ~contains(error,struct.struct.(col).table{:})
                            error = strcat(error, " ",struct.struct.(col).table{:});
                        end
                        success = false;
                    end
                end
                if success
                    cols = ['Trip Name' , 'Particpant','Scenario', 'File Name', cols];
                    alldata =  [cols; data_all];
                else
                    alldata = data_all;
                end
                file_name = strcat(file_name,'_',num2str(i_trip));
                disp(['exporting : ' regexprep(file_name, ' ', '_') ' for ' tripName])
                %                         fr.lescot.bind.export.ExportTrip2Files.ExportTripTableWithStruct2File(trip_file, file_name, struct.type, ...
                %                             cols, data, app.espnSousEchantillon.Value, app.eddFormat.Value, date_time, app.exportFolder)
                null = false;
                if app.exportFolder == 0
                    null = true;
                end
                getParentFolder = strfind(trip_file, "\");
                getParentFolder = trip_file(1:getParentFolder(end));
                vide = strcmp(app.exportFolder, '');
                if null || vide
                    mkdir (fullfile(getParentFolder,'~DATA_EXPORT'));
                    filepathname = fullfile(getParentFolder,'~DATA_EXPORT\');
                else
                    filepathname = strcat(app.exportFolder,'\');
                end
                
                %                      disp(['exporting : ' regexprep(file_name, ' ', '_') ' for ' tripName])
                %                      fr.lescot.bind.export.ExportTrip2Files.ExportTripTableWithStruct2File(trip_file, file_name, struct.type, ...
                %                          cols, data, app.espnSousEchantillon.Value, app.eddFormat.Value, date_time, app.exportFolder)
                creeExport(app, alldata, file_name, filepathname);
                
                delete(localTrip);
            end
            if ~isempty(error)
                text = sprintf(strcat('probleme les tables ne sont pas consistantes en concatenation , ceci est valable pour :'...
                    , error, ".", ' \n veuillez ne pas concatener avec event/situation.'));
                uialert(app.BINDGUIFigure,text,'problem');
            end
        end
        
        %         -------------------------------------------------------------------------------
        
        function exportTousNormal(app, d)
            trip_files = app.selectedTripPaths;
            %itération sur les trips dans la selection
            for i_trip = 1:length(trip_files)
                trip_file = trip_files{i_trip};
                % récupération du nom du trip
                [~, tripName, ~] = fileparts(trip_file);
                %chargement du trip
                localTrip = fr.lescot.bind.kernel.implementation.SQLiteTrip(trip_file, 0.04, false);
                meta = localTrip.getMetaInformations();
                try
                    %récupération du nom du paticipant
                    participant_tables = localTrip.getAttribute('participant_id');
                catch
                    participant_tables = {};
                end
                %nom du scénario
                idScn = scenario(app, localTrip);
                %                fileNames_list = fieldnames(app.elbFichier.Value);
                fileNames_list = fieldnames(app.export);
                namefait = false;
                %itération sur les fichier à créer
                for i_fic = 1:length(fileNames_list)
                    folder_name = fileNames_list{i_fic};
                    file_name = strcat(fileNames_list{i_fic}, '_', participant_tables);
                    
                    %%%%% A quoi ça sert??? %%%%%
                    %                     ajoutDansStruct(app,localTrip,fileNames_list{i_fic});
                    
                    struct = app.export.(fileNames_list{i_fic});
                    % si on a pas selectionné de situation présent dans
                    if isempty(struct.situation)
                        cols = {};
                        data = {};
                        tripNameRec = {};
                        for i = 1:numel(fieldnames(struct.struct))
                            col = ['col' num2str(i)];
                            if ~strcmp(struct.mode,'point')
                                for j = 1:length(struct.struct.(col).var)
                                    cols = [cols, {[struct.struct.(col).table{:} '.' struct.struct.(col).var{j}]}];
                                end
                            else
                                for j = 1:length(struct.struct.(col).var)
                                    cols = [cols, {[struct.struct.(col).table{j} '.' struct.struct.(col).var{j}]}];
                                end
                            end
                            %                             %création des colonnes obligatoires
                            %                             for l = 1:length(struct.struct.(col).data)
                            %                                 tripNameRec{l,1} = tripName;
                            %                                 if isempty(participant_tables)
                            %                                     tripNameRec{l,2} = 'No Participant Name';
                            %                                 else
                            %                                     tripNameRec{l,2} = participant_tables;
                            %                                 end
                            %                                 tripNameRec{l,3} = idScn;
                            %                                 tripNameRec{l,4} = file_name;
                            %                             end
                            %                             switch struct.type
                            %                                 case 'data'
                            %                                     timecode = localTrip.getAllDataOccurences(struct.struct.(col).table{1}).getVariableValues('timecode');
                            %                                     timecode = flip(rot90(timecode));
                            %                                     tripNameRec = [tripNameRec, timecode];
                            %                                     cols = ['Timecode',cols];
                            %                                 case 'event'
                            %                                     timecode = localTrip.getAllEventOccurences(struct.struct.(col).table{1}).getVariableValues('timecode');
                            %                                     timecode = flip(rot90(timecode));
                            %                                     tripNameRec = [tripNameRec, timecode];
                            %                                     cols = ['Timecode',cols];
                            %                                 case 'situation'
                            %                                     startTimecode = localTrip.getAllSituationOccurences(struct.struct.(col).table{1}).getVariableValues('startTimecode');
                            %                                     startTimecode = flip(rot90(startTimecode));
                            %                                     endTimecode = localTrip.getAllSituationOccurences(struct.struct.(col).table{1}).getVariableValues('endTimecode');
                            %                                     endTimecode = flip(rot90(endTimecode));
                            %                                     tripNameRec = [tripNameRec, startTimecode, endTimecode];
                            %                                     cols = ['startTimecode','endTimecode',cols];
                            %                             end
                            
                            %création des colonnes obligatoires
                            tripNameCol = repmat({tripName},length(struct.struct.(col).data),1);
                            if isempty(participant_tables)
                                partNameCol = repmat({'No Participant Name'},length(struct.struct.(col).data),1);
                            else
                                partNameCol = repmat({participant_tables},length(struct.struct.(col).data),1);
                            end
                            scnNameRec = repmat({idScn},length(struct.struct.(col).data),1);
                            %                 fileNameRec = repmat({file_name},length(newdata),1);
                            tripNameRec = [tripNameCol, partNameCol, scnNameRec]; %, situationNameCol]; %fileNameRec
                            
                            %création des data
                            if namefait
                                data = [tripNameRec, data, struct.struct.(col).data];
                            else
                                data = [tripNameRec, data, struct.struct.(col).data];
                                namefait = true;
                            end
                        end
                        %concaténation des headers avec les data
                        cols = ['Trip Name' , 'Particpant','Scenario', cols];
                        alldata =  [cols; data];
                        disp(['exporting : ' regexprep(file_name, ' ', '_') ' for ' tripName])
                        %                         fr.lescot.bind.export.ExportTrip2Files.ExportTripTableWithStruct2File(trip_file, file_name, struct.type, ...
                        %                             cols, data, app.espnSousEchantillon.Value, app.eddFormat.Value, date_time, app.exportFolder)
                        
                        %mise en place du chemin d'export
                        parentFolder = strfind(trip_file, "\");
                        parentFolder = trip_file(1:parentFolder(end));
                        if any(or(app.exportFolder == 0, strcmp(app.exportFolder, '')))
                            filepathname = fullfile(parentFolder, '~DATA_EXPORT', app.date_time, folder_name);
                        else
                            filepathname = fullfile(app.exportFolder, '~DATA_EXPORT', app.date_time, folder_name);
                        end
                        mkdir(filepathname)
                        
                        %                         % mise en place du chemin pour l'export
                        %                         null = false;
                        %                         if app.exportFolder == 0
                        %                             null = true;
                        %                         end
                        %                         getParentFolder = strfind(trip_file, "\");
                        %                         getParentFolder = trip_file(1:getParentFolder(end));
                        %                         vide = strcmp(app.exportFolder, '');
                        %                         if null || vide
                        %                             mkdir (fullfile(getParentFolder,'~DATA_EXPORT'));
                        %                             filepathname = fullfile(getParentFolder,'~DATA_EXPORT\');
                        %                         else
                        %                             filepathname = strcat(app.exportFolder,'\');
                        %                         end
                        %                      disp(['exporting : ' regexprep(file_name, ' ', '_') ' for ' tripName])
                        %                      fr.lescot.bind.export.ExportTrip2Files.ExportTripTableWithStruct2File(trip_file, file_name, struct.type, ...
                        %                          cols, data, app.espnSousEchantillon.Value, app.eddFormat.Value, date_time, app.exportFolder)
                        
                        %export
                        creeExport(app, alldata, file_name, filepathname);
                        
                        %on a choisit des situations presentDans
                    else
                        disp('situation')
                        
                        [alldata, file_name, filepathname] = exporterTousNormalPresentDans(app,struct,localTrip,tripName,namefait,participant_tables,idScn,trip_file,folder_name,file_name,d);
                        creeExport(app, alldata, file_name, filepathname);
                    end
                end
                %supression du trip sur lequel la boucle est instanciée
                delete(localTrip);
            end
        end
        
        
        %         -------------------------------------------------------------------------------
        
        function filepathname = createFilePathName(app, trip_file, folder_name)
            parentFolder = strfind(trip_file, "\");
            parentFolder = trip_file(1:parentFolder(end));
            if isempty(app.exportFolder)
                app.exportFolder = parentFolder;
            end
            filepathname = fullfile(app.exportFolder, '~DATA_EXPORT', app.date_time, folder_name);
            mkdir(filepathname)
        end
        
        %         -------------------------------------------------------------------------------
        
        function creeExport(app, alldata, file_name, filepathname,d)
            name = fullfile(filepathname,[file_name,app.eddFormat.Value]);
            d.Message = strcat('Création fichier :'," ", name);
            switch app.eddFormat.Value
                case '.xlsx'
                    writecell(alldata, name);
                case '.csv'
                    writecell(alldata, name, 'Delimiter', ';');
                case '.tsv'
                    name = strcat(filepathname,file_name,'.csv');
                    writecell(alldata, name, 'Delimiter', 'tab');
                    movefile(name,[name(1:end-3), 'tsv']);
                case '.txt'
                    writecell(alldata, name ,'Delimiter', 'tab');
                case '.trip'
                    uialert(app.BINDGUIFigure,"houla ça c'est pas fait !", 'dommage', 'Icon', 'error');
            end
        end
        
        %         -------------------------------------------------------------------------------
        
        function creeExportConcat(app, alldata, file_name, filepathname,d)
            name = fullfile(filepathname,[file_name,app.eddFormat.Value]);
            d.Message = strcat('Création fichier :'," ", name);
            switch app.eddFormat.Value
                case '.xlsx'
                    writecell(alldata, name, 'WriteMode', 'append');
                case '.csv'
                    writecell(alldata, name, 'Delimiter', ';', 'WriteMode', 'append');
                case '.tsv'
                    name = strcat(filepathname, file_name, '.csv');
                    writecell(alldata, name, 'Delimiter', 'tab', 'WriteMode', 'append');
                    movefile(name, [name(1:end-3), 'tsv']);
                case '.txt'
                    writecell(alldata, name , 'Delimiter', 'tab', 'WriteMode', 'append');
                case '.trip'
                    uialert(app.BINDGUIFigure,"houla ça c'est pas fait !", 'dommage', 'Icon', 'error');
            end
        end
        
        %         -------------------------------------------------------------------------------
        
        function results = scenario(app, localTrip)
            if any(contains(localTrip.getMetaInformations().getTripAttributesList,'id_scenario'))
                idScn = ['SCN_' localTrip.getAttribute('id_scenario')];
            elseif any(contains(localTrip.getMetaInformations().getTripAttributesList,'scenario'))
                idScn = ['SCN_' localTrip.getAttribute('scenario')];
            else
                idScn = 'SCN_VIDE';
            end
            % Chargement des data et des events/situations en fonction du trip de référence
            results =  idScn;
        end
        %         -------------------------------------------------------------------------------
        
        %         -------------------------------------------------------------------------------
        
        function [results,cols] = ExportConcatPresentDans(app, struct,localTrip,tripName,namefait,participant_tables,idScn,file_name)
            data = {};
            
            for i = 1:numel(fieldnames(struct.struct))
                cols = {};
                tripNameRec = {};
                col = ['col' num2str(i)];
                if ~strcmp(struct.mode,'point')
                    for nbcol = 1:length(struct.struct.(col).var)
                        cols = [cols, {[struct.struct.(col).table{:} '.' struct.struct.(col).var{nbcol}]}];
                    end
                else
                    for nbcol = 1:length(struct.struct.(col).var)
                        cols = [cols, {[struct.struct.(col).table{nbcol} '.' struct.struct.(col).var{nbcol}]}];
                    end
                end
                for l = 1 :length(struct.struct.(col).data)
                    tripNameRec{l,1} = tripName;
                    if isempty(participant_tables)
                        tripNameRec{l,2} = 'No Participant Name';
                    else
                        tripNameRec{l,2} = participant_tables(1);
                    end
                    tripNameRec{l,3} = idScn;
                    tripNameRec{l,4} = file_name;
                end
                switch struct.type
                    case 'data'
                        timecode = localTrip.getAllDataOccurences(struct.struct.(col).table{1}).getVariableValues('timecode');
                        timecode = flip(rot90(timecode));
                        tripNameRec = [tripNameRec, timecode];
                        %                                     tripNameRec{l,5} = timecode{:};
                        
                        cols = ['Timecode',cols];
                    case 'event'
                        timecode = localTrip.getAllEventOccurences(struct.struct.(col).table{1}).getVariableValues('timecode');
                        timecode = flip(rot90(timecode));
                        tripNameRec = [tripNameRec, timecode];
                        cols = ['Timecode',cols];
                    case 'situation'
                        startTimecode = localTrip.getAllSituationOccurences(struct.struct.(col).table{1}).getVariableValues('startTimecode');
                        startTimecode = flip(rot90(startTimecode));
                        endTimecode = localTrip.getAllSituationOccurences(struct.struct.(col).table{1}).getVariableValues('endTimecode');
                        endTimecode = flip(rot90(endTimecode));
                        tripNameRec = [tripNameRec, startTimecode, endTimecode];
                        cols = ['startTimecode','endTimecode',cols];
                end
                for j = 1:length(struct.situation)
                    situations = localTrip.getAllSituationOccurences(struct.situation{j});
                    allStarts_presentDans = situations.getVariableValues('startTimecode');
                    allStarts_presentDans = cell2mat(allStarts_presentDans);
                    allEnds_presentDans = situations.getVariableValues('endTimecode');
                    allEnds_presentDans = cell2mat(allEnds_presentDans);
                    startsSituation(j) = {allStarts_presentDans};
                    endsSituation(j) = {allEnds_presentDans};
                end
                donnees = struct.struct.(col).data;
                
                if strcmp(app.elbType.Value,'situation')
                    for indice = 1:length(struct.struct.(col).var)
                        if strcmp(struct.struct.(col).var(indice), 'startTimecode')
                            indiceStart = indice;
                        elseif strcmp(struct.struct.(col).var(indice), 'endTimecode')
                            indiceEnd = indice;
                        end
                    end
                else
                    for indice = 1:length(struct.struct.(col).var)
                        if strcmp(struct.struct.(col).var(indice), 'timecode')
                            indiceTimecode = indice;
                        end
                    end
                end
                
                mask = false;
                masktripNameRec = false;
                masktripNameRec(size(donnees,1),5) = false;
                mask(size(donnees,1),indice) = false;
                
                if strcmp(app.elbType.Value,'situation')
                    for situ = 1 :length(startsSituation)
                        for j = 1:length(startsSituation{situ})
                            for d = 1:size(donnees,1)
                                Start_timecode_bon = tripNameRec{d,5};
                                End_timecode_bon = tripNameRec{d,6};
                                if (startsSituation{situ}(j)<= Start_timecode_bon && End_timecode_bon <= endsSituation{situ}(j))
                                    mask(d,1:indice) = true;
                                    masktripNameRec(d,1:6) = true;
                                end
                            end
                        end
                    end
                else
                    for situ = 1 :length(startsSituation)
                        for j = 1:length(startsSituation{situ})
                            for d = 1:size(donnees,1)
                                timecode_bon = tripNameRec{d,5};
                                if (startsSituation{situ}(j)<= timecode_bon && timecode_bon <= endsSituation{situ}(j))
                                    mask(d,1:indice) = true;
                                    masktripNameRec(d,1:5) = true;
                                end
                            end
                        end
                    end
                end
                numTrueData = nnz(mask(:,1));
                numTrueTripNameRec = nnz(masktripNameRec(:,1));
                newdata = struct.struct.(col).data(mask);
                %                             newdata = reshape(newdata, numTrue, 2)';
                newdata = flip(rot90(reshape(newdata, numTrueData, nbcol)'));
                newtripNameRec = tripNameRec(masktripNameRec);
                newtripNameRec = flip(rot90(reshape(newtripNameRec, numTrueTripNameRec, size(masktripNameRec,2))'));
                
                %                 if namefait
                %                     data = [newtripNameRec, data, newdata];
                %                 else
                %                     data = [newtripNameRec, data, newdata];
                %                     namefait = true;
                %                 end
                if namefait
                    if numel(fieldnames(struct.struct))>1
                        temp_data = [ newtripNameRec, newdata];
                        data = [ data; temp_data];
                    else
                        data = [ newtripNameRec, data, newdata];
                    end
                    
                else
                    data = [ newtripNameRec, data, newdata];
                    namefait = true;
                end
                
            end
            results = data;
        end
        
        %         -------------------------------------------------------------------------------
        
        function ajoutDansStruct(app, localTrip,nameField)
            %initialisation
            i = 1;
            fic = ['fic',num2str(i)];
            %fic = nameField;
            %boucle tant que col existe
            while(isfield(app.export,fic))
                i = i+1;
                % mise a jour de la structure
%                 if ~strcmp(app.export.(fic).mode, 'point')
%                     if ~isempty(fic)
%                         app.export.(fic).struct.(col).var = getVars(app, localTrip, app.export.(fic).type, app.export.(fic).struct.(col).table{:}, app.export.(fic).struct.(col).var);
%                         app.export.(fic).struct.(col).data = buildArray(app, localTrip, app.export.(fic).type, app.export.(fic).struct.(col).table{:}, app.export.(fic).struct.(col).var);
%                     end
%                 else
%                     app.export.(fic).struct.(col).data = {};
%                     for i = 1:length(app.export.(fic).struct.(col).table)
%                         
%                         if isfield(app.export.(fic).struct.(col), 'var')
%                             if isempty(app.export.(fic).struct.(col).data)
%                                 app.export.(fic).struct.(col).data = buildArray(app, localTrip, app.export.(fic).type, app.export.(fic).struct.(col).table{i}, app.export.(fic).struct.(col).var(i));
%                             else
%                                 app.export.(fic).struct.(col).data(:,i) = buildArray(app, localTrip, app.export.(fic).type, app.export.(fic).struct.(col).table{i}, app.export.(fic).struct.(col).var(i));
%                             end
%                         else
%                             app.export.(fic).struct.(col).var = getVars(app, app.trip, app.export.(fic).type, app.structCol.(col).table{:}, '');
%                             app.export.(fic).struct.(col).data = buildArray(app, app.trip, app.export.(fic).type, app.structCol.(col).table{:}, '');
%                         end
%                     end
%                 end
%                 %mise a jour de col
%                 col = ['col',num2str(i)];
                app.export.(fic).vars = getVars(app, localTrip, app.export.(fic).type, app.export(fic).tables, app.export.(fic).vars);
                app.export.(fic).datas = builArray(app, localTrip, app.export, app.export.(fic).tables, app.export.(fic).vars);
                %passage au fichier suivant
                fic = ['fic', num2str(i)];
            end
        end
        %         -------------------------------------------------------------------------------
        
        function [results,cols] = ExportConEveSitPresentDans(app, struct,localTrip,tripName,namefait,participant_tables,idScn,file_name)
            data = {};
            for i = 1:numel(fieldnames(struct.struct))
                cols = {};
                tripNameRec = {};
                col = ['col' num2str(i)];
                for nbcol = 1:length(struct.struct.(col).var)
                    cols = [cols, {[struct.struct.(col).table{:} '.' struct.struct.(col).var{nbcol}]}];
                end
                for l = 1 :length(struct.struct.(col).data)
                    tripNameRec{l,1} = tripName;
                    if isempty(participant_tables)
                        tripNameRec{l,2} = 'No Participant Name';
                    else
                        tripNameRec{l,2} = participant_tables(1);
                    end
                    tripNameRec{l,3} = idScn;
                    tripNameRec{l,4} = file_name;
                end
                
                for j = 1:length(struct.situation)
                    situations = localTrip.getAllSituationOccurences(struct.situation{j});
                    allStarts_presentDans = situations.getVariableValues('startTimecode');
                    allStarts_presentDans = cell2mat(allStarts_presentDans);
                    allEnds_presentDans = situations.getVariableValues('endTimecode');
                    allEnds_presentDans = cell2mat(allEnds_presentDans);
                    startsSituation(j) = {allStarts_presentDans};
                    endsSituation(j) = {allEnds_presentDans};
                end
                donnees = struct.struct.(col).data;
                
                if strcmp(app.elbType.Value,'situation')
                    for indice = 1:length(struct.struct.(col).var)
                        if strcmp(struct.struct.(col).var(indice), 'startTimecode')
                            indiceStart = indice;
                        elseif strcmp(struct.struct.(col).var(indice), 'endTimecode')
                            indiceEnd = indice;
                        end
                    end
                else
                    for indice = 1:length(struct.struct.(col).var)
                        if strcmp(struct.struct.(col).var(indice), 'timecode')
                            indiceTimecode = indice;
                        end
                    end
                end
                
                mask = false;
                masktripNameRec = false;
                masktripNameRec(size(donnees,1),4) = false;
                mask(size(donnees,1),indice) = false;
                
                if strcmp(app.elbType.Value,'situation')
                    for situ = 1 :length(startsSituation)
                        for j = 1:length(startsSituation{situ})
                            for d = 1:size(donnees,1)
                                Start_timecode_bon = donnees{d,indiceStart};
                                End_timecode_bon = donnees{d,indiceEnd};
                                if (startsSituation{situ}(j)<= Start_timecode_bon && End_timecode_bon <= endsSituation{situ}(j))
                                    mask(d,1:indice) = true;
                                    masktripNameRec(d,1:4) = true;
                                end
                            end
                        end
                    end
                else
                    for situ = 1 :length(startsSituation)
                        for j = 1:length(startsSituation{situ})
                            for d = 1:size(donnees,1)
                                timecode_bon = donnees{d,indiceTimecode};
                                if (startsSituation{situ}(j)<= timecode_bon && timecode_bon <= endsSituation{situ}(j))
                                    mask(d,1:indice) = true;
                                    masktripNameRec(d,1:4) = true;
                                end
                            end
                        end
                    end
                end
                numTrueData = nnz(mask(:,1));
                numTrueTripNameRec = nnz(masktripNameRec(:,1));
                newdata = struct.struct.(col).data(mask);
                %                             newdata = reshape(newdata, numTrue, 2)';
                newdata = flip(rot90(reshape(newdata, numTrueData, nbcol)'));
                newtripNameRec = tripNameRec(masktripNameRec);
                newtripNameRec = flip(rot90(reshape(newtripNameRec, numTrueTripNameRec, 4)'));
                
                %newtripNameRec = tripNameRec(masktripNameRec,:);
                if namefait
                    if numel(fieldnames(struct.struct))>1
                        temp_data = [ newtripNameRec, newdata];
                        data = [ data; temp_data];
                    else
                        data = [ newtripNameRec, data, newdata];
                    end
                    
                else
                    data = [ newtripNameRec, data, newdata];
                    namefait = true;
                end
            end
            results = data;
        end
        
        %         -------------------------------------------------------------------------------
        
        function exporterPresentDans(app,struct,trip_file,tripName,file_name)
            namefait = false;
            cols = {};
            data = {};
            tripNameRec = {};
            for i = 1:numel(fieldnames(struct.struct))
                col = ['col' num2str(i)];
                for nbcol = 1:length(struct.struct.(col).var)
                    cols = [cols, {[struct.struct.(col).table{nbcol} '.' struct.struct.(col).var{nbcol}]}];
                end
                for l = 1 :size(struct.struct.(col).data,1)
                    tripNameRec{l,1} = tripName;
                end
                if namefait
                    data = [ data, struct.struct.(col).data];
                else
                    data = [ tripNameRec, data, struct.struct.(col).data];
                    namefait = true;
                end
            end
            
            localTrip = fr.lescot.bind.kernel.implementation.SQLiteTrip(trip_file, 0.04, false);
            for j = 1:length(struct.situation)
                situations = localTrip.getAllSituationOccurences(struct.situation{j});
                allStarts_presentDans = cell2mat(situations.getVariableValues('startTimecode'));
                allEnds_presentDans = cell2mat(situations.getVariableValues('endTimecode'));
                startsSituation(j) = {allStarts_presentDans};
                endsSituation(j) = {allEnds_presentDans};
            end
            
            
            %on récupère toutes les données à mettre dans l'export par
            %table
            donnees = struct.struct.(col).data;
            
            %verification si on exporte des situation
            if strcmp(app.elbType.Value,'situation')
                %recuperation des indices pour les timecodes
                for indice = 1:length(struct.struct.(col).var)
                    if strcmp(struct.struct.(col).var(indice), 'startTimecode')
                        indiceStart = indice;
                    elseif strcmp(struct.struct.(col).var(indice), 'endTimecode')
                        indiceEnd = indice;
                    end
                end
                % on exporte des data ou des events
            else
                %recuperation de l'indice pour les timecodes
                for indice = 1:length(struct.struct.(col).var)
                    if strcmp(struct.struct.(col).var(indice), 'timecode')
                        indiceTimecode = indice;
                    end
                end
            end
            
            %initialisation des masks
            mask = false;
            masktripNameRec = false;
            masktripNameRec(size(donnees,1),1) = false;
            mask(size(donnees,1),indice) = false;
            
            %verification si on exporte des situation
            if strcmp(app.elbType.Value,'situation')
                for situ = 1 :length(startsSituation)
                    for j = 1:length(startsSituation{situ})
                        for d = 1:size(donnees,1)
                            %recuperation des start timecodes des donnees
                            Start_timecode_bon = donnees{d,indiceStart};
                            %recuperation des end timecodes des donnees
                            End_timecode_bon = donnees{d,indiceEnd};
                            %verification des timecodes present dans la situation
                            if (startsSituation{situ}(j)<= Start_timecode_bon && End_timecode_bon <= endsSituation{situ}(j))
                                mask(d,1:indice) = true;
                                masktripNameRec(d,1:1) = true;
                            end
                        end
                    end
                end
                % on exporte des data ou des events
            else
                for situ = 1 :length(startsSituation)
                    for j = 1:length(startsSituation{situ})
                        for d = 1:size(donnees,1)
                            %recuperation des timecodes des donnees
                            timecode_bon = donnees{d,indiceTimecode};
                            %verification des timecodes present dans la situation
                            if (startsSituation{situ}(j)<= timecode_bon && timecode_bon <= endsSituation{situ}(j))
                                mask(d,1:indice) = true;
                                masktripNameRec(d,1:1) = true;
                            end
                        end
                    end
                end
            end
            
            numTrueData = nnz(mask(:,1));
            numTrueTripNameRec = nnz(masktripNameRec(:,1));
            newdata = struct.struct.(col).data(mask);
            %               newdata = reshape(newdata, numTrue, 2)';
            newdata = flip(rot90(reshape(newdata, numTrueData, nbcol)'));
            newtripNameRec = tripNameRec(masktripNameRec);
            newtripNameRec = flip(rot90(reshape(newtripNameRec, numTrueTripNameRec, 1)'));
            
            if namefait
                if numel(fieldnames(struct.struct))>1
                    temp_data = [ newtripNameRec, newdata];
                    data = [ data; temp_data];
                else
                    data = [ newtripNameRec, newdata];
                end
                
            else
                data = [ newtripNameRec, newdata];
                namefait = true;
            end
            
            %cols = ['Trip Name' , 'Particpant','Scenario', 'File Name', cols];
            cols = ['Trip Name',cols];
            alldata =  [cols; data];
            disp(['exporting : ' regexprep(file_name, ' ', '_') ' for ' tripName])
            %                         fr.lescot.bind.export.ExportTrip2Files.ExportTripTableWithStruct2File(trip_file, file_name, struct.type, ...
            %                             cols, data, app.espnSousEchantillon.Value, app.eddFormat.Value, date_time, app.exportFolder)
            
            
            %mise en place du chemin d'export
            null = false;
            if app.exportFolder == 0
                null = true;
            end
            getParentFolder = strfind(trip_file, "\");
            getParentFolder = trip_file(1:getParentFolder(end));
            vide = strcmp(app.exportFolder, '');
            if null || vide
                mkdir (fullfile(getParentFolder,'~DATA_EXPORT'));
                filepathname = fullfile(getParentFolder,'~DATA_EXPORT\');
            else
                filepathname = strcat(app.exportFolder,'\');
            end
            creeExport(app, alldata, file_name, filepathname);
        end
        
        %            -------------------------------------------------------
        
        function [alldata, file_name, filepathname] = exporterTousNormalPresentDans(app,struct,localTrip,tripName,namefait,participant_tables,idScn,trip_file,folder_name,file_name,d)
            cols = {};
            data = {};
            % Iteration sur les tables à créer par fichier
            for i = 1:numel(fieldnames(struct.struct))
                col = ['col' num2str(i)];
                % Itération sur les variables à exporter par table
                for nbcol = 1:length(struct.struct.(col).var)
                    cols = [cols, {[struct.struct.(col).table{nbcol} '.' struct.struct.(col).var{nbcol}]}];
                end
                
                % On crée les données des situations presentDans
                for j = 1:length(struct.situation)
                    situations = localTrip.getAllSituationOccurences(struct.situation{j});
                    % TODO : if existSituationVariable situations.getVariableValues('Name')
                    allNames_presentDans(j) = {situations.getVariableValues('Name')};
                    startsSituation(j) = {cell2mat(situations.getVariableValues('startTimecode'))};
                    endsSituation(j) = {cell2mat(situations.getVariableValues('endTimecode'))};
                end
                
                % On récupère toutes les données à mettre dans l'export par table
                donnees = struct.struct.(col).data;
                
                % Verification si on exporte des situation
                if strcmp(app.elbType.Value,'situation')
                    % Recuperation des indices pour les timecodes
                    for idx = 1:length(struct.struct.(col).var)
                        if strcmp(struct.struct.(col).var(idx), 'startTimecode')
                            idxStart = idx;
                        elseif strcmp(struct.struct.(col).var(idx), 'endTimecode')
                            idxEnd = idx;
                        end
                    end
                    % On exporte des data ou des events
                else
                    % recuperation de l'idx pour les timecodes
                    for idx = 1:length(struct.struct.(col).var)
                        if strcmp(struct.struct.(col).var(idx), 'timecode')
                            idxTimecode = idx;
                        end
                    end
                end
                
                maskSituation(size(donnees,1),1) = false;
                situationNameCol = [];
                % verification si on exporte des situation
                if strcmp(app.elbType.Value,'situation')
                    for situ = 1 :length(startsSituation)
                        for j = 1:length(startsSituation{situ})
                            Start_timecode_bon = cell2mat(donnees(:,idxStart)); %recuperation des start timecodes des donnees
                            End_timecode_bon = cell2mat(donnees(:,idxEnd)); %recuperation des end timecodes des donnees
                            mask = and(startsSituation{situ}(j)<= Start_timecode_bon, End_timecode_bon <= endsSituation{situ}(j));
                            maskSituation = or(mask, maskSituation);
                            situationNameCol = [situationNameCol; repmat(allNames_presentDans{situ}(j),nnz(mask(:,1)),1)];
                        end
                    end
                    % on exporte des data ou des events
                else
                    for situ = 1:length(startsSituation)
                        for j = 1:length(startsSituation{situ})
                            timecode_bon = cell2mat(donnees(:,idxTimecode)); %recuperation des timecodes des donnees
                            mask = and(startsSituation{situ}(j)<= timecode_bon, timecode_bon <= endsSituation{situ}(j));
                            maskSituation = or(mask, maskSituation);
                            situationNameCol = [situationNameCol; repmat(allNames_presentDans{situ}(j),nnz(mask(:,1)),1)];
                        end
                    end
                end
                newdata = struct.struct.(col).data(maskSituation,1:nbcol);
                
                %création des colonnes obligatoires
                tripNameCol = repmat({tripName},length(newdata),1);
                if isempty(participant_tables)
                    partNameCol = repmat({'No Participant Name'},length(newdata),1);
                else
                    partNameCol = repmat({participant_tables},length(newdata),1);
                end
                scnNameRec = repmat({idScn},length(newdata),1);
                %                 fileNameRec = repmat({file_name},length(newdata),1);
                tripNameRec = [tripNameCol, partNameCol, scnNameRec, situationNameCol]; %fileNameRec
                
                if namefait
                    if numel(fieldnames(struct.struct))>1
                        temp_data = [tripNameRec, newdata];
                        data = [data; temp_data];
                    else
                        data = [tripNameRec, data, newdata];
                    end
                    
                else
                    data = [tripNameRec, data, newdata];
                    namefait = true;
                end
            end
            cols = ['Trip Name', 'Participant', 'Scenario', 'Situation', cols]; %'File Name'
            alldata =  [cols; data];
            d.Message = ['exporting : ' regexprep(file_name, ' ', '_') ' for ' tripName];
            %             disp(['exporting : ' regexprep(file_name, ' ', '_') ' for ' tripName])
            %                         fr.lescot.bind.export.ExportTrip2Files.ExportTripTableWithStruct2File(trip_file, file_name, struct.type, ...
            %                             cols, data, app.espnSousEchantillon.Value, app.eddFormat.Value, date_time, app.exportFolder)
            
            %mise en place du chemin d'export
            parentFolder = strfind(trip_file, "\");
            parentFolder = trip_file(1:parentFolder(end));
            if any(or(app.exportFolder == 0, strcmp(app.exportFolder, '')))
                filepathname = fullfile(parentFolder, '~DATA_EXPORT', app.date_time, folder_name);
            else
                filepathname = fullfile(app.exportFolder, '~DATA_EXPORT', app.date_time, folder_name);
            end
            mkdir(filepathname)
            
            %                      disp(['exporting : ' regexprep(file_name, ' ', '_') ' for ' tripName])
            %                      fr.lescot.bind.export.ExportTrip2Files.ExportTripTableWithStruct2File(trip_file, file_name, struct.type, ...
            %                          cols, data, app.espnSousEchantillon.Value, app.eddFormat.Value, date_time, app.exportFolder)
        end
        
        %         -------------------------------------------------------------------------------
        
        
        function results = rewrite(app,cols,i)
            newcols = {};
            indice = 1;
            for j = 1: length(cols)
                if ~(j == i)
                    newcols{indice} = cols{j} ;
                    indice = indice + 1;
                end
            end
            results = newcols;
        end
        
        
        function results = verification_tc(app, nomTable)
            results = true;
            % a mettre a jour avec la nouvelle structure export
            
%             if ~isfield(app.structCol, 'timecode') && ~isfield(app.structCol,'startTimecode')
%                 switch app.export.type
%                     case 'data'
%                         newtimecode = cell2mat(app.trip.getAllDataOccurences(app.elbTable.Value{1}).getVariableValues('timecode'));
%                         for i = 2:length(app.elbTable.Value)
%                             timecode = cell2mat(app.trip.getAllDataOccurences(app.elbTable.Value{i}).getVariableValues('timecode'));
%                             if newtimecode == timecode
%                                 results = true;
%                             else
%                                 results = false;
%                                 break;
%                             end
%                         end
%                     case 'event'
%                         newtimecode = cell2mat(app.trip.getAllEventOccurences(app.elbTable.Value{1}).getVariableValues('timecode'));
%                         for i = 2:length(app.elbTable)
%                             timecode = cell2mat(app.trip.getAllEventOccurences(app.elbTable.Value{i}).getVariableValues('timecode'));
%                             if newtimecode == timecode
%                                 results = true;
%                             else
%                                 results = false;
%                                 break;
%                             end
%                         end
%                     case 'situation'
%                         newstartTimecode = cell2mat(app.trip.getAllSituationOccurences(app.elbTable.Value{1}).getVariableValues('startTimecode'));
%                         newendTimecode = cell2mat(app.trip.getAllSituationOccurences(app.elbTable.Value{1}).getVariableValues('endTimecode'));
%                         
%                         for i =2:length(app.elbTable)
%                             startTimecode = cell2mat(app.trip.getAllSituationOccurences(app.elbTable.Value{i}).getVariableValues('startTimecode'));
%                             endTimecode = cell2mat(app.trip.getAllSituationOccurences(app.elbTable.Value{i}).getVariableValues('endTimecode'));
%                             if (newstartTimecode == startTimecode && newendTimecode == endTimecode)
%                                 results = true;
%                             else
%                                 results = false;
%                                 break;
%                             end
%                         end
%                 end
%             else
%                 switch app.export.type
%                     case 'data'
%                         timecode = cell2mat(app.trip.getAllDataOccurences(nomTable{1}).getVariableValues('timecode'));
%                         if length(app.structCol.timecode) == length(timecode)
%                             if app.structCol.timecode == timecode
%                                 results = true;
%                             else
%                                 results = false;
%                             end
%                         else
%                             results = false;
%                         end
%                     case 'event'
%                         
%                         timecode = cell2mat(app.trip.getAllEventOccurences(nomTable{1}).getVariableValues('timecode'));
%                         if length(app.structCol.timecode) == length(timecode)
%                             if app.structCol.timecode == timecode
%                                 results = true;
%                             else
%                                 results = false;
%                             end
%                         else
%                             results = false;
%                         end
%                         
%                     case 'situation'
%                         startTimecode = cell2mat(app.trip.getAllSituationOccurences(nomTable{1}).getVariableValues('startTimecode'));
%                         endTimecode = cell2mat(app.trip.getAllSituationOccurences(nomTable{1}).getVariableValues('endTimecode'));
%                         if length(startTimecode) ==  length(app.structCol.startTimecode)
%                             if (app.structCol.startTimecode == startTimecode && app.structCol.endTimecode == endTimecode)
%                                 results = true;
%                             else
%                                 results = false;
%                             end
%                         else
%                             results = false;
%                         end
%                         
%                 end
%             end
        end
        
        function results = verification_variables(app)
            results = true;
            if length(app.elbFichier.Items)>1
                app.elbFichier.Value = app.elbFichier.Items{1};
                elbFichier_ValueChanged(app,'');
                posPoint = regexp(app.elbCol.Items{1}, '\.');
                premierVar = app.elbCol.Items{1}(posPoint(1)+1:end);
                
                for i=2:length(app.elbFichier.Items)
                    app.elbFichier.Value = app.elbFichier.Items{i};
                    elbFichier_ValueChanged(app,'');
                    posPoint = regexp(app.elbCol.Items{1}, '\.');
                    autreVar = app.elbCol.Items{1}(posPoint(1)+1:end);
                    if ~strcmp(premierVar,autreVar)
                        results = false;
                        break;
                    end
                end
            end
        end
        
        function load_all(app,FileName,PathName)
            env = importdata(fullfile(PathName,FileName));
            env_to_trips(app,env);
            env_to_seg(app,env);
            env_to_export(app,env);
        end
        
        function load_chargement(app,FileName,PathName)
            env = importdata(fullfile(PathName,FileName));
            env_to_trips(app,env);
        end
        
        function env_to_trips(app,env)
            app.structScnList = env.ScnList;
            app.structScnSelect = env.ScnSelect;
            app.existTripRef = env.existTripRef;
            app.tripRef = env.tripRef;
            app.selectedTripPaths = env.selectedTripPaths;
            %app.TripRefNode = env.TripRefNode;
            refreshTripsNode(app);
        end
        
        function save_env_seg_simple(app)
            app.struct_save.segmentation.temporelle.simple.(app.tefNom.Value) = struct();
            app.struct_save.segmentation.temporelle.simple.(app.tefNom.Value).tspnInf1 = app.tspnInf1.Value;
            app.struct_save.segmentation.temporelle.simple.(app.tefNom.Value).tspnSup1 = app.tspnSup1.Value;
            app.struct_save.segmentation.temporelle.simple.(app.tefNom.Value).table = app.tddRef.Value;
            app.struct_save.segmentation.temporelle.simple.(app.tefNom.Value).present_Dans = app.tddSituation.Value;
            app.struct_save.segmentation.temporelle.simple.(app.tefNom.Value).appliquer = app.tcbAppliquerTrip.Value;
            app.struct_save.segmentation.temporelle.simple.(app.tefNom.Value).repeter = app.tcbRepeter.Value;
            app.struct_save.segmentation.temporelle.simple.(app.tefNom.Value).ttbEvent = app.ttbEvent.Value;
            app.struct_save.segmentation.temporelle.simple.(app.tefNom.Value).ttbSituation = app.ttbSituation.Value;
            
            if app.ttbEvent.Value
                app.struct_save.segmentation.temporelle.simple.(app.tefNom.Value).Filtre = app.structFiltrageEvent;
            else
                app.struct_save.segmentation.temporelle.simple.(app.tefNom.Value).Filtre = app.structFiltrageSituation;
            end
        end
        
        function save_env_seg_double(app)
            app.struct_save.segmentation.temporelle.double.(app.tefNom.Value).tspnInf = app.tspnInf2.Value;
            app.struct_save.segmentation.temporelle.double.(app.tefNom.Value).tspnSup = app.tspnSup2.Value;
            app.struct_save.segmentation.temporelle.double.(app.tefNom.Value).table1 = app.tddTable1.Value;
            app.struct_save.segmentation.temporelle.double.(app.tefNom.Value).ligne1 = app.tddAvant.Value;
            app.struct_save.segmentation.temporelle.double.(app.tefNom.Value).table2 = app.tddTable2.Value;
            app.struct_save.segmentation.temporelle.double.(app.tefNom.Value).ligne2 = app.tddApres.Value;
            app.struct_save.segmentation.temporelle.double.(app.tefNom.Value).present_Dans = app.tddSituation.Value;
            app.struct_save.segmentation.temporelle.double.(app.tefNom.Value).appliquer = app.tcbAppliquerTrip.Value;
            app.struct_save.segmentation.temporelle.double.(app.tefNom.Value).repeter = app.tcbRepeter.Value;
            %app.struct_save.segmentation.temporelle.double.(app.tefNom).ttbEvent = app.ttbEvent.Value;
            %app.struct_save.segmentation.temporelle.double.(app.tefNom).ttbSituation = app.ttbSituation.Value;
            %if app.ttbEvent.Value
            app.struct_save.segmentation.temporelle.double.(app.tefNom.Value).Filtre = app.structFiltrageEvent;
            %else
            %app.struct_save.segmentation.temporelle.double.(app.tefNom).Filtre = app.structFiltrageSituation;
            %end
        end
        
        function save_env_seg_cond(app)
            app.struct_save.segmentation.conditionnelle.(app.cefNom.Value).condMinStep = app.condMinStep;
            app.struct_save.segmentation.conditionnelle.(app.cefNom.Value).choix = app.cddChoix.Value;
            app.struct_save.segmentation.conditionnelle.(app.cefNom.Value).conditions = app.structCondition;
            app.struct_save.segmentation.conditionnelle.(app.cefNom.Value).filtre = app.structFiltrageEvent;
            app.struct_save.segmentation.conditionnelle.(app.cefNom.Value).connecteurs = app.connecteurs.Value;
            app.struct_save.segmentation.conditionnelle.(app.cefNom.Value).presentDans = app.cddSituation.Value;
            app.struct_save.segmentation.conditionnelle.(app.cefNom.Value).appliquer = app.ccbAppliquerTrip.Value;
            app.struct_save.segmentation.conditionnelle.(app.cefNom.Value).var = app.clbVar.Items;
        end
        
        
        function save_env_export_tous(app)
            fic = fieldnames(app.export);
            app.struct_save.Export.tous.type = app.export.type;
            for i = 2:length(fic)
                app.struct_save.Export.tous.(fic{i}) = app.export.(fic{i});
            end
            app.struct_save.Export.tous.(fic{1}).concatTrip = app.ConcatTrip.Value;
            app.struct_save.Export.tous.(fic{1}).concatSitu = app.ConcatSituationEvents.Value;
        end
        
        function save_env_export(app)
            fic = fieldnames(app.export);
            app.struct_save.Export.simple.type = app.export.type;
            for i = 2:length(fic)
                app.struct_save.Export.simple.(fic{i}) = app.export.(fic{i});
            end
            app.struct_save.Export.simple.(fic{1}).concatTrip = app.ConcatTrip.Value;
            app.struct_save.Export.simple.(fic{1}).concatSitu = app.ConcatSituationEvents.Value;
        end
        
        function load_segmentation(app,FileName,PathName)
            env = importdata(fullfile(PathName,FileName));
            %app.TripRefNode = env.TripRefNode;
            %mettre bien tout dans l'ordre
            env_to_seg(app, env);
            %             refreshTripsNode(app);
        end
        
        function load_export(app,FileName,PathName)
            env = importdata(fullfile(PathName,FileName));
            env_to_export(app,env);
            %             refreshTripsNode(app);
        end
        
        
        % remettre dans la stucture de sauvegarde les données chargées
        function fill_all_to_struct(app,env)
            if isfield(env,'segmentation')
                if isfield(env.segmentation,'temporelle')
                    if isfield(env.segmentation.temporelle,'simple')
                        envFields = fieldnames(env.segmentation.temporelle.simple);
                        if ~isempty(envFields)
                            for i =1:length(envFields)
                                app.struct_save.segmentation.temporelle.simple.(envFields{i}) = env.segmentation.temporelle.simple.(envFields{i});
                            end
                        end
                    end
                end
            end
            if isfield(env,'segmentation')
                if isfield(env.segmentation,'temporelle')
                    if isfield(env.segmentation.temporelle,'double')
                        envFields = fieldnames(env.segmentation.temporelle.double);
                        if ~isempty(envFields)
                            for i =1:length(envFields)
                                app.struct_save.segmentation.temporelle.double.(envFields{i}) = env.segmentation.temporelle.double.(envFields{i});
                            end
                        end
                    end
                end
            end
            if isfield(env,'segmentation')
                if isfield(env.segmentation,'conditionnelle')
                    envFields = fieldnames(env.segmentation.conditionnelle);
                    if ~isempty(envFields)
                        for i =1:length(envFields)
                            app.struct_save.segmentation.conditionnelle.(envFields{i}) = env.segmentation.conditionnelle.(envFields{i});
                        end
                    end
                end
            end
        end
        
        
        % remplissage de l'arbre depuis un chargement d'environnement
        function env_to_seg(app, env)
            if isfield(env,'segmentation')
                %1/parcourt de env.segmentation.temporelle.simple
                %remplissage de l'arbre
                if isfield(env.segmentation,'temporelle')
                    if isfield(env.segmentation.temporelle,'simple')
                        simple = fieldnames(env.segmentation.temporelle.simple);
                        if ~isempty(simple)
                            for i = 1:length(simple)
                                uitreenode('Parent',app.SimpleNode,"Text",simple{i},'nodeData',env.segmentation.temporelle.simple.(simple{i}));
                            end
                        end
                    end
                    %2/parcourt de env.segmentation.temporelle.double
                    %remplissage de l'arbre
                    if isfield(env.segmentation.temporelle,'double')
                        double = fieldnames(env.segmentation.temporelle.double);
                        if ~isempty(double)
                            for i = 1:length(double)
                                uitreenode('Parent',app.DoubleNode,"Text",double{i},'nodeData',env.segmentation.temporelle.double.(double{i}));
                            end
                        end
                    end
                end
                %3/parcourt de env.segmentation.conditionnelle
                %remplissage de l'arbre
                if isfield(env.segmentation,'conditionnelle')
                    cond = fieldnames(env.segmentation.conditionnelle);
                    if ~isempty(cond)
                        for i = 1:length(cond)
                            uitreenode('Parent',app.ConditionnelleNode,"Text",cond{i},'nodeData',env.segmentation.conditionnelle.(cond{i}));
                        end
                    end
                end
                fill_all_to_struct(app,env);
            end
        end
        
        
        % remplissage de l'arbre depuis un chargement d'environnement
        function env_to_export(app,env)
            if isfield(env,'Export')
                if isfield(env.Export,'simple')
                    simple = fieldnames(env.Export.simple);
                    if ~isempty(simple)
                        for i = 1:length(simple)
                            uitreenode('Parent',app.SeulNode,"Text",simple{i},'nodeData',env.Export.simple.(simple{i}));
                        end
                    end
                end
                if isfield(env.Export,'tous')
                    tous = fieldnames(env.Export.tous);
                    if ~isempty(tous)
                        for i = 1:length(tous)
                            uitreenode('Parent',app.TousNode,"Text",tous{i},'nodeData',env.Export.tous.(tous{i}));
                        end
                    end
                end
            end
        end
        
        % met a jour l'interface en remettant les données présent dans le
        % noeud
        function nodeSimple_to_panel(app,node,event)
            % affichage de la segmentation
            bSegmentation_ButtonPushed(app,event);
            app.bgSeg.SelectedObject = app.rbTemporelle;
            bgSegmentation_SelectionChanged(app,event);
            % vide le nom
            if ~isempty(app.tddTable1.Value)
                app.tddTable1.Value = '';
                tdd_ValueChanged(app,event);
            end
            % recuperation données
            data = node.NodeData;
            % maj interface
            if  data.ttbEvent
                app.tbtgTable.SelectedObject = app.ttbEvent;
                tc_bg_SelectionChanged(app, event)
            elseif data.ttbSituation
                app.tbtgTable.SelectedObject = app.ttbSituation;
                tc_bg_SelectionChanged(app, event)
            end
            app.tddRef.Value = data.table;
            tdd_ValueChanged(app,event);
            app.tspnInf1.Value = data.tspnInf1;
            app.tspnSup1.Value = data.tspnSup1;
            app.tddSituation.Value = data.present_Dans;
            app.tcbAppliquerTrip.Value = data.appliquer;
            app.tcbRepeter.Value = data.repeter;
            if isfield(data.Filtre,data.table)
                if data.ttbEvent
                    app.structFiltrageEvent.(data.table) = data.Filtre.(data.table);
                end
                if data.ttbSituation
                    app.structFiltrageSituation.(data.table) = data.Filtre.(data.table);
                end
            end
            app.tefNom.Value = node.Text;
            
        end
        
        

%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%        
        % met a jour l'interface en remettant les données présent dans le
        % noeud
        function nodeDouble_to_panel(app,node,event)
            % affichage de la segmentation
            bSegmentation_ButtonPushed(app,event);
            app.bgSeg.SelectedObject = app.rbTemporelle;
            bgSegmentation_SelectionChanged(app,event);
            % vide me nom
            if ~isempty(app.tddRef.Value)
                app.tddRef.Value = '';
                tdd_ValueChanged(app,event);
            end
            % recuperation des données
            data = node.NodeData;
            % maj de l'interface
            app.tddTable1.Value = data.table1;
            tdd_ValueChanged(app,event);
            app.tddTable2.Value = data.table2;
            tdd_ValueChanged(app,event);
            app.tddAvant.Value = data.ligne1;
            tdd_ValueChanged(app,event);
            app.tddApres.Value = data.ligne2;
            tdd_ValueChanged(app,event);
            app.tspnInf2.Value = data.tspnInf;
            app.tspnSup2.Value = data.tspnSup;
            app.tddSituation.Value = data.present_Dans;
            app.tcbAppliquerTrip.Value = data.appliquer;
            app.tcbRepeter.Value = data.repeter;
            % maj des filtres dont on a besoin
            if isfield(data.Filtre,data.table1)
                app.structFiltrageEvent.(data.table1) = data.Filtre.(data.table1);
            end
            if isfield(data.Filtre,data.table2)
                app.structFiltrageEvent.(data.table2) = data.Filtre.(data.table2);
            end
            app.tefNom.Value = node.Text;
            
        end
        
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        % met a jour l'interface en remettant les données présent dans le
        % noeud
        function nodeCond_to_panel(app,node,event)
            % affichage de la segmentation
            bSegmentation_ButtonPushed(app,event);
            app.bgSeg.SelectedObject = app.rbConditionnelle;
            bgSegmentation_SelectionChanged(app,event);
            % récupérarion des données
            data = node.NodeData;
            % mise a jour de l'interface
            app.cddChoix.Value = data.choix;
            app.clbVar.Items = data.var;
            app.ccbAppliquerTrip.Value = data.appliquer;
            app.structCondition =  data.conditions;
            app.connecteurs.Value = data.connecteurs;
            app.structFiltrageEvent = data.filtre;
            app.cddSituation.Value = data.presentDans;
            app.condMinStep = data.condMinStep;
            app.cefNom.Value = node.Text;
        end
 
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%        
        % met a jour l'interface en remettant les données présent dans le
        % noeud
        function nodeExport_to_panel(app,node,event)
            % affichage du panneau d'export
            bExport_ButtonPushed(app,event);
            % recup des données du noeud
            export = node.NodeData;
            %si on est dans l'export seul
            if node.Parent == app.SeulNode
                % met a jour les cochages de concatenation
                app.ConcatSituationEvents.Value = export.concatTrip;
                app.ConcatTrip.Value = export.concatSitu;
                % supprime les elements indésirables de la structure
                export = rmfield(export,'concatTrip');
                export = rmfield(export,'concatSitu');
            else
                fields = fieldnames(export);
                % met a jour les cochages de concatenation
                app.ConcatSituationEvents.Value = export.(fields{1}).concatTrip;
                app.ConcatTrip.Value = export.(fields{1}).concatSitu;
                % supprime les elements indésirables de la structure
                export.(fields{1}) = rmfield(export.(fields{1}),'concatTrip');
                export.(fields{1}) = rmfield(export.(fields{1}),'concatSitu');
            end
            % met a jour la structure des fichiers et l'interface
            app.export = export;
            app.elbFichier.Items = {''};
            field = fieldnames(export);
            for i = 1:length(field)
                app.elbFichier.Items{i} = field{i};
            end
        end
        
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%        
        function compatible = verif_header(app,data_type,colonnes)
            compatible = false;
        end
        
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% 
        function results = getdatas(app, trip,struct)
            %table de base
            tmp_table = struct.table{1};
            data ={};
            %mettre le premier record
            switch app.export.type
                case 'data'
                    record = trip.getAllDataOccurences(tmp_table);
                case 'event'
                    record = trip.getAllEventOccurences(tmp_table);
                case 'situation'
                    record = trip.getAllSituationOccurences(tmp_table);
            end
            %iteration sur les liste des tables du fichier
            for j= 1:length(struct.var)
                switch app.export.type
                    case 'data'
                        %si la table suivante est la même que la table
                        %précedente le record ne change pas sinon
                        %changement
                        if ~strcmp(struct.table{j},tmp_table)
                            tmp_table = struct.table{j};
                            record = app.trip.getAllDataOccurences(tmp_table);
                        end    
                    case 'event'
                        %si la table suivante est la même que la table
                        %précedente le record ne change pas sinon
                        %changement
                        if ~strcmp(struct.table{j},tmp_table)
                            tmp_table = struct.table{j};
                            record = app.trip.getAllEventOccurences(tmp_table);
                        end
                    case 'situation'
                        %si la table suivante est la même que la table
                        %précedente le record ne change pas sinon
                        %changement
                        if ~strcmp(struct.table{j},tmp_table)
                            tmp_table = struct.table{j};
                            record = app.trip.getAllSituationOccurences(tmp_table);
                        end
                end
                %recupérer le nom de la variable voulu
                var = {struct.var{j}};
                %stocker les données 
                data = [data, record.buildCellArrayWithVariables(var)'];
            end
            results = data;
        end
        
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%        
        function new_exportation(app, d, exporter_tous)
            %savoir dans quel cas on se trouve
            if app.ConcatTrip.Value && app.ConcatSituationEvents.Value
                mode = "concat_all";
            elseif app.ConcatTrip.Value
                mode = "concat_trip";
            elseif app.ConcatSituationEvents.Value
                mode = "concat_event_situation";
            else
                mode = "normal";
            end
            
            %parcours des fichiers
            date_time = char(datetime('now','Format','yyMMdd_HHmm'));
            %le ou les trips a exporter
            if exporter_tous
                trip_files = app.selectedTripPaths;
            else 
                trip_files = {app.trip.getTripPath};
            end
            
            data_for_all = {}; %{length(fileNames_list)}{length(trip_files)};
            % parcours des fichiers
            d.Message = "création des données";
            
            %récupérer les fichiers a exporter(ceux selectionnés)
            if length(app.elbFichier.Items) == 1
                fichiers_a_exporter = app.elbFichier.Items;
            else
                fichiers_a_exporter = app.elbFichier.Value;
            end
            for i=1:length(fichiers_a_exporter)
                indicefic{i} = find(~cellfun(@isempty, strfind(app.elbFichier.Items, fichiers_a_exporter{i})));
            end
            
            %itération sur les fichier puis sur les trips
            for i_fic = 1:length(indicefic)
                fic = ['fic' num2str(indicefic{i_fic})];
                %file_name = app.export.(fic).name;
                for i_trip = 1:length(trip_files)
                    fileNames_list{i_fic} = app.export.(fic).name;
                    succes = true;
                    % colonnes = {};
                    data = {};
                    trip_file = trip_files{i_trip};
                    [~, tripName, ~] = fileparts(trip_file);
                    localTrip = fr.lescot.bind.kernel.implementation.SQLiteTrip(trip_file, 0.04, false);
                    colonnes = app.export.(fic).header;
                    %recupération des datas
                    try
                        data = getdatas(app, localTrip, app.export.(fic));
                    catch
                        %marche pas
                        succes = false;
                    end
                    % récupération du nom du paticipant
                    try
                        participant_tables = localTrip.getAttribute('participant_id');
                    catch
                        participant_tables = {};
                    end
                    %verif si il y'a bien un nom de participant
                    if isempty(participant_tables)
                        partNameCol = repmat({'No Participant Name'},size(data,1),1);
                    else
                        partNameCol = repmat({participant_tables},size(data,1),1);
                    end
%                     mettre les données dans la structure 
                    tripNameCol = repmat({tripName},size(data,1),1);
                    idScn = scenario(app, localTrip);
                    scnNameRec = repmat({idScn},size(data,1),1);
                    tripNameRec = [tripNameCol, partNameCol, scnNameRec];
                    data = [tripNameRec,data];
                    if succes
                        colonnes = ['Trip Name' , 'Particpant','Scenario', colonnes];
                        data =  [colonnes; data];
                    else
                        data = {};
                    end
                    data_for_all{i_fic,i_trip} = data;
                    delete(localTrip);
                end
            end
            
            %export
            exportation(app, data_for_all, trip_files, fileNames_list, mode, d);
        end
        
        
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%        
        function exportation(app, data_for_all, trip_files, fileNames_list, mode, d)
            %switch mode
            %faire la verification de la compatibilité des données 
            if strcmp(mode,"normal")
                for i_fic = 1:size(data_for_all,1)
                    for i_trip = 1:size(data_for_all,2)
                        trip_file = trip_files{i_trip};
                        folder_name = fileNames_list{i_fic};
                        % file_name = strcat(fileNames_list{i_fic}, '_',int2str(i_trip));
                        % Todo : check if this way to create file_name is good
                        data = data_for_all{i_fic,i_trip};
                        if ~isempty(data)
                            file_name = strcat(fileNames_list{i_fic}, '_', data{2,2}, '_', data{2,3});
                            filepathname = createFilePathName(app, trip_file, folder_name);
                            creeExport(app, data_for_all{i_fic,i_trip}, file_name, filepathname, d);
                        end
                    end
                end
            elseif strcmp(mode,"concat_event_situation")
                for i_trip = 1:size(data_for_all,2)
                    dataconcat = {};
                    for i_fic = 1:size(data_for_all,1)
                        % TODO enlever les entètes
                        %datas = data_for_all{i_fic,i_trip};
                        %datas(1,:) = {};
                        % protéger contre les problèmes de tailles des données
                        try
                            dataconcat = [dataconcat;data_for_all{i_fic,i_trip}];
                        catch
                            %error
                            %a faire mettre message d'erreur
                        end
                    end
                    %export
                    trip_file = trip_files{i_trip};
                    folder_name = fileNames_list{i_fic};
                    % file_name = strcat(fileNames_list{i_fic}, '_',int2str(i_trip));
                    % Todo : check if this way to create file_name is good
                    data = data_for_all{i_fic,i_trip};
                    if ~isempty(data)
                        file_name = strcat(fileNames_list{i_fic}, '_', data{2,2}, '_', data{2,3});
                        filepathname = createFilePathName(app, trip_file, folder_name);
                        creeExport(app, dataconcat, file_name, filepathname, d);
                    end
                end
                
            elseif strcmp(mode,"concat_all")
                dataconcat = {};
                for i_trip = 1:size(data_for_all,2)
                    dataconcat_fic = {};
                    for i_fic = 1:size(data_for_all,1)
                        % TODO enlever les entètes
                        datas = data_for_all{i_fic,i_trip};
                        % protéger contre les problèmes de tailles des données
                        try
                            dataconcat_fic = [dataconcat;data_for_all{i_fic,i_trip}];
                        catch
                            %error
                        end
                    end
                    dataconcat = [dataconcat,dataconcat_fic];
                end
                %export
                trip_file = trip_files{i_trip};
                folder_name = fileNames_list{i_fic};
                file_name = strcat(fileNames_list{i_fic}, '_',int2str(i_trip));
                filepathname = createFilePathName(app, trip_file, folder_name);
                creeExport(app, dataconcat, file_name, filepathname, d);
                %todo concat event situation trips
            elseif strcmp(mode,"concat_trip")
                for i_fic = 1:size(data_for_all,1)
                    dataconcat = {};
                    for i_trip = 1:size(data_for_all,2)
                        % TODO enlever les entètes
                        datas = data_for_all{i_fic,i_trip};
                        % protéger contre les problèmes de tailles des données
                        dataconcat = [dataconcat,data_for_all{i_fic,i_trip}];
                    end
                    %export
                    trip_file = trip_files{i_trip};
                    folder_name = fileNames_list{i_fic};
                    file_name = strcat(fileNames_list{i_fic}, '_',int2str(i_trip));
                    filepathname = createFilePathName(app, trip_file, folder_name);
                    creeExport(app, dataconcat, file_name, filepathname, d);
                end
            end
            %ouvrir le dossier dans lequel on a exporté
            winopen(app.exportFolder);
        end
        
        
        function ajouteAPlugins(app, nomPlugin)
            
            app.lbPlugins.Value
            lenlbPlugins=length( app.lbPlugins.Value);
            if (lenlbPlugins==0 )
                app.lbPlugins.Items = {nomPlugin};
                app.lbPlugins.Value = {nomPlugin};
            else
               app.lbPlugins.Items(lenlbPlugins+1) = {nomPlugin};
               app.lbPlugins.Value(lenlbPlugins+1) = {nomPlugin};
            end
        end
        
        function MaJLbPreset(app, nomPreset)
           
            lenlbPresetItem=length(app.lbPreset.Items)
          %option de list box
            if (lenlbPresetItem<1 )
                app.lbPreset.Items = {nomPreset};
                %app.lbPreset.Value fonction valueChanged  
                app.lbPreset.ItemsData={nomPreset};
                
            else
                app.lbPreset.Items{lenlbPresetItem+1} =nomPreset ;
                app.lbPreset.ItemsData{lenlbPresetItem+1}=nomPreset;
            end
 
        end
        
        function  lanceMagneto(app)

            if (~ isempty(app.magneto)) % isprop(app, 'magneto'))
                %magneto dejà ouvert, ne rien faire
            else
                %pas de magneto ouvert alors on l'ouvre
                MagnetoProp=fr.lescot.bind.plugins.Magneto_simplif(app.trip, 'northwest','Magneto');
                %on conserve en mémoire sa figure
                
                app.magneto=MagnetoProp;
                
            end
            
        end
    end % end methode private
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: bChargement
        function bChargement_ButtonPushed(app, event)
            app.bChargement.Enable = 'off';
            paths = uigetdir2(app.lastMainPath,'Sélectionnez le(s) fichier(s) Trip(s) ou le(s) dossier(s) contenant les Trip(s)');
            if ~isempty(paths)
                %% Création de la liste des fichiers .trip
                trips = {};
                for i = 1:length(paths)
                    if endsWith(paths{i}, '.trip')
                        trips = [trips, paths(i)];
                        app.lastMainPath = fileparts(paths{i});
                    else
                        trips = [trips, dirrec(paths{i},'.trip')];
                        app.lastMainPath = paths{i};
                    end
                end
                if length(trips) == 1
                    d = uiprogressdlg(app.BINDGUIFigure, 'Message', 'Chargement du Trip', 'Indeterminate',"on"); pause(app.pauseValue);
                else
                    d = uiprogressdlg(app.BINDGUIFigure, 'Message', 'Chargement des Trips',"Indeterminate","on"); pause(app.pauseValue);
                end
                %d = uiprogressdlg(app.BINDGUIFigure, 'Message', 'Chargement en cours...'); pause(app.pauseValue);
                %% Création de la structure des idScénarios/tripNames/tripPaths
                if ~isempty(trips)
                    inc = 0; percent = 1 ./ (length(trips) + 1);
                    for i = 1:length(trips)
                        Trip = fr.lescot.bind.kernel.implementation.SQLiteTrip(trips{i}, 0.04, false);% Instanciation du trip
                        tripPath = trips{i}; % tripPath tableau de chemins vers les trips
                        [~,tripName,~] = fileparts(tripPath); %
                        tripNodeName = ['trip_' regexprep(regexprep(regexprep(tripName, ')' , ''), '(', ''), '\s*', '_')];
                        %tripNodeName = ['trip_' tripName];
                        if any(contains(Trip.getMetaInformations().getTripAttributesList,'id_scenario'))
                            idScn{i} = ['SCN_' Trip.getAttribute('id_scenario')];
                        elseif any(contains(Trip.getMetaInformations().getTripAttributesList,'scenario'))
                            idScn{i} = ['SCN_' Trip.getAttribute('scenario')];
                        else
                            idScn{i} = 'SCN_VIDE';
                        end
                        %app.selectedTripPaths = [app.selectedTripPaths,tripPath];
                        delete(Trip);
                        % TODO : if fileName exist, warning "same fileName multiple times"
                        app.structScnLoad.(idScn{i}).(tripNodeName) = tripPath;
                        if ~any(contains(app.selectedTripNames, tripNodeName))
                            inc = inc + percent; text = ['Chargement de : ' tripName]; d.Value = inc; d.Message = text;
                            if length(trips) == 1 && isempty(fieldnames(app.structScnSelect))
                                app.structScnSelect.(idScn{i}).(tripNodeName) = tripPath;
                                app.selectedTripNames = [app.selectedTripNames,tripName];
                                app.selectedTripPaths = [app.selectedTripPaths,tripPath];
                                system(['echo F|xcopy /Y ' tripPath ' ' [tripPath '_autosave']]);
                            else
                                app.structScnList.(idScn{i}).(tripNodeName) = tripPath;
                            end
                        else
                            inc = inc + percent;
                            text = ['Le trip ' tripName ' fait parti de la sélection'];
                            d.Value = inc;
                            d.Message = text;
                        end
                    end
                    %% Filtre des scénarios
                    % TODO: ajouter
                    refreshTripsNode(app)
                    d.Value = 1; d.Message = 'Chargement terminé'; pause(app.pauseValue); close(d);
                    
                    
                end
                
            app.bChargement.Enable = 'on';
     
            end
           
            %initialisation: 
                 app.env.pluginConfig=struct();
                 app.env.figureOpen=struct();
                 app.env.pluginConfig.nbMagneto=1;
                 app.env.pluginConfig.nbPlugin=1;
                 app.env.pluginConfig.nbVideoPlayer=1;
                 app.env.pluginConfig.nbEventDisplay=1;
                 app.env.pluginConfig.nbEventBrowser=1;
                 app.env.pluginConfig.nbPlotter2D=1;
                 app.env.pluginConfig.nbPlotter3D=1;
                 app.env.pluginConfig.nbPlotter2Dfx=1;
                 app.env.pluginConfig.nbGPS=1;
                 app.env.pluginConfig.nbMessage=1;
                 app.env.pluginConfig.nbValeurInstant=1;
                 app.env.pluginConfig.nbEnvoiWorkspace=1;
                 app.env.pluginConfig.nbEnvoiReseau=1;
                 app.env.pluginConfig.nbVideoSynchro=1;
        end

        % Menu selected function: GuideMenu
        function bGuide_ButtonPushed(app, event)
            pos = app.BINDGUIFigure.Position;
            BIND_GUIDE(pos(1), pos(2));
        end

        % Button pushed function: bSegmentation
        function bSegmentation_ButtonPushed(app, event)
            if isempty(app.TripsSelectNode.Children)
                uialert(app.BINDGUIFigure, 'Aucun Trip chargé !', ' ', 'Icon', 'warning');
            else
                if isempty(app.trip) && ~app.existTripRef
                    uialert(app.BINDGUIFigure, 'Aucun Trip de référence sélectionné !', ' ', 'Icon', 'warning');
                else
                    showSegmentationPanel(app);
                    app.bTraitement.BackgroundColor = [1 1 1]; app.bTraitement.FontColor = [.01 .34 .6];
                    app.bSegmentation.BackgroundColor = [.01 .34 .6]; app.bSegmentation.FontColor = [1 1 1];
                    collapse(app.Tree); expand(app.EventsNode); expand(app.SituationsNode); %expand(app.DatasNode); %expand(app.EventsSituationsNode);
                end
            end
        end

        % Button pushed function: bTraitement
        function bTraitement_ButtonPushed(app, event)
            if isempty(app.TripsSelectNode.Children)
                uialert(app.BINDGUIFigure, 'Aucun Trip chargé !', ' ', 'Icon', 'warning');
            else
                if isempty(app.trip) && ~app.existTripRef
                    uialert(app.BINDGUIFigure, 'Aucun Trip de référence sélectionné !', ' ', 'Icon', 'warning');
                else
                    showTraitementPanel(app);
                    app.bSegmentation.BackgroundColor = [1 1 1]; app.bSegmentation.FontColor = [.01 .34 .6];
                    app.bTraitement.BackgroundColor = [.01 .34 .6]; app.bTraitement.FontColor = [1 1 1];
                    collapse(app.Tree); expand(app.DatasNode); expand(app.FonctionsNode);
                end
            end
        end

        % Button pushed function: bReset
        function bReset_ButtonPushed(app, event)
            node = app.Tree.SelectedNodes;
            %% Revert case
            if node.Parent.Parent == app.TripsSelectNode
                uiconfirm(app.BINDGUIFigure, 'Quels trips souhaitez-vous remettre à l''état d''origine ?', 'Réinitiatisation', 'Options', ...
                    {'Annuler', 'Trips de la sélection', 'Trip de référence'}, 'DefaultOption', 3, 'CancelOption', 1, ...
                    'CloseFcn', @(src,event)bReset_CloseFcn(app,event));
                %% Edit case
            else
                answer = inputdlg({'Nouveau nom:'}, 'Editer', 1, {node.Text});
                if ~isempty(answer) && ~strcmp(answer{:}, node.Text)
                    switch node.Parent
                        case app.EventsNode
                            tableName = ['event_' node.Text];
                            new_tableName = ['event_' answer{:}];
                        case app.SituationsNode
                            tableName = ['situation_' node.Text];
                            new_tableName = ['situation_' answer{:}];
                        case app.DatasNode
                            tableName = ['data_' node.Text];
                            new_tableName = ['data_' answer{:}];
                    end
                    %%% BLOQUE -> renommage (il reste à renommer dans les metaInformations)
                    connection = sqlite4m(0, 'open', app.tripRef.Tag);
                    renameRequest = ['ALTER TABLE "' tableName '" RENAME TO "' new_tableName '"'];
                    sqlite4m(connection, renameRequest);
                    d = uiprogressdlg(app.BINDGUIFigure, 'Message', 'Rafraîchissement en cours'); pause(app.pauseValue);
                    refreshTree(app, d);
                    d.Value = 1; d.Message = 'Rafraîchissement terminé'; pause(app.pauseValue); close(d);
                end
            end
            
            function bReset_CloseFcn(app, event)
                if strcmp(event.SelectedOption, 'Trips de la sélection')
                    list = fieldnames(app.TripsSelectNode);
                    if ~isempty(list)
                        for i = 1:length(app.TripsSelectNode.Children)
                            if any(contains(list, {app.TripsSelectNode.Children(i).Text}))
                                for j=1:length(app.TripsSelectNode.Children(i).Children)
                                    copyfile([app.TripsSelectNode.Children(i).Children(j).Tag '_autosave'], app.TripsSelectNode.Children(i).Children(j).Tag);
                                end
                            end
                        end
                        d = uiprogressdlg(app.BINDGUIFigure, 'Message', 'Rafraîchissement en cours'); pause(app.pauseValue);
                        refreshTree(app, d);
                        d.Value = 1; d.Message = 'Rafraîchissement terminé'; pause(app.pauseValue); close(d);
                    end
                elseif strcmp(event.SelectedOption, 'Trip de référence')
                    copyfile([node.Tag '_autosave'], node.Tag);
                    d = uiprogressdlg(app.BINDGUIFigure, 'Message', 'Rafraîchissement en cours'); pause(app.pauseValue);
                    refreshTree(app, d);
                    d.Value = 1; d.Message = 'Rafraîchissement terminé'; pause(app.pauseValue); close(d);
                end
            end
        end

        % Button pushed function: bSupprimer
        function bSupprimer_ButtonPushed(app, event)
            node = app.Tree.SelectedNodes; %le noeud actif
            %si possible mettre dans des fonctions
            if length(node) <= 1
                if node == app.TripsNode
                    d = uiprogressdlg(app.BINDGUIFigure, 'Message', 'Retrait en cours'); pause(app.pauseValue);
                    app.selectedTripNames = {}; app.selectedTripPaths = {}; bSupprimer_TripRef(app);
                    app.structScnSelect = struct(); app.structScnList = struct(); app.structScnLoad = struct();
                    %                     refreshTripsNode(app)
                elseif node.Parent == app.TripsNode || node.Parent.Parent == app.TripsNode || node.Parent.Parent.Parent == app.TripsNode
                    d = uiprogressdlg(app.BINDGUIFigure, 'Message', 'Retrait en cours'); pause(app.pauseValue);
                    % Retirer toute la sélection
                    if node == app.TripsSelectNode
                        inc = 0; percent_scn = 1 ./ (length(node.Children) + 1);
                        for i = 1:length(node.Children)
                            percent = percent_scn ./ (length(node.Children(i).Children) + 1);
                            for j = 1:length(node.Children(i).Children)
                                inc = inc + percent; d.Value = inc; d.Message = ['Retrait de ' node.Children(i).Children(j).Text]; pause(app.pauseValue);
                                delete([node.Children(i).Children(j).Tag '_autosave']);
                                if startsWith(node.Children(i).Children(j).Text, '(réf)')
                                    bSupprimer_TripRef(app);
                                    node.Children(i).Children(j).Text = node.Children(i).Children(j).Text(7:end);
                                end
                            end
                        end
                        app.selectedTripNames = {}; app.selectedTripPaths = {};
                        app.structScnSelect = struct(); app.structScnList = app.structScnLoad;
                        %                         refreshTripsNode(app)
                        % Retirer un scénario de la sélection
                    elseif node.Parent == app.TripsSelectNode && ~isempty(node.Children)
                        inc = 0; percent = 1 ./ (length(node.Children) + 1);
                        for i = 1:length(node.Children)
                            inc = inc + percent; d.Value = inc; d.Message = ['Retrait de ' node.Children(i).Text]; pause(app.pauseValue);
                            delete([node.Children(i).Tag '_autosave']);
                            app.selectedTripPaths(contains(app.selectedTripNames, node.Children(i).Text)) = [];
                            app.selectedTripNames(contains(app.selectedTripNames, node.Children(i).Text)) = [];
                            if startsWith(node.Children(i).Text, '(réf)')
                                bSupprimer_TripRef(app);
                                node.Children(i).Text = node.Children(i).Text(7:end);
                            end
                            app.structScnList.(node.Text).(node.Children(i).Text) = node.Children(i).Tag;
                        end
                        app.structScnSelect = rmfield(app.structScnSelect, node.Text);
                        %                         refreshTripsNode(app)
                        % Retirer un trip de la sélection
                    elseif node.Parent.Parent == app.TripsSelectNode
                        tripPath = node.Tag;
                        delete([tripPath '_autosave']);
                        j = 1;
                        while(j ~= length(app.selectedTripNames)+1) %parcourt de tout le tableau
                            if (contains(['(réf) ' app.selectedTripNames{j}], node.Text)) %verification si le trip est dans le tableau de selection
                                app.selectedTripPaths(:,j) = []; %Supression de la colonne qui contient le chemin du trip dans la selection
                                app.selectedTripNames(:,j) = []; %Supression de la colonne qui contient le nom du trip dans la selection
                                j = j - 1; %car la taille du tableau change
                            end
                            j = j + 1;
                        end
                        if startsWith(node.Text, '(réf)')
                            bSupprimer_TripRef(app);
                            node.Text = node.Text(7:end);
                        end
                        app.structScnList.(node.Parent.Text).(node.Text) = tripPath;
                        app.structScnSelect.(node.Parent.Text) = rmfield(app.structScnSelect.(node.Parent.Text), node.Text);
                        if isempty(fieldnames(app.structScnSelect.(node.Parent.Text)))
                            app.structScnSelect = rmfield(app.structScnSelect, node.Parent.Text);
                        end
                        %                         refreshTripsNode(app)
                        % Retirer toute la liste
                    elseif node == app.TripsListNode
                        app.structScnLoad = app.structScnSelect;
                        app.structScnList = struct();
                        %                         refreshTripsNode(app)
                        % Retirer un scénario de la liste
                    elseif node.Parent == app.TripsListNode && ~isempty(node.Children)
                        inc = 0; percent = 1 ./ (length(node.Children) + 1);
                        for i = 1:length(node.Children)
                            inc = inc + percent; d.Value = inc; d.Message = ['Retrait de ' node.Children(i).Text]; pause(app.pauseValue);
                            app.structScnLoad.(node.Text) = rmfield(app.structScnLoad.(node.Text), (node.Children(i).Text));
                        end
                        app.structScnList = rmfield(app.structScnList, (node.Text));
                        if isempty(fieldnames(app.structScnLoad.(node.Text)))
                            app.structScnLoad = rmfield(app.structScnLoad, node.Text);
                        end
                        %                         refreshTripsNode(app)
                        % Retirer un trip de la liste
                    elseif node.Parent.Parent == app.TripsListNode
                        app.structScnList.(node.Parent.Text) = rmfield(app.structScnList.(node.Parent.Text), (node.Text));
                        app.structScnLoad.(node.Parent.Text) = rmfield(app.structScnLoad.(node.Parent.Text), node.Text);
                        if isempty(fieldnames(app.structScnLoad.(node.Parent.Text)))
                            app.structScnList = rmfield(app.structScnList, node.Parent.Text);
                            app.structScnLoad = rmfield(app.structScnLoad, node.Parent.Text);
                            delete(node.Parent)
                        else
                            delete(node);
                        end
                    end
                    pause(app.pauseValue); expand(app.TripsNode,'all');
                    d.Value = 1; d.Message = 'Retrait terminé'; pause(app.pauseValue); close(d);
                    if isempty(fieldnames(app.structScnList)) && isempty(fieldnames(app.structScnSelect))
                        app.bChargement.BackgroundColor = [.01 .34 .6]; app.bChargement.FontColor = [1 1 1];
                    end
                    % Retirer la table ou la colonne sélectionnée pour le trip de référence ou pour la sélection
                elseif node.Parent == app.SimpleNode
                    app.struct_save.segmentation.temporelle.simple = rmfield(app.struct_save.segmentation.temporelle.simple,node.Text);
                    s = node;
                    s.delete();
                elseif node.Parent == app.DoubleNode
                    app.struct_save.segmentation.temporelle.double = rmfield(app.struct_save.segmentation.temporelle.double,node.Text);
                    s = node;
                    s.delete();
                elseif node.Parent == app.ConditionnelleNode
                    app.struct_save.segmentation.conditionnelle = rmfield(app.struct_save.segmentation.conditionnelle,node.Text);
                    s = node;
                    s.delete();
                elseif node.Parent == app.SeulNode
                    app.struct_save.Export.simple = rmfield(app.struct_save.Export.simple,node.Text);
                    s = node;
                    s.delete();
                elseif node.Parent == app.TousNode
                    app.struct_save.Export.tous = rmfield(app.struct_save.Export.tous,node.Text);
                    s = node;
                    s.delete();
                else
                    uiconfirm(app.BINDGUIFigure, 'Où souhaitez-vous supprimer ?', 'Type de suppression', 'Options', ...
                        {'Annuler', 'Trip de référence', 'Trips de la Sélection'}, 'DefaultOption', 3, 'CancelOption', 1, ...
                        'CloseFcn', @(src,event)bSupprimer_CloseFcn(app,event));
                end
                
                % si on selectionné plusieurs noeuds
            else
                %a faire
                taille = length(node);
                lesnoeuds = node;
                for n = 1:length(node)
                    noeud = lesnoeuds(n);
                    if noeud.Parent == app.TripsNode || noeud.Parent.Parent == app.TripsNode || noeud.Parent.Parent.Parent == app.TripsNode
                        d = uiprogressdlg(app.BINDGUIFigure, 'Message', 'Retrait en cours'); pause(app.pauseValue);
                        % Retirer toute la sélection
                        if noeud == app.TripsSelectNode
                            inc = 0; percent_scn = 1 ./ (length(noeud.Children) + 1);
                            for i = 1:length(noeud.Children)
                                percent = percent_scn ./ (length(noeud.Children(i).Children) + 1);
                                for j = 1:length(noeud.Children(i).Children)
                                    inc = inc + percent; d.Value = inc; d.Message = ['Retrait de ' noeud.Children(i).Children(j).Text]; pause(app.pauseValue);
                                    delete([noeud.Children(i).Children(j).Tag '_autosave']);
                                    if startsWith(noeud.Children(i).Children(j).Text, '(réf)')
                                        bSupprimer_TripRef(app);
                                        noeud.Children(i).Children(j).Text = noeud.Children(i).Children(j).Text(7:end);
                                    end
                                end
                            end
                            app.selectedTripNames = {}; app.selectedTripPaths = {};
                            app.structScnSelect = struct(); app.structScnList = app.structScnLoad;
                            %refreshTripsNode(app)
                            % Retirer un scénario de la sélection
                        elseif noeud.Parent == app.TripsSelectNode && ~isempty(noeud.Children)
                            inc = 0; percent = 1 ./ (length(noeud.Children) + 1);
                            for i = 1:length(noeud.Children)
                                inc = inc + percent; d.Value = inc; d.Message = ['Retrait de ' noeud.Children(i).Text]; pause(app.pauseValue);
                                delete([noeud.Children(i).Tag '_autosave']);
                                app.selectedTripPaths(contains(app.selectedTripNames, noeud.Children(i).Text)) = [];
                                app.selectedTripNames(contains(app.selectedTripNames, noeud.Children(i).Text)) = [];
                                if startsWith(noeud.Children(i).Text, '(réf)')
                                    bSupprimer_TripRef(app);
                                    noeud.Children(i).Text = noeud.Children(i).Text(7:end);
                                end
                                app.structScnList.(noeud.Text).(noeud.Children(i).Text) = noeud.Children(i).Tag;
                            end
                            app.structScnSelect = rmfield(app.structScnSelect, noeud.Text);
                            %refreshTripsNode(app)
                            % Retirer un trip de la sélection
                        elseif noeud.Parent.Parent == app.TripsSelectNode
                            tripPath = noeud.Tag;
                            delete([tripPath '_autosave']);
                            j = 1;
                            while(j ~= length(app.selectedTripNames)+1) %parcourt de tout le tableau
                                if (contains(['(réf) ' app.selectedTripNames{j}], noeud.Text)) %verification si le trip est dans le tableau de selection
                                    app.selectedTripPaths(:,j) = []; %Supression de la colonne qui contient le chemin du trip dans la selection
                                    app.selectedTripNames(:,j) = []; %Supression de la colonne qui contient le nom du trip dans la selection
                                    j = j - 1; %car la taille du tableau change
                                end
                                j = j + 1;
                            end
                            if startsWith(noeud.Text, '(réf)')
                                bSupprimer_TripRef(app);
                                noeud.Text = noeud.Text(7:end);
                            end
                            app.structScnList.(noeud.Parent.Text).(noeud.Text) = tripPath;
                            app.structScnSelect.(noeud.Parent.Text) = rmfield(app.structScnSelect.(noeud.Parent.Text), noeud.Text);
                            if isempty(fieldnames(app.structScnSelect.(noeud.Parent.Text)))
                                app.structScnSelect = rmfield(app.structScnSelect, noeud.Parent.Text);
                            end
                            %refreshTripsNode(app)
                            % Retirer toute la liste
                        elseif noeud == app.TripsListNode
                            app.structScnLoad = app.structScnSelect;
                            app.structScnList = struct();
                            %refreshTripsNode(app)
                            % Retirer un scénario de la liste
                        elseif noeud.Parent == app.TripsListNode && ~isempty(noeud.Children)
                            inc = 0; percent = 1 ./ (length(noeud.Children) + 1);
                            for i = 1:length(noeud.Children)
                                inc = inc + percent; d.Value = inc; d.Message = ['Retrait de ' noeud.Children(i).Text]; pause(app.pauseValue);
                                app.structScnLoad.(noeud.Text) = rmfield(app.structScnLoad.(noeud.Text), (noeud.Children(i).Text));
                            end
                            app.structScnList = rmfield(app.structScnList, (noeud.Text));
                            if isempty(fieldnames(app.structScnLoad.(noeud.Text)))
                                app.structScnLoad = rmfield(app.structScnLoad, noeud.Text);
                            end
                            % refreshTripsNode(app)
                            % Retirer un trip de la liste
                        elseif noeud.Parent.Parent == app.TripsListNode
                            app.structScnList.(noeud.Parent.Text) = rmfield(app.structScnList.(noeud.Parent.Text), (noeud.Text));
                            app.structScnLoad.(noeud.Parent.Text) = rmfield(app.structScnLoad.(noeud.Parent.Text), noeud.Text);
                            if isempty(fieldnames(app.structScnLoad.(noeud.Parent.Text)))
                                app.structScnList = rmfield(app.structScnList, noeud.Parent.Text);
                                app.structScnLoad = rmfield(app.structScnLoad, noeud.Parent.Text);
                                delete(noeud.Parent)
                            else
                                delete(noeud);
                            end
                        end
                        pause(app.pauseValue); expand(app.TripsNode,'all');
                        d.Value = 1; d.Message = 'Retrait terminé'; pause(app.pauseValue); close(d);
                        if isempty(fieldnames(app.structScnList)) && isempty(fieldnames(app.structScnSelect))
                            app.bChargement.BackgroundColor = [.01 .34 .6]; app.bChargement.FontColor = [1 1 1];
                        end
                        % Retirer la table ou la colonne sélectionnée pour le trip de référence ou pour la sélection
                    elseif noeud.Parent == app.SimpleNode
                        app.struct_save.segmentation.temporelle.simple = rmfield(app.struct_save.segmentation.temporelle.simple,noeud.Text);
                        s = noeud;
                        s.delete();
                    elseif noeud.Parent == app.DoubleNode
                        app.struct_save.segmentation.temporelle.double = rmfield(app.struct_save.segmentation.temporelle.double,noeud.Text);
                        s = noeud;
                        s.delete();
                    elseif noeud.Parent == app.ConditionnelleNode
                        app.struct_save.segmentation.conditionnelle = rmfield(app.struct_save.segmentation.conditionnelle,noeud.Text);
                        s = noeud;
                        s.delete();
                    elseif noeud.Parent == app.SeulNode
                        app.struct_save.Export.simple = rmfield(app.struct_save.Export.simple,noeud.Text);
                        s = noeud;
                        s.delete();
                    elseif noeud.Parent == app.TousNode
                        app.struct_save.Export.tous = rmfield(app.struct_save.Export.tous,noeud.Text);
                        s = noeud;
                        s.delete();
                    else
                        if n ==1
                            a = uiconfirm(app.BINDGUIFigure, 'Où souhaitez-vous supprimer ?', 'Type de suppression', 'Options', ...
                                {'Annuler', 'Trip de référence', 'Trips de la Sélection'}, 'DefaultOption', 3, 'CancelOption', 1, ...
                                'CloseFcn', @(src,event)bSupprimer_group_CloseFcn(app,event,noeud,n,taille));
                            waitfor(a);
                            %else strcmp(a,'Trip de référence')
                        elseif strcmp(a,'Trip de référence')
                            choix.SelectedOption = 'Trip de référence';
                            bSupprimer_group_CloseFcn(app,choix,noeud,n,taille);
                        elseif strcmp(a,'Trips de la Sélection')
                            choix.SelectedOption = 'Trip de référence';
                            bSupprimer_group_CloseFcn(app,choix,noeud,n,taille);
                        end
                    end
                end
                %                 refreshTripsNode(app);
            end
            %             if n == length(node)
            %                 d = uiprogressdlg(app.BINDGUIFigure, 'Message', 'refresh'); pause(app.pauseValue);
            %                 d.Value = 0; d.Message = 'Rafraîchissement en cours'; pause(app.pauseValue);
            %                 refreshTree(app,d);
            %                 d.Value = 1; d.Message = 'Rafraîchissement terminé'; pause(app.pauseValue); close(d);
            %             end
            refreshTripsNode(app);
            
            
            % Retirer les tables ou les colonnes sélectionnées pour le trip de référence
            function bSupprimer_group_CloseFcn(app, event, noeud,n,taille)
                app.forceIsBase = false;
                %app.forceIsBase = true;
                if strcmp(event.SelectedOption, 'Trips de la Sélection')
                    bSupprimerTout_group_CloseFcn(app,noeud)
                    %                     uiconfirm(app.BINDGUIFigure, {'L''opération peut prendre du temps.' 'Voulez-vous continuer ?'}, 'Opération longue', ...
                    %                         'Options', {'Non', 'Oui'}, 'DefaultOption', 2, 'CancelOption', 1, 'Icon', 'info', ...
                    %                         'CloseFcn', @(src,event)bSupprimerTout_CloseFcn(app,event));
                elseif strcmp(event.SelectedOption, 'Trip de référence')
                    d = uiprogressdlg(app.BINDGUIFigure, 'Message', 'Suppression en cours'); pause(app.pauseValue);
                    tablesList = {noeud.Text}; deleteInTree(app, app.trip, noeud, tablesList);
                    if app.forceIsBase, deleteInTree(app, app.trip, noeud, tablesList); end
                end
                if n == taille
                    d.Value = 1; d.Message = 'Suppression terminée'; pause(app.pauseValue);
                    d.Value = 0; d.Message = 'Rafraîchissement en cours'; pause(app.pauseValue);
                    refreshTree(app, d);
                    d.Value = 1; d.Message = 'Rafraîchissement terminé'; pause(app.pauseValue); close(d);
                else
                    d.Value = 1; d.Message = 'Suppression terminée'; pause(app.pauseValue);
                    close(d);
                end
            end
            
            function bSupprimerTout_group_CloseFcn(app,noeud)
                scnList = fieldnames(app.structScnSelect);
                if ~isempty(scnList) % strcmp(event.SelectedOption, 'Oui')
                    d = uiprogressdlg(app.BINDGUIFigure, 'Message', 'Suppression en cours'); pause(app.pauseValue);
                    %node = app.Tree.SelectedNodes; tablesList = {node.Text};
                    node = noeud; tablesList = {node.Text};
                    inc = 0; percent_scn = 1 ./ (length(scnList) + 1);
                    for i = 1:length(app.TripsSelectNode.Children)
                        if any(contains(scnList, {app.TripsSelectNode.Children(i).Text}))
                            percent = percent_scn ./ length(app.TripsSelectNode.Children(i).Children);
                            for j = 1:length(app.TripsSelectNode.Children(i).Children)
                                inc = inc + percent; text = ['Suppression dans ' app.TripsSelectNode.Children(i).Children(j).Text]; d.Value = inc; d.Message = text;
                                %si l'élément à supprimer n'existe pas dans
                                %le trip continue quand même
                                try
                                    trip_todelete = fr.lescot.bind.kernel.implementation.SQLiteTrip(app.TripsSelectNode.Children(i).Children(j).Tag, 0.04, false);
                                    deleteInTree(app, trip_todelete, node, tablesList);
                                    if app.forceIsBase, deleteInTree(app, trip_todelete, node, tablesList); end
                                catch
                                    disp("l'élément à supprimer n'existe pas")
                                    continue
                                end
                                delete(trip_todelete)
                            end
                        end
                    end
                end
            end


            % Retirer la table ou la colonne sélectionnée pour le trip de référence
            function bSupprimer_CloseFcn(app, event)
                app.forceIsBase = false;
                %app.forceIsBase = true;
                if strcmp(event.SelectedOption, 'Trips de la Sélection')
                    bSupprimerTout_CloseFcn(app)
                    %                     uiconfirm(app.BINDGUIFigure, {'L''opération peut prendre du temps.' 'Voulez-vous continuer ?'}, 'Opération longue', ...
                    %                         'Options', {'Non', 'Oui'}, 'DefaultOption', 2, 'CancelOption', 1, 'Icon', 'info', ...
                    %                         'CloseFcn', @(src,event)bSupprimerTout_CloseFcn(app,event));
                elseif strcmp(event.SelectedOption, 'Trip de référence')
                    d = uiprogressdlg(app.BINDGUIFigure, 'Message', 'Suppression en cours'); pause(app.pauseValue);
                    tablesList = {node.Text}; deleteInTree(app, app.trip, node, tablesList);
                    if app.forceIsBase, deleteInTree(app, app.trip, node, tablesList); end
                end
                d.Value = 1; d.Message = 'Suppression terminée'; pause(app.pauseValue);
                d.Value = 0; d.Message = 'Rafraîchissement en cours'; pause(app.pauseValue);
                refreshTree(app, d);
                d.Value = 1; d.Message = 'Rafraîchissement terminé'; pause(app.pauseValue); close(d);
            end


            % Retirer la table ou la colonne sélectionnée pour le trip de référence ou pour la sélection
            function bSupprimerTout_CloseFcn(app)
                scnList = fieldnames(app.structScnSelect);
                if ~isempty(scnList) % strcmp(event.SelectedOption, 'Oui')
                    d = uiprogressdlg(app.BINDGUIFigure, 'Message', 'Suppression en cours'); pause(app.pauseValue);
                    node = app.Tree.SelectedNodes; tablesList = {node.Text};
                    inc = 0; percent_scn = 1 ./ (length(scnList) + 1);
                    for i = 1:length(app.TripsSelectNode.Children)
                        if any(contains(scnList, {app.TripsSelectNode.Children(i).Text}))
                            percent = percent_scn ./ length(app.TripsSelectNode.Children(i).Children);
                            for j = 1:length(app.TripsSelectNode.Children(i).Children)
                                inc = inc + percent; text = ['Suppression dans ' app.TripsSelectNode.Children(i).Children(j).Text]; d.Value = inc; d.Message = text;
                                %si l'élément à supprimer n'existe pas dans
                                %le trip continue quand même
                                try
                                    trip_todelete = fr.lescot.bind.kernel.implementation.SQLiteTrip(app.TripsSelectNode.Children(i).Children(j).Tag, 0.04, false);
                                    deleteInTree(app, trip_todelete, node, tablesList);
                                    if app.forceIsBase, deleteInTree(app, trip_todelete, node, tablesList); end
                                catch
                                    disp("la situation ou event n'existe pas");
                                end
                                delete(trip_todelete)
                            end
                        end
                    end
                end
            end
            
            function bSupprimer_TripRef(app)
                delete(app.trip);
                app.TripRefNode.Text = '';
                app.existTripRef = 0;
                delete(app.EventsNode.Children);
                delete(app.SituationsNode.Children);
                delete(app.DatasNode.Children);
                delete(app.AttributesNode.Children);
                delete(app.AudioVideoNode.Children);
                delete(app.ParticipantNode.Children);
                app.panelSeg.Visible = 'off'; app.panelTrait.Visible = 'off';
                app.bSegmentation.BackgroundColor = [1 1 1]; app.bSegmentation.FontColor = [.01 .34 .6];
                app.bTraitement.BackgroundColor = [1 1 1]; app.bTraitement.FontColor = [.01 .34 .6];
                showControlePanel(app);
                app.bSegmentation.Enable = 'off';% désactive le bouton de segmentation car on a plus de trip actif
                app.bTraitement.Enable = 'off'; % désactive le bouton de traitement car on a plus de trip actif
            end
        end

        % Button pushed function: bVoir
        function bVoir_ButtonPushed(app, event)
            node = app.Tree.SelectedNodes;
            pos = app.BINDGUIFigure.Position;
            d = uiprogressdlg(app.BINDGUIFigure, 'Message', 'Ouverture en cours', 'Indeterminate', 'on');
            if length(node) == 1
                if strcmp(app.bVoir.Text, '✔')
                    % Validate Liste
                    if node == app.TripsListNode
                        for i = 1:length(node.Children)
                            for j = 1:length(node.Children(i).Children)
                                d.Message = ['Ajout de ' node.Children(i).Children(j).Text];
                                tripPath = node.Children(i).Children(j).Tag;
                                system(['echo F|xcopy /Y ' tripPath ' ' [tripPath '_autosave']]); % TODO : gérer [status,cmdout]
                                app.selectedTripNames = [app.selectedTripNames, node.Children(i).Children(j).Text];
                                app.selectedTripPaths = [app.selectedTripPaths, node.Children(i).Children(j).Tag];
                            end
                        end
                        app.structScnList = struct(); app.structScnSelect = app.structScnLoad;
                        % Validate Scénario in List
                    elseif node.Parent == app.TripsListNode
                        for i = 1:length(node.Children)
                            d.Message = ['Ajout de ' node.Children(i).Text];
                            tripPath = node.Children(i).Tag;
                            system(['echo F|xcopy /Y ' tripPath ' ' tripPath '_autosave &']); % TODO : gérer [status,cmdout]
                            app.selectedTripNames = [app.selectedTripNames, node.Children(i).Text];
                            app.selectedTripPaths = [app.selectedTripPaths, node.Children(i).Tag];
                            app.structScnSelect.(node.Text).(node.Children(i).Text) = tripPath;
                            app.TripRefNode.Text = node.Children(1).Text;
                        end
                        app.structScnList = rmfield(app.structScnList, node.Text);
                        % Validate Trip in List
                    elseif node.Parent.Parent == app.TripsListNode
                        d.Message = ['Ajout de ' node.Text];
                        tripPath = node.Tag;
                        system(['echo F|xcopy /Y ' tripPath ' ' [tripPath '_autosave']]); % TODO : gérer [status,cmdout]
                        app.selectedTripNames = [app.selectedTripNames, node.Text];
                        app.selectedTripPaths = [app.selectedTripPaths, node.Tag];
                        app.structScnSelect.(node.Parent.Text).(node.Text) = tripPath;
                        app.structScnList.(node.Parent.Text) = rmfield(app.structScnList.(node.Parent.Text), node.Text);
                        app.TripRefNode.Text = node.Text;
                        if isempty(fieldnames(app.structScnList.(node.Parent.Text)))
                            app.structScnList = rmfield(app.structScnList, node.Parent.Text);
                        end
                        %                     elseif node.Parent == app.SimpleNode
                        %                         nodeSimple_to_panel(app,node,event);
                        %                         tc_bCreer_ButtonPushed(app, event);
                        %                         refreshTripsNode(app);
                        %                         return
                        %                     elseif node.Parent == app.DoubleNode
                        %                         nodeDouble_to_panel(app,node,event);
                        %                         tc_bCreer_ButtonPushed(app, event);
                        %                         refreshTripsNode(app);
                        %                         return
                        %                     elseif node.Parent == app.ConditionnelleNode
                        %                         nodeCond_to_panel(app,node,event);
                        %                         tc_bCreer_ButtonPushed(app, event);
                        %                         refreshTripsNode(app);
                        %                         return
                    end
                    % Validate Trip in Sélection
                    if node.Parent.Parent == app.TripsSelectNode % on se trouve bien dans selection
                        app.TripRefNode.Text = node.Text;
                        app.tripRef = node;
                        %                         instantiateTrip(app, node);
                        %                         app.bSegmentation.Enable = 'on'; % activer le bouton de Segmentation
                        %                         app.bTraitement.Enable = 'on'; % activer le bouton de Traitement
                        %                         return
                    end
                    %                     refreshTripsNode(app);
                elseif (strcmp(app.bVoir.Text, '👁️') && node ~= app.TripsListNode)
                    if node.Parent == app.EventsNode
                        BIND_VISU(app.trip, node.Text, 'event', pos(1), pos(2), '');
                    elseif node.Parent == app.SituationsNode
                        BIND_VISU(app.trip, node.Text, 'situation', pos(1), pos(2), '');
                    elseif node.Parent == app.DatasNode
                        BIND_VISU(app.trip, node.Text, 'data', pos(1), pos(2), '');
                        % TODO : BIND_VISU pour metas
                    elseif node.Parent == app.MetasNode
                        BIND_VISU(app.trip, node.Text, 'meta', pos(1), pos(2), '');
                    end
                elseif strcmp(app.bVoir.Text, '⚙️')
                    if node.Parent == app.SimpleNode
                        nodeSimple_to_panel(app,node,event);
                        tc_bCreer_ButtonPushed(app, event);
                        refreshTripsNode(app);
                        return
                    elseif node.Parent == app.DoubleNode
                        nodeDouble_to_panel(app,node,event);
                        tc_bCreer_ButtonPushed(app, event);
                        refreshTripsNode(app);
                        return
                    elseif node.Parent == app.ConditionnelleNode
                        nodeCond_to_panel(app,node,event);
                        tc_bCreer_ButtonPushed(app, event);
                        refreshTripsNode(app);
                        return
                    elseif node.Parent == app.SeulNode || node.Parent == app.TousNode
                        nodeExport_to_panel(app,node,event);
                        %deux possibilitées soit simple soit tous
                        if strcmp(node.NodeData.(node.Text).mode,'normal')
                            ebExporter_ButtonPushed(app,event);
                        else
                            ebExporterTous_ButtonPushed(app,event);
                        end
                    end
                end
            else
                for n = 1:length(node)
                    noeud = node(n);
                    %stop = false;
                    if strcmp(app.bVoir.Text, '✔')
                        % Validate Liste
                        if noeud == app.TripsListNode
                            for i = 1:length(noeud.Children)
                                for j = 1:length(noeud.Children(i).Children)
                                    tripPath = noeud.Children(i).Children(j).Tag;
                                    [status,cmdout] = system(['echo F|xcopy /Y ' tripPath ' ' [tripPath '_autosave']]);
                                    app.selectedTripNames = [app.selectedTripNames, noeud.Children(i).Children(j).Text];
                                    app.selectedTripPaths = [app.selectedTripPaths, noeud.Children(i).Children(j).Tag];
                                end
                            end
                            app.structScnList = struct(); app.structScnSelect = app.structScnLoad;
                            % Validate Scénario in List
                        elseif noeud.Parent == app.TripsListNode
                            for i = 1:length(noeud.Children)
                                tripPath = noeud.Children(i).Tag;
                                [status,cmdout] = system(['echo F|xcopy /Y ' tripPath ' ' [tripPath '_autosave']]);
                                app.selectedTripNames = [app.selectedTripNames, noeud.Children(i).Text];
                                app.selectedTripPaths = [app.selectedTripPaths, noeud.Children(i).Tag];
                                app.structScnSelect.(noeud.Text).(noeud.Children(i).Text) = tripPath;
                            end
                            app.structScnList = rmfield(app.structScnList, noeud.Text);
                            % Validate Trip in List
                        elseif noeud.Parent.Parent == app.TripsListNode
                            tripPath = noeud.Tag;
                            [status,cmdout] = system(['echo F|xcopy /Y ' tripPath ' ' [tripPath '_autosave']]);
                            app.selectedTripNames = [app.selectedTripNames, noeud.Text];
                            app.selectedTripPaths = [app.selectedTripPaths, noeud.Tag];
                            app.structScnSelect.(noeud.Parent.Text).(noeud.Text) = tripPath;
                            app.structScnList.(noeud.Parent.Text) = rmfield(app.structScnList.(noeud.Parent.Text), noeud.Text);
                            if isempty(fieldnames(app.structScnList.(noeud.Parent.Text)))
                                app.structScnList = rmfield(app.structScnList, noeud.Parent.Text);
                            end
                            if n == 1
                                app.TripRefNode.Text = noeud.Text;
                            end
                            %                         elseif noeud.Parent == app.SimpleNode
                            %                             nodeSimple_to_panel(app,noeud,event);
                            %                             tc_bCreer_ButtonPushed(app, event);
                            %                             refreshTripsNode(app);
                            %                             stop = true;
                            %                         elseif noeud.Parent == app.DoubleNode
                            %                             nodeDouble_to_panel(app,noeud,event);
                            %                             tc_bCreer_ButtonPushed(app, event);
                            %                             refreshTripsNode(app);
                            %                             stop = true;
                            %                         elseif noeud.Parent == app.ConditionnelleNode
                            %                             nodeCond_to_panel(app,noeud,event);
                            %                             tc_bCreer_ButtonPushed(app, event);
                            %                             refreshTripsNode(app);
                            %                             stop = true;
                        end
                        %                         if ~stop
                        % Validate Trip in Sélection
                        if noeud.Parent.Parent == app.TripsSelectNode && n == 1% on se trouve bien dans selection
                            app.TripRefNode.Text = noeud.Text;
                            %                                 instantiateTrip(app, noeud);
                            %                                 app.bSegmentation.Enable = 'on'; % activer le bouton de Segmentation
                            %                                 app.bTraitement.Enable = 'on'; % activer le bouton de Traitement
                            %                                 return
                        end
                        %                         end
                        %refreshTripsNode(app); pause(app.pauseValue); expand(app.TripsNode,'all');
                    elseif (strcmp(app.bVoir.Text, '👁️') && noeud ~= app.TripsListNode)
                        if noeud.Parent == app.EventsNode
                            BIND_VISU(app.trip, noeud.Text, 'event', pos(1), pos(2), '');
                        elseif noeud.Parent == app.SituationsNode
                            BIND_VISU(app.trip, noeud.Text, 'situation', pos(1), pos(2), '');
                        elseif noeud.Parent == app.DatasNode
                            BIND_VISU(app.trip, noeud.Text, 'data', pos(1), pos(2), '');
                            % TODO : BIND_VISU pour metas
                        elseif noeud.Parent == app.MetasNode
                            BIND_VISU(app.trip, noeud.Text, 'meta', pos(1), pos(2), '');
                        end
                    elseif strcmp(app.bVoir.Text, '⚙️')
                        if noeud.Parent == app.SimpleNode
                            nodeSimple_to_panel(app,noeud,event);
                            tc_bCreer_ButtonPushed(app, event);
                            %                             refreshTripsNode(app);
                            %stop = true;
                        elseif noeud.Parent == app.DoubleNode
                            nodeDouble_to_panel(app,noeud,event);
                            tc_bCreer_ButtonPushed(app, event);
                            %                             refreshTripsNode(app);
                            %stop = true;
                        elseif noeud.Parent == app.ConditionnelleNode
                            nodeCond_to_panel(app,noeud,event);
                            tc_bCreer_ButtonPushed(app, event);
                            %                             refreshTripsNode(app);
                            %stop = true;
                        elseif noeud.Parent == app.SeulNode || noeud.Parent == app.TousNode
                            nodeExport_to_panel(app,noeud,event);
                            if strcmp(noeud.NodeData.(noeud.Text).mode,'normal')
                                ebExporter_ButtonPushed(app,event);
                            else
                                ebExporterTous_ButtonPushed(app,event);
                            end
                        end
                    end
                end
                
            end
            refreshTripsNode(app);
            close(d);
        end

        % Selection changed function: Tree
        function Tree_SelectionChanged(app, event)
            node = app.Tree.SelectedNodes;
            oldnodes = event.PreviousSelectedNodes;
            taille = length(node);
            %si node n'est pas vite
            if ~isempty(node)
                %si la taille est de 1
                if taille == 1
                    if node.Parent == app.FonctionsNode
                        % TODO
                    elseif node.Parent == app.SimpleNode
                        nodeSimple_to_panel(app,node,event);
                        if ~isempty(oldnodes)
                            if oldnodes(1).Parent == app.SeulNode || oldnodes(1).Parent == app.TousNode
                                app.panelExport.Visible = 'off';
                            end
                        end
                        %                         app.Tree.Multiselect = 'on';
                    elseif node.Parent == app.DoubleNode
                        nodeDouble_to_panel(app,node,event);
                        if ~isempty(oldnodes)
                            if oldnodes(1).Parent == app.SeulNode || oldnodes(1).Parent == app.TousNode
                                app.panelExport.Visible = 'off';
                            end
                        end
                        %                         app.Tree.Multiselect = 'on';
                    elseif node.Parent == app.ConditionnelleNode
                        nodeCond_to_panel(app,node,event);
                        if ~isempty(oldnodes)
                            if oldnodes(1).Parent == app.SeulNode || oldnodes(1).Parent == app.TousNode
                                app.panelExport.Visible = 'off';
                            end
                        end
                        %                         app.Tree.Multiselect = 'on';
                        %                     elseif node.Parent.Parent == app.TripsListNode
                        %                         app.Tree.Multiselect = 'on';
                        %                     elseif node.Parent.Parent == app.TripsSelectNode
                        %                         app.Tree.Multiselect = 'on';
                        %                     else
                        %                         app.Tree.Multiselect = 'off';
                    elseif node.Parent == app.SeulNode || node.Parent == app.TousNode
                        nodeExport_to_panel(app,node,event);
                    end
                    showControlePanel(app);
                end
                
            end
            %                 else
            %                     i = 2;
            %                     while i <=taille
            %                         last_node = node(i);
            %                         first_node = node(1);
            %                         last_simplenode = last_node.Parent == app.SimpleNode;
            %                         last_doublenode = last_node.Parent == app.DoubleNode;
            %                         last_condnode = last_node.Parent == app.ConditionnelleNode;
            %
            %                         first_simplenode = first_node.Parent == app.SimpleNode;
            %                         first_doublenode = first_node.Parent == app.DoubleNode;
            %                         first_condnode = first_node.Parent == app.ConditionnelleNode;
            %
            %                         %si le dernier noeud cliquer est bien dans le catalogue
            %                         if last_simplenode || last_doublenode || last_condnode
            %                             %si le premier n'y est pas
            %                             if ~(first_simplenode || first_doublenode || first_condnode)
            %                                 if i == taille
            %                                      all = node(1:i-1);
            %                                 else
            %                                      all = [node(1:i-1); node(i+1:end)];
            %                                 end
            %                                 node = all;
            %                                 taille = taille - 1;
            %                                 i = i-1;
            %                             end
            %                         else
            %                             if first_simplenode || first_doublenode || first_condnode
            %                                 if i == taille
            %                                      all = node(1:i-1);
            %                                 else
            %                                      all = [node(1:i-1); node(i+1:end)];
            %                                 end
            %                                 node = all;
            %                                 taille = taille - 1;
            %                                 i = i-1;
            %                             end
            %                         end
            %                         i = i+1;
            %                     end
            %                     app.Tree.SelectedNodes = node;
            %                 end
            %end
            if taille >1
                memetype = true;
                type = node(1).Parent;
                if type == app.SimpleNode || type == app.DoubleNode || type == app.ConditionnelleNode
                    for n = 2:taille
                        if ~(node(n).Parent == app.SimpleNode || node(n).Parent == app.DoubleNode || node(n).Parent == app.ConditionnelleNode)
                            memetype = false;
                        end
                    end
                    %else
                elseif  type.Parent == app.TripsSelectNode
                    for n = 2:taille
                        if node(n).Parent.Parent ~= app.TripsSelectNode
                            memetype = false;
                        end
                    end
                elseif type.Parent == app.TripsListNode
                    for n = 2:taille
                        if node(n).Parent.Parent ~= app.TripsListNode
                            memetype = false;
                        end
                    end
                elseif type == app.EventsNode || type == app.SituationsNode
                    for n = 2:taille
                        if ~(node(n).Parent == app.EventsNode || node(n).Parent == app.SituationsNode)
                            memetype = false;
                        end
                    end
                elseif type == app.SeulNode || type == app.TousNode
                    for n = 2:taille
                        if ~(node(n).Parent == app.SeulNode || node(n).Parent == app.TousNode)
                            memetype = false;
                        end
                    end
                end
                if ~memetype
                    app.Tree.SelectedNodes = oldnodes;
                end
            end
            
            
        end

        % Button pushed function: bImport
        function bImport_ButtonPushed(app, event)
            if isempty(app.TripsNode.Children)
                uialert(app.BINDGUIFigure, 'Aucun Trip chargé !', ' ', 'Icon', 'warning');
            else
                if isempty(app.trip) && ~app.existTripRef
                    uialert(app.BINDGUIFigure, 'Aucun Trip de référence sélectionné !', ' ', 'Icon', 'warning');
                else
                    %                 app.panelImport.Visible = 'on';
                end
            end
        end

        % Button pushed function: bExport
        function bExport_ButtonPushed(app, event)
            if isempty(app.TripsSelectNode.Children)
                uialert(app.BINDGUIFigure, 'Aucun Trip chargé !', ' ', 'Icon', 'warning');
            else
                if isempty(app.trip) && ~app.existTripRef
                    uialert(app.BINDGUIFigure, 'Aucun Trip de référence sélectionné !', ' ', 'Icon', 'warning');
                else
                    app.panelExport.Visible = 'on';
                    for i = 1:length(app.SituationsNode.Children)
                        app.elbSituation.Items{i} = app.SituationsNode.Children(i).Text;
                    end
                end
            end
        end

        % Selection changed function: bgSeg
        function bgSegmentation_SelectionChanged(app, event)
            switch app.bgSeg.SelectedObject
                case app.rbVideo
                    app.vsubP.Visible = 'on';
                    app.csubP.Visible = 'off';
                    app.tsubP.Visible = 'off';
                case app.rbConditionnelle
                    app.vsubP.Visible = 'off';
                    app.csubP.Visible = 'on';
                    app.tsubP.Visible = 'off';
                case app.rbTemporelle
                    app.vsubP.Visible = 'off';
                    app.csubP.Visible = 'off';
                    app.tsubP.Visible = 'on';
            end
        end

        % Value changed function: cefNum, tcbRepeter, tddApres, 
        % tddAvant, tddRef, tddTable1, tddTable2
        function tdd_ValueChanged(app, event)
            %           quand on change la valeur du premier panneau déroulant du
            %           menu de la segmentation temporelle ou des tables
            meta = app.trip.getMetaInformations();
            showTemporellePanel(app);
            
            % Cas simple
            if ~isempty(app.tddRef.Value)
                if app.ttbEvent.Value == true && ~isEmpty(app.trip.getAllEventOccurences(app.tddRef.Value))
                    app.tc = getTC(app, app.trip, 'event');
                elseif app.ttbSituation.Value == true && ~isEmpty(app.trip.getAllSituationOccurences(app.tddRef.Value))
                    app.tc = getTC(app, app.trip, 'situation');
                else
                    text = ['La table ' app.tddRef.Value {' est vide.' 'Veuillez en choisir une autre.'}];
                    uialert(app.BINDGUIFigure, text, ' ', 'Icon', 'error');
                    app.tddRef.Value = ''; showTemporellePanel(app);
                    return;
                end
                tspn_ValueChanged(app, event);
                
                % Cas double
            elseif ~isempty(app.tddTable1.Value)
                if ~strcmp(app.oldTable1, app.tddTable1.Value)
                    app.tddAvant.Items = {''}; app.tefNom.Value = ''; showTemporellePanel(app);
                end
                
                if ~isEmpty(app.trip.getAllEventOccurences(app.tddTable1.Value))
                    record = app.trip.getAllEventOccurences(app.tddTable1.Value);
                    list = meta.getEventVariablesNamesList(app.tddTable1.Value);
                    before = record.getVariableValues(list{2});
                    for i = 1:length(before)
                        app.tddAvant.Items{i+1} = before{i};
                    end
                    
                    if ~isempty(app.tddAvant.Value)
                        table{1} = app.tddTable1.Value;
                        table{2} = find(contains(app.tddAvant.Items, app.tddAvant.Value), 1) - 1;
                        app.tc{1} = getTC(app, app.trip, table);
                    end
                else
                    text = ['La table ' app.tddTable1.Value ' est vide. \n Veuillez en choisir une autre.'];
                    uialert(app.BINDGUIFigure, text, ' ', 'Icon', 'error');
                    app.tddTable1.Value = ''; showTemporellePanel(app);
                    return
                end
                
                if ~isempty(app.tddTable2.Value)
                    if ~strcmp(app.oldTable2, app.tddTable2.Value)
                        app.tddApres.Items = {''}; app.tefNom.Value = ''; showTemporellePanel(app);
                    end
                    
                    if ~isEmpty(app.trip.getAllEventOccurences(app.tddTable2.Value))
                        record = app.trip.getAllEventOccurences(app.tddTable2.Value);
                        list = meta.getEventVariablesNamesList(app.tddTable2.Value);
                        after = record.getVariableValues(list{2});
                        for i = 1:length(after)
                            app.tddApres.Items{i+1} = after{i};
                        end
                        
                        if ~isempty(app.tddApres.Value)
                            if ~all(strcmp(app.tddApres.Value, app.tddAvant.Value) && strcmp(app.tddTable1.Value, app.tddTable2.Value))
                                table{1} = app.tddTable2.Value;
                                table{2} = find(contains(app.tddApres.Items, app.tddApres.Value), 1) - 1;
                                app.tc{2} = getTC(app, app.trip, table);
                                
                                tspn_ValueChanged(app, event);
                            else
                                text = ['Veuillez choisir différents évènements.'];
                                uialert(app.BINDGUIFigure, text, ' ', 'Icon', 'error');
                                app.tddApres.Value = ''; showTemporellePanel(app);
                                return
                            end
                        end
                    else
                        text = ['La table ' app.tddTable2.Value ' est vide. \n Veuillez en choisir une autre.'];
                        uialert(app.BINDGUIFigure, text, ' ', 'Icon', 'error');
                        app.tddTable2.Value = ''; showTemporellePanel(app);
                        return
                    end
                end
                
                app.oldTable1 = app.tddTable1.Value;
                app.oldTable2 = app.tddTable2.Value;
            end
        end

        % Value changed function: tspnInf1, tspnInf2, tspnSup1, 
        % tspnSup2
        function tspn_ValueChanged(app, event)
            if ~isempty(app.tddRef.Value) %on a bien choisit une situation
                
                if app.ttbEvent.Value == true
                    app.tspnSup1.LowerLimitInclusive = 'on';
                    app.tspnSup1.UpperLimitInclusive = 'on';
                    app.tspnInf1.UpperLimitInclusive = 'on';
                    app.tspnInf1.LowerLimitInclusive = 'on';
                    if app.tspnSup1.Value == app.tc{1}(1)
                        app.tspnInf1.Enable = 'off';
                        app.tspnSup1.Limits = [app.tspnInf1.Value Inf];
                    else
                        app.tspnInf1.Enable = 'on';
                        app.tspnInf1.Limits = [-app.tc{1}(1) app.tspnSup1.Value];
                        app.tspnSup1.Limits = [app.tspnInf1.Value Inf];
                    end
                else
                    app.tspnSup1.LowerLimitInclusive = 'on';
                    app.tspnSup1.UpperLimitInclusive = 'on';
                    app.tspnInf1.UpperLimitInclusive = 'on';
                    app.tspnInf1.LowerLimitInclusive = 'on';
                    app.tspnInf1.Enable = 'on';
                    lim_inf = min(-app.tc{1} + app.tc{2} + app.tspnSup1.Value);
                    lim_sup = min(app.tc{1} - app.tc{2} + app.tspnInf1.Value);
                    app.tspnInf1.Limits = [-app.tc{1}(1) lim_inf];
                    app.tspnSup1.Limits = [lim_sup Inf];
                end
                
                inf1 = num2str(abs(app.tspnInf1.Value));
                sup1 = num2str(abs(app.tspnSup1.Value));
                app.tefNom.Value = [app.tddRef.Value '_' inf1 '_' sup1];
                
                app.create{1} = app.tspnInf1.Value;
                app.create{2} = app.tspnSup1.Value;
            elseif ~isempty(app.tddAvant.Value) && ~isempty(app.tddApres.Value) %on a remplis les deuxiemes cases des deux tables pour le cas double
                app.tspnInf2.UpperLimitInclusive = 'on';
                app.tspnSup2.LowerLimitInclusive = 'on';
                if app.tc{1} > app.tc{2}
                    text = sprintf('Le TimeCode de la 1ère Table est inférieur à celui de la 2ème. \n Veuillez choisir une autres de temps');
                    uialert(app.BINDGUIFigure, text, ' Erreur timecode ', 'Icon', 'error');
                    app.tddAvant.Value = ''; app.tddApres.Value = '';  showTemporellePanel(app);
                    return
                else
                    lim_inf = -app.tc{1} + app.tc{2} + app.tspnSup2.Value;
                    lim_sup = app.tc{1} - app.tc{2} + app.tspnInf2.Value;
                    app.tspnInf2.Limits = [-app.tc{1} lim_inf];
                    app.tspnSup2.Limits = [lim_sup Inf];
                    
                    inf2 = num2str(abs(app.tspnInf2.Value));
                    sup2 = num2str(abs(app.tspnSup2.Value));
                    app.tefNom.Value = [app.tddTable1.Value(1:3) '_' app.tddTable2.Value(1:3) '_' inf2 '_' sup2];
                    
                    app.create{1} = app.tspnInf2.Value;
                    app.create{2} = app.tspnSup2.Value;
                end
            end
            showbCreer(app);
        end

        % Callback function: cbtgTable, cddChoix, tbtgTable
        function tc_bg_SelectionChanged(app, event)
            if app.rbConditionnelle.Value
                app.cddData.Items = {''};
                switch app.cddChoix.Value
                    case "Situation"
                        app.lc_nom.Text = "Nom de la situation :";
                        app.cddCond.Items = {'', '<', '≤', '=', '≠', '>', '≥'};
                        app.cbtgTable.SelectedObject = app.ctbData;
                    case "Event"
                        app.lc_nom.Text = "Nom de l'évènement :";
                        app.cddCond.Items = {'', '<', '≤', '=', '≠', '>', '≥', 'est égal à', 'n''est pas égal à', 'contient', 'ne contient pas', 'commence par', 'ne commence pas par', 'finit par', 'ne finit pas par', 'NULL', 'non NULL'};
                end
                switch app.cbtgTable.SelectedObject
                    case app.ctbData
                        for i = 1:length(app.DatasNode.Children)
                            app.cddData.Items{end+1} = app.DatasNode.Children(i).Text;
                        end
                    case app.ctbEvent
                        for i = 1:length(app.EventsNode.Children)
                            app.cddData.Items{end+1} = app.EventsNode.Children(i).Text;
                        end
                        app.cddChoix.Value = 'Event';
                end
                showConditionnellePanel(app);
            elseif app.rbTemporelle.Value
                app.tddRef.Items = {''};
                app.tddTable1.Items = {''};
                app.tddTable2.Items = {''};
                app.tspnInf1.Limits = [-Inf Inf];
                app.tspnSup1.Limits = [-Inf Inf];
                app.tefNom.Value = '';
                %                 switch app.tbtgTable.SelectedObject
                %                     case app.ttbEvent
                %                         for i = 1:length(app.EventsNode.Children)
                %                             app.tddRef.Items{end+1} = app.EventsNode.Children(i).Text;
                %                             %% TODO : gérer le chargement des events table1/table2
                %                             app.tddTable1.Items{end+1} = app.EventsNode.Children(i).Text;
                %                             app.tddTable2.Items{end+1} = app.EventsNode.Children(i).Text;
                %                         end
                %                     case app.ttbSituation
                %                         for i = 1:length(app.SituationsNode.Children)
                %                             app.tddRef.Items{end+1} = app.SituationsNode.Children(i).Text;
                %                         end
                %                         for i = 1:length(app.EventsNode.Children)
                %                             app.tddTable1.Items{end+1} = app.EventsNode.Children(i).Text;
                %                             app.tddTable2.Items{end+1} = app.EventsNode.Children(i).Text;
                %                         end
                %                 end
                update_DropDownliste(app);
                showbCreer(app); showTemporellePanel(app);
            end
        end

        % Value changed function: cddCond, cddData, cddVar, cefText
        function cdd_ValueChanged(app, event)
            meta = app.trip.getMetaInformations();
            
            if ~strcmp(app.oldChoix, app.cddChoix.Value)
                showConditionnellePanel(app);
            end
            
            if ~isempty(app.cddData.Value)
                showConditionnellePanel(app);
                
                if ~strcmp(app.oldTable, app.cddData.Value)
                    app.cddVar.Items = {''};
                    showConditionnellePanel(app);
                    
                    if app.ctbData.Value
                        cols = meta.getDataVariablesNamesList(app.cddData.Value);
                    else
                        cols = meta.getEventVariablesNamesList(app.cddData.Value);
                    end
                    
                    % Remplissage dynamique de Colonne en fonction de Data
                    for i = 1:length(cols)
                        app.cddVar.Items{end+1} = cols{i};
                        help{i} = class(cols{i});
                    end
                end
                
            else
                app.cddVar.Items = {''};
                showConditionnellePanel(app);
            end
            if ~isempty(app.cddVar.Value)
                if ~strcmp(app.cddVar.Value,'timecode')
                    if app.ctbData.Value
                        meta_data = meta.getMetaData(app.cddData.Value);
                    else
                        meta_data = meta.getMetaEvent(app.cddData.Value);
                    end
                    variables = meta_data.getVariables();
                    for i = 1:length(variables)
                        if strcmp(variables{i}.getName(),app.cddVar.Value)
                            j=i;
                        end
                    end
                    type = variables{j}.getType();
                else
                    type = 'REAL';
                end
                if type == "REAL"
                    app.cddCond.Items = {'', '<', '≤', '=', '≠', '>', '≥'};
                else
                    app.cddCond.Items = {'', 'est égal à', 'n''est pas égal à', 'contient', 'ne contient pas', 'commence par', 'ne commence pas par', 'finit par', 'ne finit pas par', 'NULL', 'non NULL'};
                end
            end
            
            showConditionnellePanel(app);
            app.oldTable = app.cddData.Value;
        end

        % Button pushed function: cbPlus
        function cbPlus_ButtonPushed(app, event)
            if isnumeric(app.c_ef.Value)
                value = num2str(app.c_ef.Value);
            else
                value = app.c_ef.Value;
            end
            
            app.clbVar.Items{end+1} = ['(C' num2str(length(app.clbVar.Items) + 1) ' ) : ' app.cddData.Value(1:3) '_' app.cddVar.Value '_' app.cddCond.Value '_' value];
            app.create{1} = app.cddCond.Value;
            app.create{2} = app.c_ef.Value;
            
            item = ['cond' num2str(length(app.clbVar.Items))];
            app.structCondition.(item) = createField(app, app.trip, '');
            
            app.isUniform = checkIfIsUniform(app, app.structCondition);
            if app.isUniform
                app.condMinStep = item;
            elseif app.structCondition.(item).nbPas < app.structCondition.(app.condMinStep).nbPas
                app.condMinStep = item;
            end
            app.cddCond.Value = ''; showConditionnellePanel(app);
            
            %             if length(app.clbVar.Items) > 1
            %                 app.cefNom.Value = num2str(length(app.clbVar.Items));
            %                 conditions = '(';
            %                 for i = 1:length(app.clbVar.Items)
            %                     app.cefNom.Value = [app.cefNom.Value '_' app.clbVar.Items{i}(1:3)];
            %                     indice = int2str(i);
            %                     conditions = strcat(conditions,' C',indice);
            %                     if ~(i == length(app.clbVar.Items))
            %                         conditions = strcat(conditions,' ET');
            %                     end
            %                 end
            %                 conditions = strcat(conditions, ')');
            %                 app.connecteurs.Value = conditions;
            %             else
            %                 app.cefNom.Value = [app.cddData.Value(1:3) '_' app.cddVar.Value '_' app.cddCond.Value '_' value];
            %             end
            showbCreer(app);
        end

        % Button pushed function: cbVider
        function cbVider_ButtonPushed(app, event)
            new_items = {}; new_struct = struct();
            
            if isempty(app.clbVar.Value)
                for i = 1:numel(fieldnames(app.structCondition))
                    cond = ['cond' num2str(i)];
                    app.structCondition = rmfield(app.structCondition, cond);
                end
                app.clbVar.Items = {};
                app.isUniform = 1;
                app.condMinStep = '';
            else
                for i = 1:length(app.clbVar.Items)
                    delete = 0;
                    cond = ['cond' num2str(i)];
                    for j = 1:length(app.clbVar.Value)
                        if strcmp(app.clbVar.Items{i}, app.clbVar.Value{j})
                            delete = 1;
                            break;
                        end
                    end
                    if ~delete
                        new_items{end+1} = app.clbVar.Items{i};
                        newCond = ['cond' num2str(length(new_items))];
                        new_struct.(newCond) = app.structCondition.(cond);
                    end
                end
                app.clbVar.Items = new_items;
                app.structCondition = new_struct;
                app.isUniform = checkIfIsUniform(app, app.structCondition);
                item = ['cond' num2str(length(app.clbVar.Items))];
                app.condMinStep = item;
            end
            app.ETButton.Enable = 'off';
            app.OUButton.Enable = 'off';
            if isempty(app.clbVar.Items)
                app.connecteurs.Value = '';
            end
            
            %             if isnumeric(app.c_ef.Value)
            %                 value = num2str(app.c_ef.Value);
            %             else
            %                 value = app.c_ef.Value;
            %             end
            %             name='';
            %             if length(app.clbVar.Items) > 1
            %                 app.cefNom.Value = num2str(length(app.clbVar.Items));
            %                 conditions = '(';
            %                 for i = 1:length(app.clbVar.Items)
            %                     app.cefNom.Value = [app.cefNom.Value '_' app.clbVar.Items{i}(1:3)];
            %                     indice = int2str(i);
            %                     conditions = strcat(conditions,' C',indice);
            %                     if ~(i == length(app.clbVar.Items))
            %                         conditions = strcat(conditions,' ET');
            %                     end
            %                     numerocondition = strcat('app.structCondition.cond',num2str(i));
            %                     situation = eval_struct_str(app , numerocondition);
            %                     name = strcat(name, situation.name(1:3));
            %                     condition = num2str(situation.value);
            %                     if length(condition)>3
            %                         condition = condition(1:3);
            %                     end
            %                     name =  strcat(name, '_', condition, '_');
            %
            %                 end
            %                 conditions = strcat(conditions, ')');
            %                 app.connecteurs.Value = conditions;
            %             end
            app.create = {};
            %             app.cefNom.Value = name;
            %app.cefNom.Value = [app.cddData.Value(1:3) '_' app.cddVar.Value '_' app.cddCond.Value '_' value];
            for i = 1:length(app.clbVar.Items)
                name = app.clbVar.Items{i};
                if strcmp(name(6:6), ' ')
                    longueur = length(name);
                    app.clbVar.Items{i} = name(8:longueur);
                    app.clbVar.Items{i} = strcat('(C', num2str(i), ' ) : ', app.clbVar.Items{i});
                elseif strcmp(name(7:7), ' ')
                    longueur = length(name);
                    app.clbVar.Items{i} = name(9:longueur);
                    app.clbVar.Items{i} = strcat('(C', num2str(i), ' ) : ',  app.clbVar.Items{i});
                elseif strcmp(name(8:8), ' ')
                    longueur = length(name);
                    app.clbVar.Items{i} = name(10:longueur);
                    app.clbVar.Items{i} = strcat('(C', num2str(i), ' ) : ', app.clbVar.Items{i});
                end
            end
            
            showConditionnellePanel(app);
            showbCreer(app);
        end

        % Button pushed function: cbCreer, tbCreer
        function tc_bCreer_ButtonPushed(app, event)
            %  ---- "Temporelle" panel ----
            percent = 1;
            if app.rbTemporelle.Value
                if ~compareCreatedNames(app,app.tefNom.Value)
                    if app.tcbAppliquerTrip.Value
                        list = fieldnames(app.structScnSelect);
                        if isempty(list)
                            return
                        end
                    end
                    d = uiprogressdlg(app.BINDGUIFigure, 'Message', 'Création en cours', 'Indeterminate','on'); pause(app.pauseValue);
                    
                    % Cas simple
                    if ~isempty(app.tddRef.Value)
                        if ~app.tcbAppliquerTrip.Value
                            segmentationTemporelle(app, app.trip, app.tc, d, 1, '');
                        else
                            %inc = d.Value; percent_scn = 1 ./ length(list);
                            for i = 1:length(app.TripsSelectNode.Children)
                                if any(contains(list, {app.TripsSelectNode.Children(i).Text}))
                                    %percent = percent_scn ./ length(app.TripsSelectNode.Children(i).Children);
                                    for j = 1:length(app.TripsSelectNode.Children(i).Children)
                                        if startsWith(app.TripsSelectNode.Children(i).Children(j).Text, '(réf)')
                                            text = [app.TripRefNode.Text ': '];
                                            segmentationTemporelle(app, app.trip, app.tc, d, percent, text);
                                        else
                                            %inc = inc + percent; d.Value = inc;
                                            d.Message = [app.TripsSelectNode.Children(i).Children(j).Text];
                                            trip_toload = fr.lescot.bind.kernel.implementation.SQLiteTrip(app.TripsSelectNode.Children(i).Children(j).Tag, 0.04, false);
                                            if checkIfExists(app, trip_toload, '')
                                                if app.ttbEvent.Value
                                                    tc_toload = getTC(app, trip_toload, 'event');
                                                else
                                                    tc_toload = getTC(app, trip_toload, 'situation');
                                                end
                                                d.Message = [app.TripsSelectNode.Children(i).Children(j).Text ': '];
                                                segmentationTemporelle(app, trip_toload, tc_toload, d, percent, d.Message);
                                            end
                                            delete(trip_toload);
                                        end
                                    end
                                end
                            end
                        end
                        % sauvegarde env
                        save_env_seg_simple(app);
                        present = false;
                        %verification si il n'existe pas déjà
                        for n = 1:length(app.SimpleNode.Children)
                            if strcmp(app.SimpleNode.Children(n).Text,app.tefNom.Value)
                                present = true;
                                indice = n;
                            end
                        end
                        if ~present
                            uitreenode('Parent',app.SimpleNode,"Text",app.tefNom.Value,'nodeData',app.struct_save.segmentation.temporelle.simple.(app.tefNom.Value));
                        else
                            node = app.SimpleNode.Children(indice);
                            node.delete;
                            uitreenode('Parent',app.SimpleNode,"Text",app.tefNom.Value,'nodeData',app.struct_save.segmentation.temporelle.simple.(app.tefNom.Value));
                        end
                        % Cas double
                    else
                        if ~app.tcbAppliquerTrip.Value
                            segmentationTemporelle(app, app.trip, app.tc, d, 1, '');
                        else
                            %inc = d.Value; percent_scn = 1 ./ length(app.TripsSelectNode.Children);
                            for i = 1:length(app.TripsSelectNode.Children)
                                if any(contains(list, {app.TripsSelectNode.Children(i).Text}))
                                    %percent = percent_scn ./ length(app.TripsSelectNode.Children(i).Children);
                                    for j = 1:length(app.TripsSelectNode.Children(i).Children)
                                        if startsWith(app.TripsSelectNode.Children(i).Children(j).Text, '(réf)')
                                            text = [app.TripRefNode.Text ': '];
                                            segmentationTemporelle(app, app.trip, app.tc, d, percent, text);
                                        else
                                            %inc = inc + percent; d.Value = inc;
                                            d.Message = [app.TripsSelectNode.Children(i).Children(j).Text];
                                            
                                            trip_toload = fr.lescot.bind.kernel.implementation.SQLiteTrip(app.TripsSelectNode.Children(i).Children(j).Tag, 0.04, false);
                                            if checkIfExists(app, trip_toload, '')
                                                d.Message = strcat([app.TripsSelectNode.Children(i).Text ': '],' ',[app.TripsSelectNode.Children(i).Children(j).Text]," ");
                                                table{1} = app.tddTable1.Value;
                                                table{2} = find(contains(app.tddAvant.Items, app.tddAvant.Value), 1) - 1;
                                                tc_toload{1} = getTC(app, trip_toload, table);
                                                
                                                table{1} = app.tddTable2.Value;
                                                table{2} = find(contains(app.tddApres.Items, app.tddApres.Value), 1) - 1;
                                                tc_toload{2} = getTC(app, trip_toload, table);
                                                segmentationTemporelle(app, trip_toload, tc_toload, d, percent, d.Message);
                                            end
                                            delete(trip_toload);
                                        end
                                    end
                                end
                            end
                        end
                        save_env_seg_double(app);
                        present = false;
                        %verification si il n'existe pas déjà
                        for n = 1:length(app.DoubleNode.Children)
                            if strcmp(app.DoubleNode.Children(n).Text,app.tefNom.Value)
                                present = true;
                                indice = n;
                            end
                        end
                        if ~present
                            uitreenode('Parent',app.DoubleNode,"Text",app.tefNom.Value,'nodeData',app.struct_save.segmentation.temporelle.double.(app.tefNom.Value));
                        else
                            node = app.DoubleNode.Children(indice);
                            node.delete;
                            uitreenode('Parent',app.DoubleNode,"Text",app.tefNom.Value,'nodeData',app.struct_save.segmentation.temporelle.double.(app.tefNom.Value));
                        end
                    end
                    try
                        %d.Value = 1;
                        d.Message = 'Création terminée'; pause(app.pauseValue);
                        %d.Value = 0;
                        d.Message = 'Rafraîchissement en cours'; pause(app.pauseValue);
                        refreshTree(app, d);
                        d.Value = 1; d.Message = 'Rafraîchissement terminé'; pause(app.pauseValue);
                        close(d);
                    catch
                        return;
                    end
                else
                    uialert(app.BINDGUIFigure, 'Ce nom existe déjà. Veuillez saisir un nouveau nom', ' ', 'Icon', 'warning');
                    
                end
            end
            
            % ---- "Conditionnelle" panel ----
            function uniform_CloseFcn(app, event) % verifie si l'utilisateur a bien donné oui à la question de uniformiser
                if strcmp(event.SelectedOption, 'Oui')
                    d = uiprogressdlg(app.BINDGUIFigure, 'Message', 'Opération en cours', 'Indeterminate','on'); pause(app.pauseValue);
                    app.structCondition = uniform(app, app.trip, app.structCondition);
                    app.isUniform = 1;
                    close(d);
                    tc_bCreer_ButtonPushed(app, event);
                end
            end
            
            if app.rbConditionnelle.Value
                if ~compareCreatedNames(app, app.cefNom.Value)
                    if ~app.isUniform
                        uiconfirm(app.BINDGUIFigure, ...
                            'Voulez-vous homogénéiser les tables ?', 'Tables non homogènes', 'Options', {'Non', 'Oui'}, ...
                            'DefaultOption', 2, 'CancelOption', 1, 'Icon', 'info', 'CloseFcn', @(src,event)uniform_CloseFcn(app,event));
                    else
                        if app.tcbAppliquerTrip.Value
                            list = fieldnames(app.structScnSelect);
                            if isempty(list)
                                return
                            end
                        end
                        d = uiprogressdlg(app.BINDGUIFigure, 'Message', 'Création en cours',"Indeterminate","on"); pause(app.pauseValue);
                        
                        if ~app.ccbAppliquerTrip.Value
                            save_env_seg_cond(app);
                            present = false;
                            %verification si il n'existe pas déjà
                            for n = 1:length(app.ConditionnelleNode.Children)
                                if strcmp(app.ConditionnelleNode.Children(n).Text,app.tefNom.Value)
                                    present = true;
                                    indice = n;
                                end
                            end
                            if ~present
                                uitreenode('Parent',app.ConditionnelleNode,"Text",app.cefNom.Value,'nodeData',app.struct_save.segmentation.conditionnelle.(app.cefNom.Value));
                            else
                                node = app.ConditionnelleNode.Children(indice);
                                node.delete;
                                uitreenode('Parent',app.ConditionnelleNode,"Text",app.cefNom.Value,'nodeData',app.struct_save.segmentation.conditionnelle.(app.cefNom.Value));
                            end
                            segmentationConditionnelle(app, app.trip, app.structCondition, d, 1, '');
                        else
                            save_env_seg_cond(app);
                            uitreenode('Parent',app.ConditionnelleNode,"Text",app.cefNom.Value,'nodeData',app.struct_save.segmentation.conditionnelle.(app.cefNom.Value));
                            
                            struct_toload = struct();
                            %inc = d.Value; percent_scn = 1 ./ length(app.TripsSelectNode.Children);
                            %parcourt de la liste des scenarios de la selection
                            for i = 1:length(app.TripsSelectNode.Children)
                                %percent = avancement
                                %percent = percent_scn ./ length(app.TripsSelectNode.Children(i).Children);
                                %parcourt de la listes des trips du scenario i
                                for j = 1:length(app.TripsSelectNode.Children(i).Children)
                                    c = 1;
                                    %si c'est le trip de reférence alors
                                    %segmentation normale
                                    if startsWith(app.TripsSelectNode.Children(i).Children(j).Text, '(réf)')
                                        text = [app.TripRefNode.Text ': '];
                                        segmentationConditionnelle(app, app.trip, app.structCondition, d, percent, text);
                                    else
                                        %inc = inc + percent; d.Value = inc;
                                        d.Message = [app.TripsSelectNode.Children(i).Children(j).Text];
                                        trip_toload = fr.lescot.bind.kernel.implementation.SQLiteTrip(app.TripsSelectNode.Children(i).Children(j).Tag, 0.04, false);
                                        %c = 1;
                                        for k = 1:numel(fieldnames(app.structCondition))
                                            cond = ['cond' num2str(k)];
                                            if checkIfExists(app, trip_toload, cond)
                                                item = ['cond' num2str(c)];
                                                struct_toload.(item) = createField(app, trip_toload, cond);
                                                %c = c + 1;
                                                if c == 1
                                                    app.condMinStep = item;
                                                elseif struct_toload.(item).nbPas < struct_toload.(app.condMinStep).nbPas
                                                    app.condMinStep = item;
                                                end
                                                c = c + 1;
                                            end
                                        end
                                        
                                        if ~isempty(fieldnames(struct_toload))
                                            if ~checkIfIsUniform(app, struct_toload)
                                                struct_toload = uniform(app, trip_toload, struct_toload);
                                            end
                                            d.Message = [app.TripsSelectNode.Children(i).Children(j).Text ': '];
                                            segmentationConditionnelle(app, trip_toload, struct_toload, d, percent, d.Message)
                                        end
                                        delete(trip_toload);
                                    end
                                end
                            end
                        end
                        try
                            %d.Value = 1;
                            d.Message = 'Création terminée'; pause(app.pauseValue);
                            %d.Value = 0;
                            d.Message = 'Rafraîchissement en cours'; pause(app.pauseValue);
                            refreshTree(app, d);
                            d.Value = 1;
                            d.Message = 'Rafraîchissement terminé'; pause(app.pauseValue);
                            close(d);
                        catch
                            return;
                        end
                        
                    end
                else
                    uialert(app.BINDGUIFigure, 'Ce nom existe déjà. Veuillez saisir un nouveau nom', ' ', 'Icon', 'warning');
                end
            end
            
            update_DropDownliste(app);
            
            
        end

        % Button pushed function: vbChargerProtocole
        function vbChargerProtocole_ButtonPushed(app, event)
            [file,path] = uigetfile('*.pro', 'Chargez un protocole de codage exisant');
            if ~isequal(path, 0)
                app.protoPath = fullfile(path, file);
                app.vtaNom.Value = app.protoPath;
            end
        end

        % Button pushed function: vbCreerProtocole
        function vbCreerProtocole_ButtonPushed(app, event)
            fr.lescot.codingProtocolEditor.ProtocolCreator;
        end

        % Button pushed function: vbEditerProtocole
        function vbEditerProtocole_ButtonPushed(app, event)
            if isempty(app.protoPath)
                [file,path] = uigetfile('*.pro', 'Chargez un protocole de codage exisant');
                if ~isequal(path, 0)
                    app.protoPath = fullfile(path, file);
                    app.vtaNom.Value = app.protoPath;
                end
            end
            
            protocolCreator = fr.lescot.codingProtocolEditor.ProtocolCreator;
            protocolCreator.setPath(app.protoPath);
            protocolCreator.loadProtocol;
        end

        % Button pushed function: vbLancerCodage
        function vbLancerCodage_ButtonPushed(app, event)
            if isempty(app.trip.getMetaInformations().getVideoFiles())
                uialert(app.BINDGUIFigure, 'Aucune vidéo détectée. \n Veuillez choisir un TRIP adapté.', ' ', 'Icon', 'Info');
            elseif isempty(app.protoPath)
                uialert(app.BINDGUIFigure, 'Aucun protocole détecté. \n Veuillez en charger ou en créer un.', ' ', 'Icon', 'Error');
            else
                d = uiprogressdlg(app.BINDGUIFigure, 'Message', 'Ouverture en cours', 'Indeterminate', 'on');
                fr.lescot.bind.plugins.GenericCodingInterface(app.trip, app.protoPath);
                app.lbPlugins.Value = {'Magneto'};
                lbPlugins_ValueChanged(app, event);
                app.lbPlugins.Value = {'VideoPlayer'};
                lbPlugins_ValueChanged(app, event);
                app.protoPath = ''; app.vtaNom.Value = '';
                close(d);
            end
        end

        % Value changed function: lbPlugins
        function lbPlugins_ValueChanged(app, event)
            
            lenNomP=length(app.env.pluginConfig.nomP);
            listePluginsInPreset={};

            for i=1:1:lenNomP
                listePluginsInPreset{i}=app.env.pluginConfig.nomP(i).nomPlugin;
            end

        app.lbPlugins.Items= listePluginsInPreset;      
        app.lbPlugins.ItemsData= listePluginsInPreset;

            % rien pour le moment
            %peut-être la possibilité de suprimer un plugins de la liste à
            %envoyer à preset
        end

        % Close request function: BINDGUIFigure
        function BINDGUIFigureCloseRequest(app, event)
            for i = 1:length(app.TripsSelectNode.Children)
                for j = 1:length(app.TripsSelectNode.Children(i).Children)
                    delete([app.TripsSelectNode.Children(i).Children(j).Tag '_autosave']);
                end
            end
            %% TODO: Save work environment
            selection = uiconfirm(app.BINDGUIFigure, 'Souhaitez-vous enregistrer votre environnement de travail ?', ...
                'Sauvegarde de l''environnement de travail', 'Options', {'Non', 'Oui'}, 'DefaultOption', 2, 'CancelOption', 1);
            if strcmp(selection, 'Oui')
                %[file_tosave,path] = uiputfile('*.env');
                %save(fullfile(path, file_tosave), 'app');
                SaveMenuSelected(app,event);
                delete(app);
            end
            delete(app);
        end

        % Value changed function: elbType
        function elbType_ValueChanged(app, event)
            app.exportCols = 'type';
            app.elbTable.Items = {}; app.elbVar.Items = {};
            if ~isempty(app.elbType.Value)
                switch app.elbType.Value
                    case 'data'
                        for i = 1:length(app.DatasNode.Children)
                            app.elbTable.Items{i} = app.DatasNode.Children(i).Text;
                        end
                    case 'event'
                        for i = 1:length(app.EventsNode.Children)
                            app.elbTable.Items{i} = app.EventsNode.Children(i).Text;
                        end
                    case 'situation'
                        for i = 1:length(app.SituationsNode.Children)
                            app.elbTable.Items{i} = app.SituationsNode.Children(i).Text;
                        end
                end
            end
        end

        % Value changed function: elbTable
        function elbTable_ValueChanged(app, event)
            app.elbVar.Items = {};
            meta = app.trip.getMetaInformations();
            if length(app.elbTable.Value) == 1
                switch app.elbType.Value
                    case 'data'
                        cols = meta.getDataVariablesNamesList(app.elbTable.Value);
                        cols(ismember(cols,'timecode')) = [];
                    case 'event'
                        cols = meta.getEventVariablesNamesList(app.elbTable.Value);
                        cols(ismember(cols,'timecode')) = [];
                    case 'situation'
                        cols = meta.getSituationVariablesNamesList(app.elbTable.Value);
                        cols(ismember(cols,'startTimecode')) = [];
                        cols(ismember(cols,'endTimecode')) = [];
                end
                app.elbVar.Items = cols;
            end
        end

        % Button pushed function: ebAjouter
        function ebAjouter_ButtonPushed(app, event)
            
            %             app.elbFichier.Value = {};
            %             elbFichier_ValueChanged(app);
            %             app.espnSousEchantillon.Enable = 'off'; app.espnSousEchantillon.Value = 1;
            %             app.le_sousEchantillon.Enable = 'off';
            % si on veut exporter le même type de données
            if ~isfield(app.export,'type')
                app.export.type = '';
            end
            if isempty(app.export.type) || strcmp(app.export.type, app.elbType.Value)
                app.export.type = app.elbType.Value;
                % si on a aucune colonnes dans la liste
                if isempty(app.elbCol.Items)
                    %si on a pas choisi de table en particulier
                    if isempty(app.elbTable.Value)
%                         app.elbCol.Items = {};
%                         name = ['all_' app.elbType.Value];
%                         app.structCol.all.table = app.elbTable.Items;
%                         app.elbCol.Items{end+1} = name;
                        %si on a choisi une/des tables sans info
                    elseif isempty(app.elbVar.Value)
                        fichiers_create = fieldnames(app.export);
                        num_fich = length(fichiers_create);
                        for i = 1:length(app.elbTable.Value)
                            % recupérer le numéro du dernier fichier créer
                            % dans la structure
                            fic = ['fic' num2str(length(app.elbCol.Items)+num_fich)];
                            name = app.elbTable.Value{i};
                            if ~any(contains(app.elbCol.Items, name))
                                %app.structCol.(col).table = app.elbTable.Value(i);
                                %app.elbCol.Items{end+1} = name;
                                if length(app.elbTable.Value) > 1
                                    app.export.(fic).table = app.elbTable.Value(i);
                                else
                                    app.export.(fic).table = {app.elbTable.Value(i)};
                                end 
                                app.elbCol.Items{end+1} = name;
                            end
                        end
                        % si on a choisit une table et des info particulières
                    else
                        fichiers_create = fieldnames(app.export);
                        length(fichiers_create);
                        fic = ['fic',num2str(length(fichiers_create))];
                        switch app.export.type
                            case 'data'
                                app.export.(fic).timecode = cell2mat(app.trip.getAllDataOccurences(app.elbTable.Value{1}).getVariableValues('timecode'));
                            case 'event'
                                %a remplacer pas app.export.(fic).timecode 
                                app.export.(fic).timecode = cell2mat(app.trip.getAllEventOccurences(app.elbTable.Value{1}).getVariableValues('timecode'));
                            case 'situation'
                                %a remplacer pas app.export.(fic).timecode 
                                app.export.(fic).startTimecode = cell2mat(app.trip.getAllSituationOccurences(app.elbTable.Value{1}).getVariableValues('startTimecode'));
                                app.export.(fic).endTimecode = cell2mat(app.trip.getAllSituationOccurences(app.elbTable.Value{1}).getVariableValues('endTimecode'));
                        end
                        for i = 1:length(app.elbTable.Value)
                            %col = ['col' num2str(length(app.elbCol.Items)+1)];
                            if strcmp(app.export.type, 'situation')
                                app.elbCol.Items{end+1} = [app.elbTable.Value{i} '.startTimecode'];
                                app.elbCol.Items{end+1} = [app.elbTable.Value{i} '.endTimecode'];
                            else
                                app.elbCol.Items{end+1} = [app.elbTable.Value{i} '.timecode'];
                            end
                            for j = 1:length(app.elbVar.Value)
                                name = [app.elbTable.Value{i} '.' app.elbVar.Value{j}];
                                if ~any(contains(app.elbCol.Items, name))
                                    %                                      app.structCol.(col).table(end +1) = app.elbTable.Value(i);
                                    app.elbCol.Items{end+1} = name;
                                end
                            end
                        end
                    end
                    
                    
                    % si il y'a déjà des colonnes dans la liste
                    % beaucoup de cas à gérer
                else
                    % si on est dans le cas ou l'on veut des info en particulier
                    if any(contains(app.elbCol.Items,'.'))
                        % 3 cas
                        % si on choisit toutes les tables
                        if isempty(app.elbTable.Value)
                            % demander si il veut vraiment mettre toutes les données
                            % a faire
                            uiconfirm(app.BINDGUIFigure, {'on ne peut pas ajouter toutes les données veuillez le faire manuellement (option "non") ou alors vider et ajouter toutes les tables(option "oui").'...
                                'Le fichier en cours de création va être effacer.' ...
                                'Voulez-vous continuer ?'}, 'Format de données incompatible', 'Options', {'Non', 'Oui'}, ...
                                'DefaultOption', 2, 'CancelOption', 1, 'Icon', 'warning', 'CloseFcn', @(src,event)type_CloseFcn(app,event));
                            
                            %on veut ajouter une ou plusieurs tables complètes
                        elseif isempty(app.elbVar.Value)
                            %verification que la table selectionnée est présente
                            %fonction de verification
                            elements = app.elbTable.Value;
                            nbcol = length(app.elbCol.Items);
                            for i = 1:length(elements)
                                verif = verification_tc(app,elements(i));
                                if verif
                                    j = 1;
                                    app.elbTable.Value = elements(i);
                                    elbTable_ValueChanged(app,event);
                                    while j <= nbcol
                                        if contains(app.elbCol.Items(j),elements(i))
                                            app.elbCol.Value = app.elbCol.Items(j);
                                            ebDelete_ButtonPushed(app,event);
                                            nbcol = nbcol-1;
                                        else
                                            j = j+1;
                                        end
                                    end
                                    app.export.type = app.elbType.Value;
                                    for j = 1:length(app.elbVar.Items)
                                        name = [elements{i} '.' app.elbVar.Items{j}];
                                        if ~any(contains(app.elbCol.Items, name))
                                            app.elbCol.Items{end+1} = name;
                                        end
                                    end
                                else
                                    uiconfirm(app.BINDGUIFigure, {'on ne peut pas ajouter toutes les données veuillez le faire manuellement (option "non") ou alors vider et ajouter toutes les tables(option "oui").'...
                                        'Le fichier en cours de création va être effacer.' ...
                                        'Voulez-vous continuer ?'}, 'Format de données incompatible', 'Options', {'Non', 'Oui'}, ...
                                        'DefaultOption', 2, 'CancelOption', 1, 'Icon', 'warning', 'CloseFcn', @(src,event)type_CloseFcn(app,event));
                                end
                            end
                            %on veut ajouter des éléments d'une table
                        else
                            %verification que la table selectionnée est présente
                            %fonction de verification
                            verif = verification_tc(app, app.elbTable.Value(1));
                            if verif
                                for i = 1:length(app.elbVar.Value)
                                    if ~any(contains(app.elbCol.Items, strcat(app.elbTable.Value,'.',app.elbVar.Value(i))))
                                        %a changer tout ça
                                        col = 'col1';
                                        if isfield(app.structCol,col)
                                            if ~isfield(app.structCol.(col),'table')
                                                app.structCol.(col).table = app.elbTable.Value;
                                            else
                                                if ~any(contains(app.structCol.(col).table,app.elbTable.Value{1}))
                                                    app.structCol.(col).table{end+1} = app.elbTable.Value{1};
                                                end
                                            end
                                        else
                                            app.structCol.(col).table = app.elbTable.Value;
                                        end
                                        name = strcat(app.elbTable.Value{1},'.',app.elbVar.Value{i});
                                        if ~any(contains(app.elbCol.Items, name))
                                            app.elbCol.Items{end+1} = name;
                                        end
                                    end
                                end
                            else
                                uiconfirm(app.BINDGUIFigure, {'Impossible d''ajouter des données de differents timecode dans le même fichier. Le fichier en cours de création va être effacer.' ...
                                    'Voulez-vous continuer ?'}, 'Format de données incompatible', 'Options', {'Non', 'Oui'}, ...
                                    'DefaultOption', 2, 'CancelOption', 1, 'Icon', 'warning', 'CloseFcn', @(src,event)type_CloseFcn(app,event));
                            end
                            
                            
                        end
%                         % si on a mis des tables complètes
                    else
                        %3cas
                        % si on choisit toutes les tables
                        if isempty(app.elbTable.Value)
                            % demander si il veut vraiment mettre toutes les données
                            % a faire
                            uiconfirm(app.BINDGUIFigure, {'on ne peut pas ajouter toutes les données veuillez le faire manuellement (option "non") ou alors vider et ajouter toutes les tables(option "oui").'...
                                'Le fichier en cours de création va être effacer.' ...
                                'Voulez-vous continuer ?'}, 'Format de données incompatible', 'Options', {'Non', 'Oui'}, ...
                                'DefaultOption', 2, 'CancelOption', 1, 'Icon', 'warning', 'CloseFcn', @(src,event)type_CloseFcn(app,event));
                            
                            %on veut ajouter une ou plusieurs tables complètes
                        elseif isempty(app.elbVar.Value)
                            %verification que la table selectionnée est
                            %présente
                            %fonction de verification
                            tableExiste = {};
                            for i = 1:length(app.elbTable.Value)
                                if ~any(contains(app.elbCol.Items, app.elbTable.Value{i}))
                                    fichiers_create = fieldnames(app.export);
                                    nbfich = length(fichiers_create);
                                    fic = ['fic' num2str(nbfich)];
                                    app.export.(fic).table = app.elbTable.Value(i);
                                    name = app.elbTable.Value{i};
                                    %app.structCol.(col).table = app.elbTable.Value(i);
                                    app.elbCol.Items{end+1} = name;
                                else
                                    tableExiste{end+1} =  app.elbTable.Value(i);
                                end
                            end
                            if ~isempty(tableExiste)
                                if length(tableExiste) == 1
                                    text = strcat('La table', " ","'", tableExiste{1}, "'", ' existe deja dans votre fichier.' );
                                    uialert(app.BINDGUIFigure, text, ' ', 'Icon', 'info');
                                else
                                    text = strcat('Les tables', " ","'", tableExiste{1}, "'");
                                    for num = 2:length(tableExiste)
                                        text = strcat(text, " et", " ","'", tableExiste{num}, "'");
                                    end
                                    text = strcat(text,' existes deja dans votre fichier.' );
                                    uialert(app.BINDGUIFigure, text, ' ', 'Icon', 'info');
                                end
                            end
                            %on veut ajouter des éléments d'une table
                        else
                            % on ne peut pas
                            
                        end
                    end
                    
                    %fonction verification
                    %                     bool = verification_tc(app);
                    %                     if ~bool
                    %                         %message d'erreur
                    %                         text = strcat('les timecodes ne sont pas homogènes');
                    %                         uialert(app.BINDGUIFigure, text, ' ', 'Icon', 'error');
                    %                         return
                    %                     else
                    %                         for i = 1:length(app.elbTable.Value)
                    %                             col = ['col' num2str(length(app.elbCol.Items)+1)];
                    %                             for j = 1:length(app.elbVar.Value)
                    %                                 name = [app.elbTable.Value{i} '.' app.elbVar.Items{j}];
                    %                                 if ~any(contains(app.elbCol.Items, name))
                    %                                     app.structCol.(col).table = app.elbTable.Value(i);
                    %                                     app.elbCol.Items{end+1} = name;
                    %                                 end
                    %                             end
                    %                         end
                    %                     end
                    
                    
                end
                %                  if isempty(app.elbTable.Value)
                %                      app.elbCol.Items = {};
                %                      name = ['all_' app.elbType.Value];
                %                      app.structCol.all.table = app.elbTable.Items;
                %                      app.elbCol.Items{end+1} = name;
                %                 elseif isempty(app.elbVar.Value)
                %                     for i = 1:length(app.elbTable.Value)
                %                         col = ['col' num2str(length(app.elbCol.Items)+1)];
                %                         name = app.elbTable.Value{i};
                %                         if ~any(contains(app.elbCol.Items, name))
                %                             app.structCol.(col).table = app.elbTable.Value(i);
                %                             app.elbCol.Items{end+1} = name;
                %                         end
                %                     end
                %                  else
                %                     for i = 1:length(app.elbVar.Value)
                %                         col = ['col' num2str(length(app.elbCol.Items)+1)];
                %                         name = cell2mat([app.elbTable.Value '.' app.elbVar.Value{i}]);
                %                         if ~any(contains(app.elbCol.Items, name))
                %                             app.structCol.(col).table = app.elbTable.Value;
                %                             app.structCol.(col).var = app.elbVar.Value;
                %                             app.elbCol.Items{end+1} = name;
                %                         end
                %                     end
                %                     if strcmp(app.elbType.Value, 'situation')
                %                         str_start = cell2mat(strcat(app.elbTable.Value, '.','startTimecode'));
                %                         str_end = cell2mat(strcat(app.elbTable.Value, '.','endTimecode'));
                %                         if ~any(contains(app.elbCol.Items, str_start))
                %                             %col = ['col' num2str(length(app.elbCol.Items)+1)];
                %                             app.structCol.(col).table = app.elbTable.Value;
                %                             app.structCol.(col).var{end+1} = 'startTimecode';
                %                             app.elbCol.Items{end+1} = str_start;
                %                         end
                %                         if ~any(contains(app.elbCol.Items, str_end))
                %                             %col = ['col' num2str(length(app.elbCol.Items)+1)];
                %                             app.structCol.(col).table = app.elbTable.Value;
                %                             app.structCol.(col).var{end+1} = 'endTimecode';
                %                             app.elbCol.Items{end+1} = str_end;
                %                         end
                %                     end
                %                     if length(app.elbCol.Items) == 1
                %                         app.espnSousEchantillon.Enable = 'on'; app.le_sousEchantillon.Enable = 'on'; % à voir...
                %                     end
                %                 end
            else
                uiconfirm(app.BINDGUIFigure, {'Impossible d''ajouter différents types de données. Le fichier en cours de création va être effacer.' ...
                    'Voulez-vous continuer ?'}, 'Format de données incompatible', 'Options', {'Non', 'Oui'}, ...
                    'DefaultOption', 2, 'CancelOption', 1, 'Icon', 'warning', 'CloseFcn', @(src,event)type_CloseFcn(app,event));
            end
            
            function type_CloseFcn(app, event)
                if strcmp(event.SelectedOption, 'Oui')
                    app.elbCol.Value = {};
                    ebDelete_ButtonPushed(app, event);
                    app.exportType = app.elbType.Value;
                    ebAjouter_ButtonPushed(app, event)
                    if isfield(app.structCol,'timecode')
                        app.structCol = rmfield(app.structCol,'timecode');
                    elseif isfield(app.structCol,'startTimecode')
                        app.structCol = rmfield(app.structCol,'startTimecode');
                        app.structCol = rmfield(app.structCol,'endTimecode');
                    end
                end
            end
        end

        % Button pushed function: ebUp
        function ebUp_ButtonPushed(app, event)
            
            tmp = find(~cellfun(@isempty, strfind(app.elbCol.Items, app.elbCol.Value)));
            if tmp ~= 1
                %le cas ou on prend des tables completes
                if ~any(contains(app.elbCol.Items,'.'))
                    return;
                %le cas normal ou on prend des colonnes dans les tables
                else
                    if ~isempty(app.elbFichier.Value)
                        %recup du fichier à modifier
                        %modifier les données directement dans la structure
                        num_fic = find(~cellfun(@isempty, strfind(app.elbFichier.Items, app.elbFichier.Value)));
                        fic = ['fic' num2str(num_fic)];
                        %switch les variables de place
                        tmp_var = app.export.(fic).var{tmp}; 
                        app.export.(fic).var{tmp} = app.export.(fic).var{tmp-1};
                        app.export.(fic).var{tmp-1} = tmp_var;
                        %switch les tables de place
                        tmp_table = app.export.(fic).table{tmp}; 
                        app.export.(fic).table{tmp} = app.export.(fic).table{tmp-1};
                        app.export.(fic).table{tmp-1} = tmp_table;
                        %switch les header de place 
                        tmp_header = app.export.(fic).header{tmp}; 
                        app.export.(fic).header{tmp} = app.export.(fic).header{tmp-1};
                        app.export.(fic).header{tmp-1} = tmp_header;
                    end
                    tmp_item = app.elbCol.Items{tmp - 1};
                    app.elbCol.Items{tmp - 1} = app.elbCol.Items{tmp};
                    app.elbCol.Items{tmp} = tmp_item;
                end
            end
        end

        % Button pushed function: ebDown
        function ebDown_ButtonPushed(app, event)
            tmp = find(~cellfun(@isempty, strfind(app.elbCol.Items, app.elbCol.Value)));
            if tmp ~= length(app.elbCol.Items)
                %le cas ou on prend des tables completes
                if ~any(contains(app.elbCol.Items,'.'))
                    return;
                %le cas normal ou on prend des colonnes dans les tables
                else
                    if ~isempty(app.elbFichier.Value)
                        %recup du fichier à modifier
                        %modifier les données directement dans la structure
                        %fichier = find(~cellfun(@isempty, strfind(app.elbFichier.Items, app.elbFichier.Value)));
                        %fic = ['fic' num2str(fichier)];
                        %tmp_var = {};
                        %tmp_table = {};
                        num_fic = find(~cellfun(@isempty, strfind(app.elbFichier.Items, app.elbFichier.Value)));
                        fic = ['fic' num2str(num_fic)];
                        %switch les variables de place
                        tmp_var = app.export.(fic).var{tmp}; 
                        app.export.(fic).var{tmp} = app.export.(fic).var{tmp+1};
                        app.export.(fic).var{tmp+1} = tmp_var;
                        %switch les tables de place
                        tmp_table = app.export.(fic).table{tmp}; 
                        app.export.(fic).table{tmp} = app.export.(fic).table{tmp+1};
                        app.export.(fic).table{tmp+1} = tmp_table;
                        %switch les header de place 
                        tmp_header = app.export.(fic).header{tmp}; 
                        app.export.(fic).header{tmp} = app.export.(fic).header{tmp+1};
                        app.export.(fic).header{tmp+1} = tmp_header;
                    end
                    tmp_col = app.elbCol.Items{tmp + 1};
                    app.elbCol.Items{tmp + 1} = app.elbCol.Items{tmp};
                    app.elbCol.Items{tmp} = tmp_col;
                end
            end
        end

        % Button pushed function: ebDelete
        function ebDelete_ButtonPushed(app, event)
%             new_items = {}; new_struct = struct();
            
%des cas spécifique car on veut faire pas mal de chose
%             
%             if isempty(app.elbCol.Value)
% %                 for i = 1:numel(fieldnames(app.structCol))
% %                     if isfield(app.structCol, 'all')
% %                         app.structCol = rmfield(app.structCol, 'all');
% %                     else
% %                         col = ['col' num2str(i)];
% %                         if isfield(app.structCol,col)
% %                             app.structCol = rmfield(app.structCol, col);
% %                         end
% %                     end
% %                 end
% %                 app.elbCol.Items = {};
% %                 app.export.type = '';
% 
%             %
%             else
%                 if ~any(contains(app.elbCol.Items,'.'))
%                     for i = 1:length(app.elbCol.Items)
%                         col = ['col' num2str(i)];
%                         if ~strcmp(app.elbCol.Items{i}, app.elbCol.Value) % modif : {} par ()
%                             new_items{end+1} = app.elbCol.Items{i};
%                             new_col = ['col' num2str(length(new_items))];
%                             new_struct.(new_col) = app.structCol.(col); % bug
%                         end
%                     end
%                 else
%                     col = 'col1';
%                     for i = 1:length(app.elbCol.Items)
%                         if ~strcmp(app.elbCol.Items{i}, app.elbCol.Value) % modif : {} par ()
%                             new_items{end+1} = app.elbCol.Items{i};
%                             new_col = 'col1';
%                             new_struct.(new_col) = app.structCol.(col); % bug
%                         end
%                     end
%                 end
%                 if isfield(app.structCol, 'timecode')
%                     new_struct.timecode = app.structCol.timecode;
%                 end
%                 app.elbCol.Items = new_items;
%                 app.structCol = new_struct;
%                 
%                 if length(app.elbCol.Items) == 1 && isfield(app.structCol.col1, 'var')
%                     app.espnSousEchantillon.Enable = 'on'; app.le_sousEchantillon.Enable = 'on';
%                 elseif isempty(app.elbCol.Items)
%                     app.exportType = '';
%                 end

        if isempty(app.elbCol.Items)
            return;
        end
        % si on a pas choisit de fichier
        if isempty(app.elbFichier.Value)
            %si on es dans le cas normal
            if any(contains(app.elbCol.Items,'.'))
                %ce n'est pas un fichier complet dans l'export
                %donc il suffit de modifier les colonnes 
                new_cols = app.elbCol.Items;
                indice = find(~cellfun(@isempty, strfind(app.elbCol.Items, app.elbCol.Value)));
                new_cols(:,indice) = [];
                app.elbCol.Items = new_cols;
                if isempty(new_cols)
                    nb_fic = length(app.elbFichier.Items)+1;
                    fic =  ['fic' num2str(nb_fic)];
                    app.export = rmfield(app.export,fic);
                end
            %si c'est un fichier directement 
            else
                new_cols = app.elbCol.Items;
                indice = find(~cellfun(@isempty, strfind(app.elbCol.Items, app.elbCol.Value)));
                new_cols(:,indice) = [];
                app.elbCol.Items = new_cols;
                fic2delete = length(app.elbFichier.Items)+indice;
                ficdelete =  ['fic' num2str(fic2delete)];
                modif = false;
                %modifier les indices des fichiers
                for i=1:length(fieldnames(app.export))-1
                    nb_fic = i;
                    if ~modif
                        if nb_fic == fic2delete
                            app.export = rmfield(app.export,ficdelete);
                            modif = true;
                        end
                    else
                        fic = ['fic' num2str(nb_fic)];
                        tmp = app.export.(fic);
                        app.export = rmfield(app.export,fic);
                        new_fic = ['fic' num2str(nb_fic-1)];
                        app.export.(new_fic) = tmp;
                    end
                    
                end
            end
            
        % sinon si il y'a un fichier choisi
        elseif length(app.elbFichier.Value) == 1
            if any(contains(app.elbCol.Items,'.'))
                %c'est un fichier complet déjà, alors à modifier
                new_cols = app.elbCol.Items;
                indice = find(~cellfun(@isempty, strfind(app.elbCol.Items, app.elbCol.Value)));
                indicefic = find(~cellfun(@isempty, strfind(app.elbFichier.Items, app.elbFichier.Value)));
                ficdelete = ['fic' num2str(indicefic)];
                new_cols(:,indice) = [];
                app.elbCol.Items = new_cols;
                
                %modif des données dans le fichier
                app.export.(ficdelete).var(:,indice) = [];
                app.export.(ficdelete).table(:,indice) = [];
                app.export.(ficdelete).header(:,indice) = [];
                modif = false;
                %modifier les indices des fichiers
                for i=1:length(fieldnames(app.export))
                    nb_fic = i;
                    if ~modif
                        if nb_fic == indicefic
                             app.export = rmfield(app.export,ficdelete);
                             new_fics = app.elbFichier.Items;
                             new_fics(:,indicefic) = [];
                             app.elbFichier.Items = new_fics;
                             modif = true;
                        end
                    else
                        fic = ['fic' num2str(nb_fic)];
                        tmp = app.export.(fic);
                        app.export = rmfield(app.export,fic);
                        new_fic = ['fic' num2str(nb_fic-1)];
                        app.export.(new_fic) = tmp;
                    end
                end
            end
        end
        end

        % Button pushed function: ebPlus
        function ebPlus_ButtonPushed(app, event)
            % si on a aucune colonnes a entrer
            if isempty(app.elbCol.Items)
                return
            end
            
            %si dans la structure il n'y a pas encore de fichier
            if ~isempty(fieldnames(app.export))
                if isfield(app.export,'type')
                    typefield = app.export.type;
                else
                    typefield = app.exportType;
                end
                
                %verification que les type de données a exporter sont les mêmes
                if ~strcmp(app.elbType.Value , typefield)
                    text = strcat('Veuillez ne mettre que des'," ",typefield,'s.');
                    uialert(app.BINDGUIFigure, text, ' ', 'Icon', 'error');
                    return
                end
            end
            
            % données avec des variables
            if any(contains(app.elbCol.Items,'.'))
                %app.structCol.col1.var = {}; %{'timecode'};
                %app.structCol.col1.table = {}; %{''};
                
                %recherche du fichier a créer
                fichiers_create = fieldnames(app.export);
                nb_fic = length(fichiers_create)-1;
                fic = ['fic' num2str(nb_fic)];
                app.export.(fic).var = {};
                app.export.(fic).table = {};
                app.export.(fic).header = app.elbCol.Items;
                for i = 1:length(app.elbCol.Items)
                    posPoint = regexp(app.elbCol.Items{i}, '\.');
                    %app.structCol.col1.table{end+1} = app.elbCol.Items{i}(1:posPoint(1)-1);
                    %app.structCol.col1.var{end+1} = app.elbCol.Items{i}(posPoint(1)+1:end);
                    app.export.(fic).table{end+1} = app.elbCol.Items{i}(1:posPoint(1)-1);
                    app.export.(fic).var{end+1} = app.elbCol.Items{i}(posPoint(1)+1:end);
                end
                
                fichier = cell2mat(inputdlg({'Nom du fichier :'}, 'Nouveau fichier', 1));
                if ~isempty(fichier)
                    while any(contains(app.elbFichier.Items, fic))
                        fichier = cell2mat(inputdlg({'Nom du fichier :'}, 'Nouveau fichier', 1));
                        if any(contains(app.elbFichier.Items, fichier))
                            %TODO 'Ce nom est déjà pris. Voulez-vous le remplacer?'
                            uialert(app.BINDGUIFigure, 'Ce nom est déjà pris. Veuillez en choisir un autre.', ' ', 'Icon', 'error');
                        end
                    end
                % remplir la structure 
                    app.export.(fic).name = fichier;
                    app.elbFichier.Items{end+1} = app.export.(fic).name;
                end
            %tables completes 
            else
                %recupération du nb de fichier existant
                %fichiers_create = fieldnames(app.export);
                %nb_fic = length(fichiers_create)-2;
                nb_fic = length(app.elbFichier.Items);
                for i = 1:length(app.elbCol.Items)
                    fic = ['fic' num2str(nb_fic)+i];
                    %nom du fichier
                    app.export.(fic).name = app.elbCol.Items{i};
                    %récupération de toutes les variables de la table
                    app.export.(fic).var = getVars(app, app.trip, app.export.type, app.export.(fic).table{1}, '');
                    len = length(app.export.(fic).var);
                    table = app.export.(fic).table;
                    app.export.(fic).table(1,len) = table;
                    app.export.(fic).table(1,:) = table;
                    %mettre les données importantes
                    app.export.(fic).situation = app.elbSituation.Value;
                    app.export.(fic).subsample = app.espnSousEchantillon.Value;
                    app.export.(fic).header = buildHeader(app,app.export.(fic).table,app.export.(fic).var);
                    app.elbFichier.Items{end+1} = app.export.(fic).name;
                end
                
            end
            
            if ~isempty(fic)
                app.elbCol.Items = {}; ebDelete_ButtonPushed(app); app.elbSituation.Value = {};
            end
            
            if length(app.elbSituation.Value) > 1 && strcmp(app.ebExporterTous.Enable, 'on')
                app.ebExporterTous.Enable = 'off'; app.ConcatTrip.Enable = 'off';
            end
            app.elbFichier.Value = {};
            elbFichier_ValueChanged(app);
            if app.elbType.Value == "data"
                app.ConcatSituationEvents.Value = 0;
                app.ConcatSituationEvents.Enable = 'off';
            else
                app.ConcatSituationEvents.Enable = 'on';
            end
            
            valide = verification_variables(app);
            if ~valide
                app.ConcatSituationEvents.Value = 0;
                app.ConcatSituationEvents.Enable = 'off';
            else
                app.ConcatSituationEvents.Enable = 'on';
            end
        
        end

        % Button pushed function: ebSupprimer
        function ebSupprimer_ButtonPushed(app, event)
%             new_items = {}; new_struct = struct();
             fictmp = app.elbFichier.Value;
             if isempty(app.elbFichier.Value)
                 for i = 1:numel(fieldnames(app.export))-1
                     fic = ['fic' num2str(i)];
                     app.export = rmfield(app.export, fic);
                 end
                 app.export.type = '';
                 app.elbFichier.Items = {};
                 app.ebExporterTous.Enable = 'on'; app.ConcatTrip.Enable = 'on';
             else
                for j = 1:length(fictmp)
                    fic2delete = find(~cellfun(@isempty, strfind(app.elbFichier.Items, app.elbFichier.Value{1})));
                    fichiers = app.elbFichier.Items;
                    fichiers(:,fic2delete) = [];
                    app.elbFichier.Items = fichiers;
                    modif = false;
                    for i=1:length(fieldnames(app.export))-1
                        fic = ['fic' num2str(i)];
                        if ~modif
                            if i == fic2delete
                                app.export = rmfield(app.export,fic);
                                modif = true;
                            end
                        else    
                            tmp = app.export.(fic);
                            app.export = rmfield(app.export,fic);
                            new_fic = ['fic' num2str(i-1)];
                            app.export.(new_fic) = tmp;
                        end
                    end
                end

                if isempty(app.elbFichier.Items)
                    app.ebSupprimer.Enable = 'off';
                    app.ebRenommer.Enable = 'off';
                    app.ebApercu.Enable = 'off';
%                 else
%                     for i = 1:length(app.elbFichier.Items)
%                         fic = app.elbFichier.Items{i};
%                         if length(app.export.(fic).situation) == 1 && strcmp(app.ebExporterTous.Enable, 'off')
%                             app.ebExporterTous.Enable = 'on'; app.ConcatTrip.Enable = 'on';
%                             break
%                         end
%                     end
                end
            end
            app.elbCol.Items = {};
        end

        % Button pushed function: ebExporter
        function ebExporter_ButtonPushed(app, event)
            save_env_export(app);
            simple = fieldnames(app.struct_save.Export.simple);
            if ~isempty(simple)
                present = false;
                for n = 1:length(app.SeulNode.Children)
                    if strcmp(app.SeulNode.Children(n).Text,simple{end})
                        present = true;
                        indice = n;
                    end
                end
                if ~present
                    uitreenode('Parent',app.SeulNode,"Text",simple{end},'nodeData',app.struct_save.Export.simple.(simple{end}));
                else
                    noeud = app.SeulNode.Children(indice);
                    noeud.delete;
                    uitreenode('Parent',app.SeulNode,"Text",simple{end},'nodeData',app.struct_save.Export.simple.(simple{end}));
                end
            end
            
            % récupération de la date
            date_time = char(datetime('now','Format','yyMMdd_HHmm'));
            %récupération du chemin d'accès au trip
            trip_file = app.trip.getTripPath;
            [~, tripName, ~] = fileparts(trip_file);
            fileNames_list = fieldnames(app.export);
            if (~app.ConcatSituationEvents.Value)
                %iteration sur les fichier à créer
                for i_fic = 1:length(fileNames_list)
                    file_name = fileNames_list{i_fic};
                    struct = app.export.(fileNames_list{i_fic});
                    %  si on n'a pas selectionné de situation
                    if isempty(struct.situation)
                        namefait = false;
                        cols = {};
                        data = {};
                        tripNameRec = {};
                        for i = 1:numel(fieldnames(struct.struct))
                            col = ['col' num2str(i)];
                            for j = 1:length(struct.struct.(col).var)
                                cols = [cols, {[struct.struct.(col).table{:} '.' struct.struct.(col).var{j}]}];
                            end
                            for l = 1 :size(struct.struct.(col).data,1)
                                tripNameRec{l,1} = tripName;
                            end
                            if namefait
                                data = [data, struct.struct.(col).data];
                            else
                                data = [tripNameRec, data, struct.struct.(col).data];
                                namefait = true;
                            end
                        end
                        cols = ['TripName', cols];
                        alldata =  [cols; data];
                        null = false;
                        %verrification qu'on ai pas choisit de dossier pour
                        %l'export (si on obtient 0)
                        if app.exportFolder == 0
                            null = true;
                        end
                        %recuperation du chemin jusqu'au trip
                        getParentFolder = strfind(trip_file, "\");
                        getParentFolder = trip_file(1:getParentFolder(end));
                        %verrification si on a selctionner un fichier d'export
                        vide = strcmp(app.exportFolder, '');
                        if null || vide
                            filepathname = fullfile(getParentFolder,'~DATA_EXPORT\');
                        else
                            filepathname = strcat(app.exportFolder,'\');
                        end
                        mkdir(filepathname);
                        %                      disp(['exporting : ' regexprep(file_name, ' ', '_') ' for ' tripName])
                        %                      fr.lescot.bind.export.ExportTrip2Files.ExportTripTableWithStruct2File(trip_file, file_name, struct.type, ...
                        %                      cols, data, app.espnSousEchantillon.Value, app.eddFormat.Value, date_time, app.exportFolder)
                        
                        creeExport(app, alldata, file_name, filepathname);
                        %si on a choisit une situation PresentDans
                    else
                        disp('Export par situation')
                        exporterPresentDans(app,struct,trip_file,tripName,file_name);
                    end
                end
            else
                %concat
                %todo
                d = uiprogressdlg(app.BINDGUIFigure, 'Message', 'export', 'Indeterminate',"on");
                concatsimple(app,d);
            end
        end

        % Button pushed function: ebApercu
        function ebApercu_ButtonPushed(app, event)
            pos = app.BINDGUIFigure.Position;
            %struct = app.export.(cell2mat(app.elbFichier.Value));
            %type = app.export.(cell2mat(app.elbFichier.Value)).type;
            indice_fic = find(~cellfun(@isempty, strfind(app.elbFichier.Items, app.elbFichier.Value)));
            fic = ['fic' num2str(indice_fic)];
            struct = app.export.(fic);
            type = app.export.type;
            BIND_VISU(app.trip, '', type, pos(1), pos(2), struct);
        end

        % Button pushed function: ebRenommer
        function ebRenommer_ButtonPushed(app, event)
            answer = inputdlg({'Nom du fichier:'}, 'Renommer', 1, app.elbFichier.Value);
            if ~isempty(answer) && ~strcmp(answer{:}, app.elbFichier.Value)
                edit = app.export.(app.elbFichier.Value{:});
                ebSupprimer_ButtonPushed(app, event);
                app.elbFichier.Items{end+1} = cell2mat(answer);
                app.export.(cell2mat(answer)) = edit;
            end
        end

        % Button pushed function: ebExporterTous
        function ebExporterTous_ButtonPushed(app, event)
            
            %nomfic = fieldnames(app.export);
            %type = app.export.(nomfic{1}).type;
            d = uiprogressdlg(app.BINDGUIFigure, 'Message', 'export', 'Indeterminate',"on");
            new_exportation(app, d, true);
            close(d);
            %récupération de la date
            %             app.date_time = char(datetime('now','Format','yyMMdd_HHmm'));
            %             save_env_export_tous(app);
            %             tous = fieldnames(app.struct_save.Export.tous);
            %             if ~isempty(tous)
            %                 present = false;
            %                 for n = 1:length(app.TousNode.Children)
            %                     if strcmp(app.TousNode.Children(n).Text,tous{end})
            %                         present = true;
            %                         indice = n;
            %                     end
            %                 end
            %                 if ~present
            %                     uitreenode('Parent',app.TousNode,"Text",tous{end},'nodeData',app.struct_save.Export.tous.(tous{end}));
            %                 else
            %                     noeud = app.TousNode.Children(indice);
            %                     noeud.delete;
            %                     uitreenode('Parent',app.TousNode,'Text',tous{end},'nodeData',app.struct_save.Export.tous.(tous{end}));
            %                 end
            %             end
            % TODO : variable_names to the user
            % DONE : bouton "Destination" (dossier d'enregistrement des exports) ouvre uigetdir :
            % "Par défaut, les fichiers d'export sont enregistrés dans les dossiers respectifs des trips.
            % Si vous choisissez un emplacement, les fichiers seront regroupés dans le dossier sélectionné
            %             d = uiprogressdlg(app.BINDGUIFigure, 'Message', 'export', 'Indeterminate',"on");
            % si on veut concaténer par des events ou situations
            %             nomfic = fieldnames(app.export);
            %             type = app.export.(nomfic{1}).type;
            %             if (type == "situation" || type == "event")
            %                 if (~app.ConcatTrip.Value && ~app.ConcatSituationEvents.Value)
            % %                     d.Message = 'Export pour tous les trips';
            %                     exportTousNormal(app, d);
            %                 % si on veut concaténer par situation et par event/situation
            %                 elseif (app.ConcatTrip.Value && app.ConcatSituationEvents.Value)
            %                     d.Message = 'Export des trips concaténés';
            %                     exportConcatenation(app, d);
            %                 % si on veut concaténer par trip
            %                 elseif (app.ConcatTrip.Value && ~app.ConcatSituationEvents.Value)
            %                     d.Message = 'Export concaténé par trip';
            %                     concatTrips(app, d);
            %                 % si on veut concaténer par event/situation
            %                 elseif (~app.ConcatTrip.Value && app.ConcatSituationEvents.Value)
            %                 switch type
            %                     case 'situation'
            %                         text = 'Export des trips concaténé par situation';
            %                     case 'event'
            %                         text = 'Export des trips concaténé par événement';
            %                 end
            %                     d.Message = text;
            %                     concatEventSituation(app, d);
            %                 end
            %             % si on veut concaténer des data
            %             else
            %                 % si on ne veut pas concaténer par trip
            %                 if (~app.ConcatTrip.Value)
            %                     d.Message = 'Export de tous les trips';
            %                     exportTousNormal(app, d);
            %                 % si on veut concaténer par trip
            %                 else
            %                     d.Message = 'Export concaténé pour tous les trips';
            %                     concatTrips(app, d);
            %                 end
            %             end
            %            close(d);
        end

        % Button pushed function: ebAnnuler
        function ebAnnuler_ButtonPushed(app, event)
            app.elbTable.Items = {};
            app.elbVar.Items = {};
            app.elbType.Value = {};
            app.elbCol.Value = {}; ebDelete_ButtonPushed(app);
            app.elbSituation.Value = {};
            app.elbFichier.Value = {}; ebSupprimer_ButtonPushed(app);
            app.ebSupprimer.Enable = 'off'; app.ebRenommer.Enable = 'off'; app.ebApercu.Enable = 'off';
            app.espnSousEchantillon.Enable = 'off'; app.le_sousEchantillon.Enable = 'off';
            app.ebExporterTous.Enable = 'on'; app.ConcatTrip.Enable = 'on';
            app.panelExport.Visible = 'off';
        end

        % Value changed function: elbFichier
        function elbFichier_ValueChanged(app, event)
            if ~isempty(app.elbFichier.Value)
                if length(app.elbFichier.Value) > 1
                    app.ebRenommer.Enable = 'off';
                    app.ebApercu.Enable = 'off';
                else
                    app.ebRenommer.Enable = 'on';
                    app.ebApercu.Enable = 'on';
                    
                     app.elbCol.Items = {};
                     fic_selected = app.elbFichier.Value;
                     nb_fic = length(fic_selected);
                     if nb_fic == 1
                         %remplir avec les infos
                         for i=1:length(fieldnames(app.export))-1
                            fic = ['fic' num2str(i)];
                            if strcmp(fic_selected,app.export.(fic).name)
                                app.elbCol.Items = app.export.(fic).header; 
                            end
                         end
                         
                     end
                     
%                     fic_name = cell2mat(app.elbFichier.Value);
%                     cols = fieldnames(app.export.(fic_name).struct);
%                     for i = 1:numel(cols)
%                         col = cols{i};
%                         %app.elbCol.Items{i} = cell2mat(app.export.(fic_name).struct.(col).table);
%                         name = cell2mat(app.export.(fic_name).struct.(col).table);
%                         if isfield(app.export.(fic_name).struct.(col), 'var')
%                             for j = 1:length(app.export.(fic_name).struct.(col).var)
%                                 %app.elbCol.Items{end+1} = cell2mat([app.elbCol.Items{i} '.' app.export.(fic_name).struct.(col).var(j)]);
%                                 app.elbCol.Items{end+1} = cell2mat([name '.' app.export.(fic_name).struct.(col).var(j)]);
%                             end
%                         end
%                     end
%                     for i = 1:length(app.export.(fic_name).situation)
%                         s = startsWith(app.elbSituation.Items, app.export.(fic_name).situation{i});
%                         app.elbSituation.Value{i} = app.elbSituation.Items{s};
%                     end
%                     app.structCol = app.export.(fic_name).struct;
                 end
                app.ebSupprimer.Enable = 'on';
            else
                app.elbCol.Items = {};
                app.ebSupprimer.Enable = 'off';
                app.ebRenommer.Enable = 'off';
                app.ebApercu.Enable = 'off';
            end
        end

        % Button pushed function: ebDestination
        function ebDestination_ButtonPushed(app, event)
            app.exportFolder = uigetdir;
        end

        % Value changing function: tefNom
        function tefNomValueChanging(app, event)
            changingValue = event.Value;
            if ~strcmp(changingValue,'')
                app.tbCreer.Enable = 'on';
                app.tcbAppliquerTrip.Enable = 'on';
            else
                app.tbCreer.Enable = 'off';
                app.tcbAppliquerTrip.Enable = 'off';
            end
            %             showbCreer(app);
        end

        % Button pushed function: FiltrageEventsSituationsButton
        function FiltrageEventsSituationsButtonPushed(app, event)
            app.PanelFiltrage.Visible = 'on';
            %selectedButton = app.ButtonGroupFiltrage.SelectedObject;
            if isempty(app.listeFiltrage1.Items)
                for i = 1:length(app.EventsNode.Children)
                    app.listeFiltrage1.Items{i} = app.EventsNode.Children(i).Text;
                    app.structFiltrageEvent.(app.listeFiltrage1.Items{i}).data = getTC_Filtrage(app, app.trip, app.ButtonGroupFiltrage.SelectedObject.Text, app.EventsNode.Children(i));
                    %app.listeFiltrage1.ItemsData{i} = getTC_Filtrage(app, app.trip, app.ButtonGroupFiltrage.SelectedObject.Text, app.EventsNode.Children(i));
                    app.listeFiltrage2.Items{i} = app.EventsNode.Children(i).Text;
                    app.structFiltrageEvent.(app.listeFiltrage2.Items{i}).data = getTC_Filtrage(app, app.trip, app.ButtonGroupFiltrage.SelectedObject.Text, app.EventsNode.Children(i));
                    %app.listeFiltrage2.ItemsData{i} = getTC_Filtrage(app, app.trip, app.ButtonGroupFiltrage.SelectedObject.Text, app.EventsNode.Children(i));
                end
            end
            app.nbpris1.Value = '0';
            app.nbpris2.Value = '0';
            if ~isempty(app.tddRef) && app.ttbEvent.Value
                app.EventsFiltrageButton.Value = 1;
                showFiltragePanel(app,app.EventsFiltrageButton);
            elseif ~isempty(app.tddRef) && app.ttbSituation.Value
                app.SituationsFiltrageButton.Value = 1;
                showFiltragePanel(app,app.SituationsFiltrageButton);
            else
                app.EventsFiltrageButton.Value = 1;
                showFiltragePanel(app,app.EventsFiltrageButton);
            end
            
        end

        % Selection changed function: ButtonGroupFiltrage
        function ButtonGroupFiltrageSelectionChanged(app, event)
            selectedButton = app.ButtonGroupFiltrage.SelectedObject;
            showFiltragePanel(app,selectedButton);
        end

        % Button pushed function: AppliquerButton
        function AppliquerButtonPushed(app, event)
            app.PanelFiltrage.Visible = 'off';
        end

        % Value changed function: listeFiltrage1, listeFiltrage2
        function listeFiltrageValueChanged(app, event)
            selectedButton = app.ButtonGroupFiltrage.SelectedObject;
            trip_toload = app.trip;
            meta = trip_toload.getMetaInformations;
            if app.EventsFiltrageButton.Value
                if strcmp(app.listeFiltrage1.Value ,'')
                    app.struct_select_liste.liste1 = app.listeFiltrage1.Value;
                end
                if strcmp(app.listeFiltrage2.Value ,'')
                    app.struct_select_liste.liste2 = app.listeFiltrage2.Value;
                end
                if strcmp(app.listeFiltrage1.Value ,app.listeFiltrage2.Value)
                    app.listeFiltrage1.Value = app.struct_select_liste.liste1;
                    app.listeFiltrage2.Value = app.struct_select_liste.liste2;
                else
                    app.struct_select_liste.liste1 = app.listeFiltrage1.Value;
                    app.struct_select_liste.liste2 = app.listeFiltrage2.Value ;
                end
            end
            switch selectedButton
                case app.EventsFiltrageButton % cas où on a cliquer sur boutton Events
                    % vide les deux tableaux
                    data1 = {};
                    data2 = {};
                    % Récupère les données selectionnées dans les listes
                    name_Item1 = app.listeFiltrage1.Value;
                    name_Item2 = app.listeFiltrage2.Value;
                    % Remplissage des deux tableaux
                    if ~isempty(name_Item1)
                        value1 = app.structFiltrageEvent.(name_Item1).data;
                        if ~isfield(app.structFiltrageEvent.(name_Item1), 'filtrage')
                            app.structFiltrageEvent.(name_Item1).validate = length(value1);
                            for i = 1:length(value1)
                                app.structFiltrageEvent.(name_Item1).filtrage(i,1) = true;
                            end
                        end
                        for i = 1:length(value1)
                            data1{i,1} = app.structFiltrageEvent.(name_Item1).filtrage(i,1);
                            data1{i,2} = value1{i,1};
                            data1{i,3} = value1{i,2};
                        end
                        name1 = meta.getEventVariablesNamesList(app.listeFiltrage1.Value);
                        app.tableFiltrage1.ColumnName = {'Ignore';'Timecode';name1{2}};
                        app.nbpris1.Value = num2str(app.structFiltrageEvent.(name_Item1).validate);
                    end
                    if ~isempty(name_Item2)
                        value2 = app.structFiltrageEvent.(name_Item2).data;
                        if ~isfield(app.structFiltrageEvent.(name_Item2), 'filtrage')
                            app.structFiltrageEvent.(name_Item2).validate = length(value2);
                            for i = 1:length(value2)
                                app.structFiltrageEvent.(name_Item2).filtrage(i,1) = true;
                            end
                        end
                        value2 = app.structFiltrageEvent.(name_Item2).data;
                        for i = 1:length(value2)
                            data2{i,1} = app.structFiltrageEvent.(name_Item2).filtrage(i,1);
                            data2{i,2} = value2{i,1};
                            data2{i,3} = value2{i,2};
                        end
                        name2 = meta.getEventVariablesNamesList(app.listeFiltrage2.Value);
                        app.tableFiltrage2.ColumnName = {'Ignore';'Timecode';name2{2}};
                        app.nbpris2.Value =  num2str(app.structFiltrageEvent.(name_Item2).validate);
                    end
                    %                     index = find(contains(app.listeFiltrage1.ItemsData{(1)},value1));
                    set(app.tableFiltrage1, 'data', data1);
                    set(app.tableFiltrage2, 'data', data2);
                    
                case app.SituationsFiltrageButton %  cas où on a cliquer sur boutton Situations
                    % vide les deux tableaux
                    data1 = {};
                    data2 = {};
                    
                    % Récupère les données selectionnées dans la 1er liste
                    
                    name_Item1 = app.listeFiltrage1.Value;
                    if ~isempty(name_Item1)
                        value1 = app.structFiltrageSituation.(name_Item1).data;
                        size_value1 = size(value1);
                        app.structFiltrageSituation.(name_Item1).validate = size_value1(1);
                        if ~isfield(app.structFiltrageSituation.(name_Item1), 'filtrage')
                            for i = 1:size_value1
                                app.structFiltrageSituation.(name_Item1).filtrage(i,1) = true;
                            end
                        end
                        
                        %Remplissage du 1er tableaux
                        for i = 1:size_value1(1)
                            data1{i,1} = app.structFiltrageSituation.(name_Item1).filtrage(i,1);
                            data1{i,2} = value1{i,1};
                            data1{i,3} = value1{i,2};
                            data1{i,4} = value1{i,3};
                            
                        end
                        app.nbpris1.Value =  num2str(app.structFiltrageSituation.(name_Item1).validate);
                    end
                    set(app.tableFiltrage1, 'data', data1);
                    set(app.tableFiltrage2, 'data', data2);
            end
        end

        % Value changing function: cefNom
        function cefNomValueChanging(app, event)
            changingValue = event.Value;
            if isempty(changingValue)
                app.cbCreer.Enable = 'off';
                app.ccbAppliquerTrip.Enable = 'off';
                app.ccbAppliquerTrip.Value = 0;
            else
                app.cbCreer.Enable = 'on';
                app.ccbAppliquerTrip.Enable = 'on';
            end
            
        end

        % Value changing function: cefText
        function cefTextValueChanging(app, event)
            changingValue = event.Value;
            if isempty(changingValue)
                app.cbPlus.Enable = 'off';
            else
                app.cbPlus.Enable = 'on';
            end
        end

        % Value changed function: clbVar
        function clbVarValueChanged(app, event)
            value = app.clbVar.Value;
            % Cas où on sélections un seul de la liste.
            if numel(value) == 1
                if ~strcmp(app.connecteurs.Value, '')
                    if length(app.connecteurs.Value) < 8
                        conditions = '';
                        for i = 1:length(app.clbVar.Items)
                            if strcmp(value(1), app.clbVar.Items(i))
                                indice = num2str(i);
                                conditions = strcat(conditions,'C', indice);
                            end
                        end
                        app.connecteurs.Value = conditions;
                        app.ETButton.Enable = 'off';
                        app.OUButton.Enable = 'off';
                    end
                    app.connecteurExemple.Visible = 'on';
                    app.connecteurs.Visible = 'on';
                    app.ConnecteurLabel.Visible = 'on';
                else % Désactive les bouttons OU et ET si champs connecteurs vide
                    app.ETButton.Enable = 'off';
                    app.OUButton.Enable = 'off';
                    conditions = '';
                    for i = 1:length(app.clbVar.Items)
                        if strcmp(value(1), app.clbVar.Items(i))
                            indice = num2str(i);
                            conditions = strcat('C', indice);
                        end
                    end
                    app.connecteurs.Value = conditions;
                end
                % Cas où on sélectionne pas.
            elseif numel(value) == 0
                app.ETButton.Enable = 'off';
                app.OUButton.Enable = 'off';
                % Cas où on sélectionne plusieurs
            else
                if length(app.connecteurs.Value) < 8
                    conditions = '';
                    app.connecteurs.Value = conditions;
                end
                app.connecteurExemple.Visible = 'on';
                app.ConnecteurLabel.Visible = 'on';
                app.connecteurExemple.Visible = 'on';
                app.connecteurs.Visible = 'on';
                app.ETButton.Enable = 'on';
                app.OUButton.Enable = 'on';
            end
        end

        % Button pushed function: ETButton
        function ET_ButtonPushed(app, event)
            value = app.clbVar.Value;
            
            conditions = app.connecteurs.Value;
            if numel(value) == 2 && strcmp(conditions, '')
                conditions = strcat(conditions, fusion_connecteur(app,'ET',value));
            elseif numel(value) >= 2 && ~strcmp(conditions, '')
                conditions = strcat('ET (', conditions, ',' , fusion_connecteur(app,'ET',value), ' )');
            elseif numel(value) >= 2 && strcmp(conditions, '')
                conditions = strcat('ET (', conditions , fusion_connecteur(app,'ET',value), ' )');
            elseif numel(value) == 1
                conditions = strcat(' ,', conditions);
                conditions = fusion_connecteur(app,'ET',value);
            end
            app.connecteurs.Value = conditions;
        end

        % Button pushed function: OUButton
        function OUButtonPushed(app, event)
            value = app.clbVar.Value;
            
            conditions = app.connecteurs.Value;
            if numel(value) == 2 && strcmp(conditions, '')
                conditions = strcat(conditions, fusion_connecteur(app,'OU',value));
            elseif numel(value) >= 2 && ~strcmp(conditions, '')
                conditions = strcat('OU (', conditions, ',' , fusion_connecteur(app,'OU',value), ' )');
            elseif numel(value) >= 2 && strcmp(conditions, '')
                conditions = strcat('OU (', conditions , fusion_connecteur(app,'OU',value), ' )');
            elseif numel(value) == 1
                conditions = strcat(' ,', conditions);
                conditions = fusion_connecteur(app,'OU',value);
            end
            app.connecteurs.Value = conditions;
        end

        % Cell edit callback: tableFiltrage1
        function ignore_Value_change_table1(app, event)
            indices = event.Indices(1);
            newData = event.NewData;
            if app.EventsFiltrageButton.Value
                app.structFiltrageEvent.(app.listeFiltrage1.Value).filtrage(indices,1) = newData;
                if newData == 1
                    app.structFiltrageEvent.(app.listeFiltrage1.Value).validate = app.structFiltrageEvent.(app.listeFiltrage1.Value).validate + 1;
                else
                    app.structFiltrageEvent.(app.listeFiltrage1.Value).validate = app.structFiltrageEvent.(app.listeFiltrage1.Value).validate - 1;
                end
                app.nbpris1.Value = num2str(app.structFiltrageEvent.(app.listeFiltrage1.Value).validate);
            end
            if app.SituationsFiltrageButton.Value
                app.structFiltrageSituation.(app.listeFiltrage1.Value).filtrage(indices,1) = newData;
                if newData == 1
                    app.structFiltrageSituation.(app.listeFiltrage1.Value).validate = app.structFiltrageSituation.(app.listeFiltrage1.Value).validate + 1;
                else
                    app.structFiltrageSituation.(app.listeFiltrage1.Value).validate = app.structFiltrageSituation.(app.listeFiltrage1.Value).validate - 1;
                end
                app.nbpris1.Editable = 'off';
                app.nbpris1.Value = num2str(app.structFiltrageSituation.(app.listeFiltrage1.Value).validate);
                
            end
            
            
        end

        % Cell edit callback: tableFiltrage2
        function ignore_Value_change_table2(app, event)
            indices = event.Indices(1);
            newData = event.NewData;
            if app.EventsFiltrageButton.Value
                app.structFiltrageEvent.(app.listeFiltrage2.Value).filtrage(indices,1) = newData;
                if newData == 1
                    app.structFiltrageEvent.(app.listeFiltrage2.Value).validate = app.structFiltrageEvent.(app.listeFiltrage2.Value).validate + 1;
                else
                    app.structFiltrageEvent.(app.listeFiltrage2.Value).validate = app.structFiltrageEvent.(app.listeFiltrage2.Value).validate - 1;
                end
                app.nbpris2.Value = num2str(app.structFiltrageEvent.(app.listeFiltrage2.Value).validate);
            end
            
            
        end

        % Button pushed function: cochertousButton
        function cochertousButtonPushed(app, event)
            new = app.tableFiltrage1.Data;
            for i = 1:length(app.tableFiltrage1.Data)
                newvaleur(i,1) = true;
            end
            new(:,1) = {true};
            app.tableFiltrage1.Data = new;
            if app.EventsFiltrageButton.Value
                app.structFiltrageEvent.(app.listeFiltrage1.Value).filtrage = newvaleur;
            else
                app.structFiltrageSituation.(app.listeFiltrage1.Value).filtrage = newvaleur;
            end
            app.nbpris1.Value = num2str(length(app.tableFiltrage1.Data));
        end

        % Button pushed function: dcochertousButton
        function dcochertousButtonPushed(app, event)
            new = app.tableFiltrage1.Data;
            for i = 1:length(app.tableFiltrage1.Data)
                newvaleur(i,1) = false;
            end
            new(:,1) = {false};
            app.tableFiltrage1.Data = new;
            if app.EventsFiltrageButton.Value
                app.structFiltrageEvent.(app.listeFiltrage1.Value).filtrage = newvaleur;
            else
                app.structFiltrageSituation.(app.listeFiltrage1.Value).filtrage = newvaleur;
            end
            app.nbpris1.Value = '0';
        end

        % Button pushed function: cochertousButton_2
        function cochertousButton_2Pushed(app, event)
            new = app.tableFiltrage2.Data;
            for i = 1:length(app.tableFiltrage2.Data)
                newvaleur(i,1) = true;
            end
            new(:,1) = {true};
            app.tableFiltrage2.Data = new;
            if app.EventsFiltrageButton.Value
                app.structFiltrageEvent.(app.listeFiltrage2.Value).filtrage = newvaleur;
            else
                app.structFiltrageSituation.(app.listeFiltrage2.Value).filtrage = newvaleur;
            end
            app.nbpris2.Value = num2str(length(app.tableFiltrage2.Data));
        end

        % Button pushed function: dcochertousButton_2
        function dcochertousButton_2Pushed(app, event)
            new = app.tableFiltrage2.Data;
            for i = 1:length(app.tableFiltrage2.Data)
                newvaleur(i,1) = false;
            end
            new(:,1) = {false};
            app.tableFiltrage2.Data = new;
            if app.EventsFiltrageButton.Value
                app.structFiltrageEvent.(app.listeFiltrage2.Value).filtrage = newvaleur;
            else
                app.structFiltrageSituation.(app.listeFiltrage2.Value).filtrage = newvaleur;
            end
            app.nbpris2.Value = '0';
        end

        % Menu selected function: SaveMenu
        function SaveMenuSelected(app, event)
            
            %%%%%%%%%%%%% Anaïs%%%%%%%%%%%%%%
            
            app.env
            
            nbPluginOpen=length(app.env.figureOpen.nomPlugin)
            for i=1:1:nbPluginOpen
                if  ~isempty(app.env.figureOpen.nomPlugin(i).figure) %isprop(app, 'env.figureOpen.nomPlugin(i).figure') &&
                    %recuperer la position
                    position=app.env.figureOpen.nomPlugin(i).figure.getFigureHandler.Position
                    app.env.pluginConfig.nomP(i).positionCoord=position;
                    i
                    %fermer la figure
                    try
                        app.env.figureOpen.nomPlugin(i).figure.closeCallback
                    catch ME
                        msg="Pas de closeCallback"
                    
                        try
                            app.env.figureOpen.nomPlugin(i).figure.closeWindow
                        catch ME
                            msg="Pas de closeWindow non plus"
                        end
                    end    
              
                
                end
            end
            
            app.env
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % sauvegarde des données liées aux trips
            app.struct_save.ScnList = app.structScnList;
            app.struct_save.ScnSelect = app.structScnSelect;
            app.struct_save.existTripRef = app.existTripRef;
            app.struct_save.tripRef = app.tripRef;
            app.struct_save.TripRefNode = app.TripRefNode;
            app.struct_save.selectedTripPaths = app.selectedTripPaths;
            app.struct_save.lastMainPath = app.lastMainPath;
            %             lesfields = fieldnames(app.struct_save.ScnSelect);
            %             if ~isempty(app.tripRef)
            %                 for i = 1:length(lesfields)
            %                     onvavoir = fieldnames(app.struct_save.ScnSelect.(lesfields{i}));
            %                     for j = 1:length(onvavoir)
            %                         if contains(onvavoir{j},app.struct_save.tripRef.Text(7:end))
            %                             newnode = uitree();
            %                             app.struct_save.ScnSelect{i} = app.struct_save.tripRef.Text(7:end);
            %                         end
            %                     end
            %                 end
            %             end
            %attention = false;
            
            %%%%%%%%%%%%%%%%% Anaïs %%%%%%%%%%%%%%%%%%%%%%
            appEnvToSave=app.env;
            % choix du nom de fichier
            [FileName,PathName,~] = uiputfile('*.env','Sauvegarde du fichier environment');
            app.envFile = fullfile(PathName,FileName);
            save(app.envFile,'appEnvToSave');
 %{       
 AVANT:
            struct_to_save = app.struct_save;
            % choix du nom de fichier
            [FileName,PathName,~] = uiputfile('*.env','Sauvegarde du fichier environment');
            app.envFile = fullfile(PathName,FileName);
            save(app.envFile,'struct_to_save');
 %}
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%


            %             fic = cell2mat(inputdlg({'Nom du fichier :'}, 'Nouveau fichier', 1));
            %             if isempty(fic) && isempty(app.nomficsave)
            %                 date_time = char(datetime('now','Format','yyMMdd_HHmm'));
            %                 namefic = strcat("save\",date_time,".env");
            %             elseif isempty(fic) && ~isempty(app.nomficsave)
            %                 namefic = app.nomficsave;
            %             else
            %                 namefic = strcat("save\",fic,".env");
            %             end
            %             filenames = ls('save');
            %             taille = size(filenames,1);
            %             if taille>2
            %                 for i = 3:taille
            %                     newfilenames(i-2) = convertCharsToStrings(filenames(i,:));
            %                     nb = regexp(newfilenames(i-2),".env");
            %                     nom = newfilenames(i-2);
            %                     nom = convertStringsToChars(nom);
            %                     nom = nom(1:nb+3);
            %                     nom = convertCharsToStrings(nom);
            %                     %nom = nom(1:nb+3);
            %                     newfilenames(i-2) = nom;
            %                 end
            %                 filenames = newfilenames;
            %                 fichname = strcat(fic,'.env');
            %                 while any(strcmpi(filenames,fichname))
            %                     %question
            %                     response = uiconfirm(app.BINDGUIFigure,'ce fichier existe déjà ajouter les données?','confirmation');
            %                     if strcmp(response,'Cancel')
            %                         fic = cell2mat(inputdlg({'Nom du fichier :'}, 'Nouveau fichier', 1));
            %                         if isempty(fic) && isempty(app.nomficsave)
            %                             date_time = char(datetime('now','Format','yyMMdd_HHmm'));
            %                             namefic = strcat("save\",date_time,".env");
            %                         elseif isempty(fic) && ~isempty(app.nomficsave)
            %                             namefic = app.nomficsave;
            %                         else
            %                             namefic = strcat("save\",fic,".env");
            %                         end
            %                         fichname = strcat(fic,'.env');
            %                     else
            %                         attention = true;
            %                         break
            %                     end
            %                 end
            %             end
            % %             while contains(filenames,fic)
            % %                 fic = cell2mat(inputdlg({'Autre Nom du fichier :'}, 'Nouveau fichier', 1));
            % %             end
            % %             if attention
            % %                 cheminfic = strcat('save/',fichname);
            % %                 env = importdata(cheminfic);
            % %                 if isfield(env,'segmentation')
            % %                     if isfield(env.segmentation,'temporelle')
            % %                         if isfield(env.segmentation.temporelle,'simple')
            % %                             envFields = fieldnames(env.segmentation.temporelle.simple);
            % %                             if ~isempty(envFields)
            % %                                 for i =1:length(envFields)
            % %                                     struct_to_save.segmentation.temporelle.simple.(envFields{i}) = env.segmentation.temporelle.simple.(envFields{i});
            % %                                 end
            % %                             end
            % %                         end
            % %                     end
            % %                 end
            % %
            % %                 if isfield(env,'segmentation')
            % %                     if isfield(env.segmentation,'temporelle')
            % %                         if isfield(env.segmentation.temporelle,'double')
            % %                             envFields = fieldnames(env.segmentation.temporelle.double);
            % %                             if ~isempty(envFields)
            % %                                 for i =1:length(envFields)
            % %                                     struct_to_save.segmentation.temporelle.double.(envFields{i}) = env.segmentation.temporelle.double.(envFields{i});
            % %                                 end
            % %                             end
            % %                         end
            % %                     end
            % %                 end
            % %
            % %                 if isfield(env,'segmentation')
            % %                     if isfield(env.segmentation,'conditionnelle')
            % %                         envFields = fieldnames(env.segmentation.conditionnelle);
            % %                         if ~isempty(envFields)
            % %                             for i =1:length(envFields)
            % %                                 struct_to_save.segmentation.conditionnelle.(envFields{i}) = env.segmentation.conditionnelle.(envFields{i});
            % %                             end
            % %                         end
            % %                     end
            % %                 end
            % %             end
            %             if ~exist(namefic, dir)
            %                 mkdir(namefic);
            %             end
            %             save(namefic,'struct_to_save');
            %             app.nomficsave = namefic;
            
        end

        % Menu selected function: EnvironnementcompletMenu
        function EnvironnementcompletMenuSelected(app, event)
            % choix du fichier a load
            [FileName,PathName] = uigetfile('*.env','Sélectionnez le fichier environnement à charger', app.lastEnvPath);
            if ischar(FileName)
                load_all(app,FileName,PathName);
                app.lastEnvPath = PathName;
                app.Tree.Enable = 'on';
                % Mise a jour pour les trips
                %                 if ~isempty(app.tripRef)
                %                     refreshTripsNode(app);
                %                     % app.bSegmentation.Enable = 'on';
                %                     % app.bTraitement.Enable = 'on';
                %                 end
            end
        end

        % Menu selected function: TripsMenu
        function TripsMenuSelected(app, event)
            % choix du fichier a load
            [FileName,PathName] = uigetfile('*.env','Sélectionnez le fichier environnement à charger', app.lastEnvPath);
            if ischar(FileName)
                load_chargement(app,FileName,PathName);
                app.lastEnvPath = PathName;
                app.Tree.Enable = 'on';
                %                 if ~isempty(app.tripRef)
                %                     refreshTripsNode(app);
                %                     % app.bSegmentation.Enable = 'on';
                %                     % app.bTraitement.Enable = 'on';
                %                 end
            end
        end

        % Menu selected function: SegmentationMenu
        function SegmentationMenuSelected(app, event)
            [FileName,PathName] = uigetfile('*.env','Sélectionnez le fichier environnement à charger', app.lastEnvPath);
            if ischar(FileName)
                load_segmentation(app,FileName,PathName);
                app.lastEnvPath = PathName;
            end
        end

        % Menu selected function: ExportMenu
        function ExportMenuSelected(app, event)
            [FileName,PathName] = uigetfile('*.env','SSélectionnez le fichier environnement à charger', app.lastEnvPath);
            if ischar(FileName)
                load_export(app,FileName,PathName);
                app.lastEnvPath = PathName;
               
            end

        end

        % Button pushed function: VoirPreset
        function VoirPresetButtonPushed(app, event)
    %%%% Import le preset sur lequel on a cliqué %%%%%%%%%
            %capter nom preset et obtenir son chemin
            FileName= app.presetValueClick
            PathName= app.presetPathClick
            toEnv=importdata(fullfile(PathName, FileName));
            
            app.env
            %régénération d'une structure:
            app.env.pluginConfig.nomP=toEnv.pluginConfig.nomP;
            
    %%%% Ouvre le contenu des plugins et les affiche dans le panel "plugins" %%%%%%%%%        
            %tests pour voir les noms des plugins:
            lenPluginsEnregistres=length(app.env.pluginConfig.nomP);
            %appelle fonction màj tous les noms 
            lbPlugins_ValueChanged(app, event)
            
     %%%% Ouvre toutes les fenetres des plugins %%%%%%%%%         
            for i=1:1:length(app.env.pluginConfig.nomP)
    
                stringNom=app.env.pluginConfig.nomP(i).nomPlugin
                sousStringNom=stringNom(1:end-1)
      %{
            app.env.pluginConfig.nomP(i).positionCoord(1) app.env.pluginConfig.nomP(i).positionCoord(2)] car la position est en 2 points (x,y)
      %}
                switch sousStringNom

                   case  "Afficher valeurs instantannées" %Nom contient « Afficher valeurs instantannées »:
                         msg="OK"
                        fr.lescot.bind.plugins.ValueDisplay(app.trip, app.env.pluginConfig.nomP(i).dataIdentifiers ,[app.env.pluginConfig.nomP(i).positionCoord(1) app.env.pluginConfig.nomP(i).positionCoord(2)]);
                        
                   case  "Tracé GPS"
                        fr.lescot.bind.plugins.GpsViewer(app.trip, [app.env.pluginConfig.nomP(i).positionCoord(1) app.env.pluginConfig.nomP(i).positionCoord(2)], app.env.pluginConfig.nomP(i).MapType, app.env.pluginConfig.nomP(i).gpsSource)
                   
                    case "Afficher dernier message"
                        fr.lescot.bind.plugins.MessageDisplay(app.trip, 'north' );
                   
                    case "Plotter2D" %%problème position et parser
                		fr.lescot.bind.plugins.DataPlotter(app.trip, app.env.pluginConfig.nomP(i).dataIdentifiers,[app.env.pluginConfig.nomP(i).positionCoord(1) app.env.pluginConfig.nomP(i).positionCoord(2)], app.env.pluginConfig.nomP(i).timeWindow);            
                       
                   case "EventSituationBrowser"
                       fr.lescot.bind.plugins.EventSituationBrowser(app.trip, app.env.pluginConfig.nomP(i).selectedIdentifier );
                       
                   case  "EventSituationDisplay"
                       fr.lescot.bind.plugins.EventSituationDisplay(app.trip, app.env.pluginConfig.nomP(i).situationIdentifiers, [app.env.pluginConfig.nomP(i).positionCoord(1) app.env.pluginConfig.nomP(i).positionCoord(2)], app.env.pluginConfig.nomP(i).xMax);
                   
                   case "VideoPlayer"
                       fr.lescot.bind.plugins.VideoPlayer_test(app.trip, [app.env.pluginConfig.nomP(i).positionCoord(1) app.env.pluginConfig.nomP(i).positionCoord(2)], app.env.pluginConfig.nomP(i).videoDes, app.env.pluginConfig.nomP(i).audioDes, app.env.pluginConfig.nomP(i).decimation);
                   
                
                    otherwise
                      %reste des plugins à mettre  
                end
                
            end
          
               
        end

        % Button pushed function: EnregistrerenpresetButton
        function EnregistrerenpresetButtonPushed(app, event)

            %{
            fig = uifigure; 
            fig.set('Name', 'Choisir un nom au preset');
            fig.set('Position', [700 350 430 200]);
            uicontrol('Style', 'pushbutton','Parent',fig, 'String', 'Enregistrer avec ce nom', 'Position', [120 30 250 50],'Callback', @closereq );
            %btn = uibutton('Parent',fig,'Text', 'Enregistrer avec ce nom', 'Position', [120 30 250 50],'callback', @CloseRequestFcn );
            texte=uieditfield('Parent', fig, 'position', [20 120 340 40]);
    
            presetName=texte.Value();
            %}
            
            %choisir un nom au preset dans une fenetre matlab
            presetName=app.EditField.Value()
            
            %sauvegrader ce preset dans la structure
            app.env.configPreset=struct();
            app.env.configPreset.NomPreset=presetName;
            app.env.configPreset.NomPreset=struct(); 
            app.env.configPreset.NomPreset.pluginConfig=app.env.pluginConfig;

            %mettre à jour le nom dans le panel
            MaJLbPreset(app,presetName)

        end

        % Value changed function: ListBox
        function ListBoxValueChanged(app, event)
            app.ListBox.Value
            value = app.ListBox.Value;
          
            if app.existTripRef
                %import fr.lescot.bind.loading.Analyzer;
                meta = app.trip.getMetaInformations();

                if ~isempty(value)
                    %%ajout pour la structure:
                    
                    switch value
                        case 'Magneto'
                            lanceMagneto(app)
                            %{
                            %positionner le nom:
                            app.env.pluginConfig.nbMagneto=app.env.pluginConfig.nbMagneto+1;
                            app.env.pluginConfig.nbPlugin=app.env.pluginConfig.nbPlugin+1;
                            indiceP=app.env.pluginConfig.nbPlugin;
                            app.env.pluginConfig
                            if indiceP==2
                                app.env.pluginConfig.nomP(indiceP-1)=struct();
                            end
                            app.env.pluginConfig.nomP(indiceP-1).nomPlugin=strcat('Magneto',num2str(app.env.pluginConfig.nbMagneto));
                          
                            msg="Pas de position sauvegardée pour magneto"
                                
                           MagnetoProp=fr.lescot.bind.plugins.Magneto_simplif(app.trip, 'northwest',strcat('Magneto',num2str(app.env.pluginConfig.nbMagneto)));
                            
                           %ajoute dans la liste 'plugins'
                           ajouteAPlugins(app, strcat('Magneto',num2str(app.env.pluginConfig.nbMagneto)));
                           
                           %ajouter la nouvelle position (positionCoord)
                           app.env.pluginConfig.nomP(indiceP-1).positionCoord=MagnetoProp.newPosition;
                           
                            %voir la structure
                           app.env

                            %}
                            
                        case 'VideoPlayer'
                             %positionner le nom:
     
                            app.env.pluginConfig.nbVideoPlayer=app.env.pluginConfig.nbVideoPlayer+1;
                            app.env.pluginConfig.nbPlugin=app.env.pluginConfig.nbPlugin+1;
                            indiceP=app.env.pluginConfig.nbPlugin;
                            app.env.pluginConfig
                            if indiceP==2
                                app.env.pluginConfig.nomP(indiceP-1)=struct();
                                app.env.figureOpen.nomPlugin=struct();
                            end
                            app.env.pluginConfig.nomP(indiceP-1).nomPlugin=strcat('VideoPlayer',num2str(app.env.pluginConfig.nbVideoPlayer));
                            %app.env.pluginConfig.nomP(indiceP-1).nomPlugin=struct();
                            %envPluginID=app.env.pluginConfig.nomP(indiceP-1).nomPlugin;
                            if ~isempty(meta.getVideoFiles())
                               try
                                   
                              nouvPosition=app.env.pluginConfig.nomP.positionCoord;
                              returnPositionCoordVideoPlayer=fr.lescot.bind.plugins.VideoPlayer_test(app.trip, nouvPosition, app.env.pluginConfig.nomP.videoDes, app.env.pluginConfig.nomP.audioDes, app.env.pluginConfig.nomP.decimation);
                               
                               catch ME
                                   %lancer le configurateur
                                   ConfigVideo=fr.lescot.bind.configurators.VideoPlayerConfigurator('VideoPlayer', app.trip, meta );
                            
                                    %msgbox('Il faur remplir le configurateur');
                                    pause (10);
                                    
                                    lanceMagneto(app)
                                    
                                    app.env.pluginConfig.nomP(indiceP-1).positionCard=ConfigVideo.configuration.findArgumentWithOrder(2).getValue();
                                    app.env.pluginConfig.nomP(indiceP-1).videoDes=ConfigVideo.configuration.findArgumentWithOrder(3).getValue();
                                    app.env.pluginConfig.nomP(indiceP-1).audioDes=ConfigVideo.configuration.findArgumentWithOrder(4).getValue();
                                    app.env.pluginConfig.nomP(indiceP-1).decimation=ConfigVideo.configuration.findArgumentWithOrder(5).getValue();
                                
                                    %lancer le plugin
                                    returnPositionCoordVideoPlayer=fr.lescot.bind.plugins.VideoPlayer_test(app.trip, app.env.pluginConfig.nomP(indiceP-1).positionCard, app.env.pluginConfig.nomP(indiceP-1).videoDes, app.env.pluginConfig.nomP(indiceP-1).audioDes, app.env.pluginConfig.nomP(indiceP-1).decimation);
                                    %récupérer la figure ouverte
                                    app.env.figureOpen.nomPlugin(indiceP-1).nom=strcat('VideoPlayer',num2str(app.env.pluginConfig.nbVideoPlayer));
                                    app.env.figureOpen.nomPlugin(indiceP-1).figure=returnPositionCoordVideoPlayer;

                                
                                    %recuperation de la nouvelle position
                                    % propriété suprimee app.env.pluginConfig.nomP(indiceP-1).positionCoord=returnPositionCoordVideoPlayer.newPosition;
                                
                               end
                               %ajoute dans la liste 'plugins'
                       
                               ajouteAPlugins(app,  strcat('VideoPlayer',num2str(app.env.pluginConfig.nbVideoPlayer)));
                               else
                                uialert(app.BINDGUIFigure, 'Aucune vidéo détectée. \n Veuillez choisir un TRIP adapté.', ' ', 'Icon', 'Info');
                            end
                            
                            case 'EventSituationDisplay'
                            
                            if  ~ (isempty(meta.getSituationsNamesList()) && isempty(meta.getSituationsNamesList()))
                                app.env.pluginConfig.nbEventDisplay=app.env.pluginConfig.nbEventDisplay+1;
                                app.env.pluginConfig.nbPlugin=app.env.pluginConfig.nbPlugin+1;
                                indiceP=app.env.pluginConfig.nbPlugin;
                                
                                if indiceP==2
                                    app.env.pluginConfig.nomP(indiceP-1)=struct();
                                    app.env.figureOpen.nomPlugin=struct();
                                end
                                app.env.pluginConfig.nomP(indiceP-1).nomPlugin=strcat('EventSituationDisplay',num2str(app.env.pluginConfig.nbEventDisplay));
                               
                               %lancer le configurateur
                               ConfigEventSituationDisplay=fr.lescot.bind.configurators.EventSituationDisplayConfigurator('EventSituationDisplay', app.trip, meta)
                               pause (10); 
                               
                               %lancer le magneto
                               lanceMagneto(app)
                               
                               app.env.pluginConfig.nomP(indiceP-1).situationIdentifiers=ConfigEventSituationDisplay.configuration.findArgumentWithOrder(2).getValue();
                               app.env.pluginConfig.nomP(indiceP-1).positionCard=ConfigEventSituationDisplay.configuration.findArgumentWithOrder(3).getValue();
                               app.env.pluginConfig.nomP(indiceP-1).xMax=ConfigEventSituationDisplay.configuration.findArgumentWithOrder(4).getValue();
                               
                               %maj du plugin dans le panel:
                               ajouteAPlugins(app,  strcat('EventSituationDisplay',num2str(app.env.pluginConfig.nbEventDisplay)));
                                
                               
                               %lancer le plugin:
                                    
                              returnPositionCoordEventDisplay=fr.lescot.bind.plugins.EventSituationDisplay(app.trip, app.env.pluginConfig.nomP(indiceP-1).situationIdentifiers, app.env.pluginConfig.nomP(indiceP-1).positionCard, app.env.pluginConfig.nomP(indiceP-1).xMax);
                              
                              %récupérer la figure ouverte
                                app.env.figureOpen.nomPlugin(indiceP-1).nom=strcat('EventSituationDisplay',num2str(app.env.pluginConfig.nbEventDisplay));
                                app.env.figureOpen.nomPlugin(indiceP-1).figure=returnPositionCoordEventDisplay;
                                
                              %recuperation de la nouvelle position
                               app.env.pluginConfig.nomP(indiceP-1).positionCoord=returnPositionCoordEventDisplay.newPosition;
                              
                            else
                                uialert(app.BINDGUIFigure, 'Aucune Situation détectée. \n Veuillez choisir un TRIP adapté.', ' ', 'Icon', 'Info');
                            end
                            
                            case 'EventSituationBrowser'
                            
                            if  ~ (isempty(meta.getSituationsNamesList()) && isempty(meta.getSituationsNamesList()))
                                app.env.pluginConfig.nbEventBrowser=app.env.pluginConfig.nbEventBrowser+1;
                                app.env.pluginConfig.nbPlugin=app.env.pluginConfig.nbPlugin+1;
                                indiceP=app.env.pluginConfig.nbPlugin;
                                
                                if indiceP==2
                                    app.env.pluginConfig.nomP(indiceP-1)=struct();
                                    app.env.figureOpen.nomPlugin=struct();
                                end
                                app.env.pluginConfig.nomP(indiceP-1).nomPlugin=strcat('EventSituationBrowser',num2str(app.env.pluginConfig.nbEventBrowser));
                               
                               %lancer le configurateur
                               ConfigEventSituationBrowser=fr.lescot.bind.configurators.EventSituationBrowserConfigurator('EventSituationBrowser', app.trip, meta)
                               
                               pause (10);
                               %lancer le magneto
                               lanceMagneto(app)
                               
                               app.env.pluginConfig.nomP(indiceP-1).selectedIdentifier=ConfigEventSituationBrowser.configuration.findArgumentWithOrder(2).getValue();
                               
                               
                               %maj du plugin dans le panel:
                               ajouteAPlugins(app,  strcat('EventSituationBrowser',num2str(app.env.pluginConfig.nbEventBrowser)));
                                
                               %lancer le plugin:
                                returnPositionCoordEventBrowser=fr.lescot.bind.plugins.EventSituationBrowser(app.trip, app.env.pluginConfig.nomP(indiceP-1).selectedIdentifier );
                              
                               %récupérer la figure ouverte
                               app.env.figureOpen.nomPlugin(indiceP-1).nom=strcat('EventSituationBrowser',num2str(app.env.pluginConfig.nbEventBrowser));
                               app.env.figureOpen.nomPlugin(indiceP-1).figure=returnPositionCoordEventBrowser;
                                
                                %recuperation de la nouvelle position
                               app.env.pluginConfig.nomP(indiceP-1).positionCoord=returnPositionCoordEventBrowser.newPosition;
                            
                               
                             else
                                uialert(app.BINDGUIFigure, 'Aucune Situation détectée. \n Veuillez choisir un TRIP adapté.', ' ', 'Icon', 'Info');
                            end
                            
  
                        case 'Plotter2D (tf(t))'
                                app.env.pluginConfig.nbPlotter2D=app.env.pluginConfig.nbPlotter2D+1;
                                app.env.pluginConfig.nbPlugin=app.env.pluginConfig.nbPlugin+1;
                                indiceP=app.env.pluginConfig.nbPlugin;
                                
                                if indiceP==2
                                    app.env.pluginConfig.nomP(indiceP-1)=struct();
                                    app.env.figureOpen.nomPlugin=struct();
                                    
                                end
                                app.env.pluginConfig.nomP(indiceP-1).nomPlugin=strcat('Plotter2D',num2str(app.env.pluginConfig.nbPlotter2D));
                                
                               ConfigPlotter2D=fr.lescot.bind.configurators.DataPlotterConfigurator('Plotter2D', app.trip, meta)
                               
                               pause (10);
                               %lancer le magneto
                               lanceMagneto(app)
                               
                               app.env.pluginConfig.nomP(indiceP-1).dataIdentifiers=ConfigPlotter2D.configuration.findArgumentWithOrder(2).getValue();
                               app.env.pluginConfig.nomP(indiceP-1).positionCard=ConfigPlotter2D.configuration.findArgumentWithOrder(3).getValue();
                               app.env.pluginConfig.nomP(indiceP-1).timeWindow=ConfigPlotter2D.configuration.findArgumentWithOrder(4).getValue();
                               app.env.pluginConfig.nomP(indiceP-1).scaleMode=ConfigPlotter2D.configuration.findArgumentWithOrder(5).getValue();
                               %app.env.pluginConfig.nomP(indiceP-1).scale=ConfigPlotter2D.configuration.findArgumentWithOrder(6).getValue();
                               %app.env.pluginConfig.nomP(indiceP-1).colors=ConfigPlotter2D.configuration.findArgumentWithOrder(7).getValue();
                               %app.env.pluginConfig.nomP(indiceP-1).lineTypes=ConfigPlotter2D.configuration.findArgumentWithOrder(8).getValue();
                               %app.env.pluginConfig.nomP(indiceP-1).markers=ConfigPlotter2D.configuration.findArgumentWithOrder(9).getValue();
                               
                               
                               %maj du plugin dans le panel:
                               ajouteAPlugins(app,  strcat('Plotter2D',num2str(app.env.pluginConfig.nbPlotter2D)));
                                
                               %lancer le plugin:
                               returnPositionCoordPlotter2D=fr.lescot.bind.plugins.DataPlotter(app.trip, app.env.pluginConfig.nomP(indiceP-1).dataIdentifiers, app.env.pluginConfig.nomP(indiceP-1).positionCard, app.env.pluginConfig.nomP(indiceP-1).timeWindow);
                              
                               %récuper la figure ouverte
                                app.env.figureOpen.nomPlugin
                                app.env.figureOpen.nomPlugin(indiceP-1).nom=strcat('Plotter2D',num2str(app.env.pluginConfig.nbPlotter2D));
                                app.env.figureOpen.nomPlugin(indiceP-1).figure=returnPositionCoordPlotter2D;
                                
                                
                                %recuperation de la nouvelle position
                               app.env.pluginConfig.nomP(indiceP-1).positionCoord=returnPositionCoordPlotter2D.newPosition; 
                            
                            
                        case 'Afficher dernier message'  
                                app.env.pluginConfig.nbMessage=app.env.pluginConfig.nbMessage+1;
                                app.env.pluginConfig.nbPlugin=app.env.pluginConfig.nbPlugin+1;
                                indiceP=app.env.pluginConfig.nbPlugin;
                                
                                if indiceP==2
                                    app.env.pluginConfig.nomP(indiceP-1)=struct();
                                    app.env.figureOpen.nomPlugin=struct();
                                end
                                app.env.pluginConfig.nomP(indiceP-1).nomPlugin=strcat('Afficher dernier message',num2str(app.env.pluginConfig.nbMessage));
                               
                                %{
                                    %%%%%%%%%%%%pas de necessité du configurateur ?
                               ConfigDernierMessage=fr.lescot.bind.configurators.MessageDisplayConfigurator('EventSituationBrowser', app.trip, meta)
                               pause (10); 
                               app.env.pluginConfig.nomP(indiceP-1).selectedIdentifier=ConfigEventSituationBrowser.configuration.findArgumentWithOrder(2).getValue();
                                %}
                               
                               %lancer le magneto
                               lanceMagneto(app)
                                
                               %maj du plugin dans le panel:
                               ajouteAPlugins(app,  strcat('Afficher dernier message',num2str(app.env.pluginConfig.nbMessage)));
                                
                                %lancer le plugin:
                                returnPositionCoordDernierMessage=fr.lescot.bind.plugins.MessageDisplay(app.trip, 'north' );
                                
                                %révupérer la figure ouverte
                                app.env.figureOpen.nomPlugin(indiceP-1).nom=strcat('Afficher dernier message',num2str(app.env.pluginConfig.nbMessage));
                                app.env.figureOpen.nomPlugin(indiceP-1).figure=returnPositionCoordDernierMessage;
                            
                                %recuperation de la nouvelle position
                               app.env.pluginConfig.nomP(indiceP-1).positionCoord=returnPositionCoordDernierMessage.newPosition;
                       
                        case 'Tracé GPS'
                                app.env.pluginConfig.nbGPS=app.env.pluginConfig.nbGPS+1;
                                msg='tracé GPS'
                                app.env.pluginConfig.nbPlugin=app.env.pluginConfig.nbPlugin+1;
                                indiceP=app.env.pluginConfig.nbPlugin;
                                
                                if indiceP==2
                                    app.env.pluginConfig.nomP(indiceP-1)=struct(); 
                                    app.env.figureOpen.nomPlugin=struct();
                                end
                                app.env.pluginConfig.nomP(indiceP-1).nomPlugin=strcat('Tracé GPS',num2str(app.env.pluginConfig.nbGPS));
                               
                               
                                   %lancer le magneto
                                   lanceMagneto(app)
                               
                               
                                   %lancer le configurateur
                                   ConfigGPS=fr.lescot.bind.configurators.GpsViewerConfigurator('Tracé GPS', app.trip, meta)


                               if (ConfigGPS.msg ~= "Pas de données GPS")
                                    
                                    pause(10)
                                    app.env.pluginConfig.nomP(indiceP-1).positionCard=ConfigGPS.configuration.findArgumentWithOrder(2).getValue();
                                    app.env.pluginConfig.nomP(indiceP-1).MapType=ConfigGPS.configuration.findArgumentWithOrder(3).getValue();
                                    app.env.pluginConfig.nomP(indiceP-1).gpsSource=ConfigGPS.configuration.findArgumentWithOrder(4).getValue();
                                    %maj du plugin dans le panel:
                                    ajouteAPlugins(app,  strcat('Tracé GPS',num2str(app.env.pluginConfig.nbGPS)));
                                    
                                        %lancer le plugin:
                                        returnPositionCoordGPS=fr.lescot.bind.plugins.GpsViewer(app.trip, app.env.pluginConfig.nomP(indiceP-1).positionCard, app.env.pluginConfig.nomP(indiceP-1).MapType, app.env.pluginConfig.nomP(indiceP-1).gpsSource)
                               
                                        %révupérer la figure ouverte
                                        app.env.figureOpen.nomPlugin(indiceP-1).nom=strcat('Tracé GPS',num2str(app.env.pluginConfig.nbGPS));
                                        app.env.figureOpen.nomPlugin(indiceP-1).figure=returnPositionCoordGPS;
                                        
                                          %recuperation de la nouvelle position
                                        app.env.pluginConfig.nomP(indiceP-1).positionCoord=returnPositionCoordGPS.newPosition;


                               else
                                   msgbox("Pas de données GPS sur ce trip")
                                
                               end


                        case 'Afficher valeurs instantannées'
                                app.env.pluginConfig.nbValeurInstant=app.env.pluginConfig.nbValeurInstant+1;
                                app.env.pluginConfig.nbPlugin=app.env.pluginConfig.nbPlugin+1;
                                indiceP=app.env.pluginConfig.nbPlugin;
                                
                                if indiceP==2
                                    app.env.pluginConfig.nomP(indiceP-1)=struct();
                                    app.env.figureOpen.nomPlugin=struct();
                                end
                                app.env.pluginConfig.nomP(indiceP-1).nomPlugin=strcat('Afficher valeurs instantannées',num2str(app.env.pluginConfig.nbValeurInstant));
                               
                               %lancer le configurateur
                               ConfigValeurInstant=fr.lescot.bind.configurators.ValueDisplayConfigurator('Afficher valeurs instantannées', app.trip, meta)
                               
                               pause (10);
                               %lancer le magneto
                               lanceMagneto(app)
                               
                               app.env.pluginConfig.nomP(indiceP-1).positionCard=ConfigValeurInstant.configuration.findArgumentWithOrder(3).getValue();
                               app.env.pluginConfig.nomP(indiceP-1).dataIdentifiers=ConfigValeurInstant.configuration.findArgumentWithOrder(2).getValue();
                            
                               
                               %maj du plugin dans le panel:
                               ajouteAPlugins(app,  strcat('Afficher valeurs instantannées',num2str(app.env.pluginConfig.nbValeurInstant)));
                                
                               %lancer le plugin:
                               returnPositionCoordValeurInstant=fr.lescot.bind.plugins.ValueDisplay(app.trip, app.env.pluginConfig.nomP(indiceP-1).dataIdentifiers ,app.env.pluginConfig.nomP(indiceP-1).positionCard); 
                               
                               %révupérer la figure ouverte
                                app.env.figureOpen.nomPlugin(indiceP-1).nom=strcat('Afficher valeurs instantannées',num2str(app.env.pluginConfig.nbValeurInstant));
                                app.env.figureOpen.nomPlugin(indiceP-1).figure=returnPositionCoordValeurInstant;

                                %recuperation de la nouvelle position
                               app.env.pluginConfig.nomP(indiceP-1).positionCoord=returnPositionCoordValeurInstant.newPosition;
                            
                        
                        case 'VideoSynchroniser'
                                app.env.pluginConfig.nbVideoSynchro=app.env.pluginConfig.nbVideoSynchro+1;
                                app.env.pluginConfig.nbPlugin=app.env.pluginConfig.nbPlugin+1;
                                indiceP=app.env.pluginConfig.nbPlugin;
                                
                                if indiceP==2
                                    app.env.pluginConfig.nomP(indiceP-1)=struct();
                                    app.env.figureOpen.nomPlugin=struct();
                                end
                                app.env.pluginConfig.nomP(indiceP-1).nomPlugin=strcat('VideoSynchroniser',num2str(app.env.pluginConfig.nbVideoSynchro));
                               
                               %lancer le configurateur
                               ConfigVideoSynchro=fr.lescot.bind.configurators.VideoSynchroniserConfigurator('VideoSynchroniser', app.trip, meta)
                               
                               pause (10);
                               %lancer le magneto
                               lanceMagneto(app)
                               
                               app.env.pluginConfig.nomP(indiceP-1).positionCard=ConfigVideoSynchro.configuration.findArgumentWithOrder(2).getValue();
                               app.env.pluginConfig.nomP(indiceP-1).description=ConfigVideoSynchro.configuration.findArgumentWithOrder(3).getValue();
                            
                               
                               %maj du plugin dans le panel:
                               ajouteAPlugins(app,  strcat('VideoSynchroniser',num2str(app.env.pluginConfig.nbVideoSynchro)));
                                
                               %lancer le plugin:
                               returnPositionCoordVideoSynchro=fr.lescot.bind.plugins.VideoSynchroniser(app.trip,app.env.pluginConfig.nomP(indiceP-1).positionCard, app.env.pluginConfig.nomP(indiceP-1).description ); 

                               %révupérer la figure ouverte
                                app.env.figureOpen.nomPlugin.nom=strcat('VideoSynchroniser',num2str(app.env.pluginConfig.nbVideoSynchro));
                                app.env.figureOpen.nomPlugin(indiceP-1).figure=returnPositionCoordVideoSynchro;
                               
                                %recuperation de la nouvelle position
                               app.env.pluginConfig.nomP(indiceP-1).positionCoord=returnPositionCoordVideoSynchro.newPosition;
                            
                            
                    otherwise
                    end
                app.env
                end
            else
                uialert(app.BINDGUIFigure, 'Aucun trip de référence. \n Veuillez en sélectionner un.', ' ', 'Icon', 'Info');
            app.env
            app.env
            end
     
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
     
            
        end

        % Menu selected function: PresetdePluginsMenu
        function PresetdePluginsMenuSelected(app, event)
            app.env
            [FileName, PathName]=uigetfile('*.env', 'Récupérer la configuration des plugins "preset"');
            app.env = importdata(fullfile(PathName,FileName));
            MaJLbPreset(app, FileName)
            app.env
        end

        % Value changed function: lbPreset
        function lbPresetValueChanged(app, event)
           
            app.presetValueClick=app.lbPreset.Value  %donne le nom du preset cliqué 1 seule valeur
           
            %app.env.configPreset.NomPreset
                
            app.presetPathClick=app.env.configPreset.NomPreset.Path
          
        end

        % Button pushed function: FermerTout
        function FermerToutButtonPushed(app, event)
                        %%%%%%%%%%%%% Anaïs%%%%%%%%%%%%%%
            
            app.env
            
            nbPluginOpen=length(app.env.figureOpen.nomPlugin)
            for i=1:1:nbPluginOpen
                if  ~isempty(app.env.figureOpen.nomPlugin(i).figure) %isprop(app, 'env.figureOpen.nomPlugin(i).figure') &&
                    %recuperer la position
                    position=app.env.figureOpen.nomPlugin(i).figure.getFigureHandler.Position
                    app.env.pluginConfig.nomP(i).positionCoord=position;
                    i
                    %fermer la figure
                    app.env.figureOpen.nomPlugin(i).figure.closeCallback
                end    
              
                
            end
            
            app.env
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.BINDGUIFigure = uifigure('Visible', 'off');
            app.BINDGUIFigure.Name = 'BIND (Pre-Alpha Version 2.0)';
            app.BINDGUIFigure.CloseRequestFcn = createCallbackFcn(app, @BINDGUIFigureCloseRequest, true);
            app.Menu = uimenu(app.BINDGUIFigure);
            app.Menu.Text = 'Environnement';
            app.SaveMenu = uimenu(app.Menu);
            app.SaveMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveMenuSelected, true);
            app.SaveMenu.Text = 'Save';
            app.LoadMenu = uimenu(app.Menu);
            app.LoadMenu.Text = 'Load';
            app.EnvironnementcompletMenu = uimenu(app.LoadMenu);
            app.EnvironnementcompletMenu.MenuSelectedFcn = createCallbackFcn(app, @EnvironnementcompletMenuSelected, true);
            app.EnvironnementcompletMenu.Text = 'Environnement complet';
            app.TripsMenu = uimenu(app.LoadMenu);
            app.TripsMenu.MenuSelectedFcn = createCallbackFcn(app, @TripsMenuSelected, true);
            app.TripsMenu.Text = 'Trips';
            app.SegmentationMenu = uimenu(app.LoadMenu);
            app.SegmentationMenu.MenuSelectedFcn = createCallbackFcn(app, @SegmentationMenuSelected, true);
            app.SegmentationMenu.Text = 'Segmentation';
            app.ExportMenu = uimenu(app.LoadMenu);
            app.ExportMenu.MenuSelectedFcn = createCallbackFcn(app, @ExportMenuSelected, true);
            app.ExportMenu.Text = 'Export';
            app.PresetdePluginsMenu = uimenu(app.LoadMenu);
            app.PresetdePluginsMenu.MenuSelectedFcn = createCallbackFcn(app, @PresetdePluginsMenuSelected, true);
            app.PresetdePluginsMenu.Text = 'Preset de Plugins';
            app.GuideMenu = uimenu(app.BINDGUIFigure);
            app.GuideMenu.MenuSelectedFcn = createCallbackFcn(app, @bGuide_ButtonPushed, true);
            app.GuideMenu.Text = 'Guide';
            app.bChargement = uibutton(app.BINDGUIFigure, 'push');
            app.bChargement.ButtonPushedFcn = createCallbackFcn(app, @bChargement_ButtonPushed, true);
            app.bChargement.Text = 'Ajout Trips';
            app.bSegmentation = uibutton(app.BINDGUIFigure, 'push');
            app.bSegmentation.ButtonPushedFcn = createCallbackFcn(app, @bSegmentation_ButtonPushed, true);
            app.bSegmentation.Text = 'Segmentation';
            app.bTraitement = uibutton(app.BINDGUIFigure, 'push');
            app.bTraitement.ButtonPushedFcn = createCallbackFcn(app, @bTraitement_ButtonPushed, true);
            app.bTraitement.Text = 'Traitement';
            app.Tree = uitree(app.BINDGUIFigure);
            app.Tree.Multiselect = 'on';
            app.Tree.SelectionChangedFcn = createCallbackFcn(app, @Tree_SelectionChanged, true);
            app.Tree.Enable = 'off';
            app.TripRefNode = uitreenode(app.Tree);
            app.TripRefNode.Text = '';
            app.TripsNode = uitreenode(app.Tree);
            app.TripsNode.Icon = 'database.png';
            app.TripsNode.Text = 'Trips';
            app.TripsSelectNode = uitreenode(app.TripsNode);
            app.TripsSelectNode.Text = 'Sélection';
            app.TripsListNode = uitreenode(app.TripsNode);
            app.TripsListNode.Text = 'Liste';
            app.EventsSituationsNode = uitreenode(app.Tree);
            app.EventsSituationsNode.Icon = 'events&situations.png';
            app.EventsSituationsNode.Text = 'Events & Situations';
            app.EventsNode = uitreenode(app.EventsSituationsNode);
            app.EventsNode.Icon = 'event.png';
            app.EventsNode.Text = 'Events';
            app.SituationsNode = uitreenode(app.EventsSituationsNode);
            app.SituationsNode.Icon = 'situation.png';
            app.SituationsNode.Text = 'Situations';
            app.DatasNode = uitreenode(app.Tree);
            app.DatasNode.Icon = 'data.png';
            app.DatasNode.Text = 'Datas';
            app.MetasNode = uitreenode(app.Tree);
            app.MetasNode.Icon = 'attribute.png';
            app.MetasNode.Text = 'Metas';
            app.AttributesNode = uitreenode(app.MetasNode);
            app.AttributesNode.Icon = 'attribute.png';
            app.AttributesNode.Text = 'Attributs';
            app.AudioVideoNode = uitreenode(app.MetasNode);
            app.AudioVideoNode.Icon = 'video.png';
            app.AudioVideoNode.Text = 'Audio/Video';
            app.ParticipantNode = uitreenode(app.MetasNode);
            app.ParticipantNode.Icon = 'subject.png';
            app.ParticipantNode.Text = 'Participant';
            app.CatalogueNode = uitreenode(app.Tree);
            app.CatalogueNode.Icon = 'window.png';
            app.CatalogueNode.Text = 'Catalogue';
            app.FonctionsNode = uitreenode(app.CatalogueNode);
            app.FonctionsNode.Text = 'Fonctions';
            app.meanNode = uitreenode(app.FonctionsNode);
            app.meanNode.Text = 'mean';
            app.stdNode = uitreenode(app.FonctionsNode);
            app.stdNode.Text = 'std';
            app.minNode = uitreenode(app.FonctionsNode);
            app.minNode.Text = 'min';
            app.maxNode = uitreenode(app.FonctionsNode);
            app.maxNode.Text = 'max';
            app.NativeothermalabfunctionsNode = uitreenode(app.FonctionsNode);
            app.NativeothermalabfunctionsNode.Text = 'Native other malab functions';
            app.OthermatlabfunctionsintoolboxNode = uitreenode(app.FonctionsNode);
            app.OthermatlabfunctionsintoolboxNode.Text = 'Other matlab functions in toolbox';
            app.accelerationmNode = uitreenode(app.FonctionsNode);
            app.accelerationmNode.Text = 'acceleration.m';
            app.crossingLaneAllmNode = uitreenode(app.FonctionsNode);
            app.crossingLaneAllmNode.Text = 'crossingLaneAll.m';
            app.crossingLaneAll2mNode = uitreenode(app.FonctionsNode);
            app.crossingLaneAll2mNode.Text = 'crossingLaneAll2.m';
            app.crossingLaneBySituationmNode = uitreenode(app.FonctionsNode);
            app.crossingLaneBySituationmNode.Text = 'crossingLaneBySituation.m';
            app.DIVmNode = uitreenode(app.FonctionsNode);
            app.DIVmNode.Text = 'DIV.m';
            app.DIVv2mNode = uitreenode(app.FonctionsNode);
            app.DIVv2mNode.Text = 'DIVv2.m';
            app.DMOmNode = uitreenode(app.FonctionsNode);
            app.DMOmNode.Text = 'DMO.m';
            app.eventDIVmNode = uitreenode(app.FonctionsNode);
            app.eventDIVmNode.Text = 'eventDIV.m';
            app.eventTIVmNode = uitreenode(app.FonctionsNode);
            app.eventTIVmNode.Text = 'eventTIV.m';
            app.firstReactionmNode = uitreenode(app.FonctionsNode);
            app.firstReactionmNode.Text = 'firstReaction.m';
            app.fixationsmNode = uitreenode(app.FonctionsNode);
            app.fixationsmNode.Text = 'fixations.m';
            app.fixationsAOImNode = uitreenode(app.FonctionsNode);
            app.fixationsAOImNode.Text = 'fixationsAOI.m';
            app.fixitesmNode = uitreenode(app.FonctionsNode);
            app.fixitesmNode.Text = 'fixites.m';
            app.frequencymNode = uitreenode(app.FonctionsNode);
            app.frequencymNode.Text = 'frequency.m';
            app.numberOfSamplesmNode = uitreenode(app.FonctionsNode);
            app.numberOfSamplesmNode.Text = 'numberOfSamples.m';
            app.numberOfSelfReportsByCLmNode = uitreenode(app.FonctionsNode);
            app.numberOfSelfReportsByCLmNode.Text = 'numberOfSelfReportsByCL.m';
            app.numberOfSelfReportsByScenariomNode = uitreenode(app.FonctionsNode);
            app.numberOfSelfReportsByScenariomNode.Text = 'numberOfSelfReportsByScenario.m';
            app.RRintervalsmNode = uitreenode(app.FonctionsNode);
            app.RRintervalsmNode.Text = 'RRintervals.m';
            app.situationDurationmNode = uitreenode(app.FonctionsNode);
            app.situationDurationmNode.Text = 'situationDuration.m';
            app.speedJerkmNode = uitreenode(app.FonctionsNode);
            app.speedJerkmNode.Text = 'speedJerk.m';
            app.SteeringAnglePeakmNode = uitreenode(app.FonctionsNode);
            app.SteeringAnglePeakmNode.Text = 'SteeringAnglePeak.m';
            app.steeringAnglesInDegreesmNode = uitreenode(app.FonctionsNode);
            app.steeringAnglesInDegreesmNode.Text = 'steeringAnglesInDegrees.m';
            app.stopYNmNode = uitreenode(app.FonctionsNode);
            app.stopYNmNode.Text = 'stopYN.m';
            app.successYNmNode = uitreenode(app.FonctionsNode);
            app.successYNmNode.Text = 'success.YN.m';
            app.timeDetectionmNode = uitreenode(app.FonctionsNode);
            app.timeDetectionmNode.Text = 'timeDetection.m';
            app.trackingPerformancemNode = uitreenode(app.FonctionsNode);
            app.trackingPerformancemNode.Text = 'trackingPerformance.m';
            app.TRDecelmNode = uitreenode(app.FonctionsNode);
            app.TRDecelmNode.Text = 'TRDecel.m';
            app.SegmentationNode = uitreenode(app.CatalogueNode);
            app.SegmentationNode.Text = 'Segmentation';
            app.TemporelleNode = uitreenode(app.SegmentationNode);
            app.TemporelleNode.Text = 'Temporelle';
            app.SimpleNode = uitreenode(app.TemporelleNode);
            app.SimpleNode.Text = 'Simple';
            app.DoubleNode = uitreenode(app.TemporelleNode);
            app.DoubleNode.Text = 'Double';
            app.ConditionnelleNode = uitreenode(app.SegmentationNode);
            app.ConditionnelleNode.Text = 'Conditionnelle';
            app.ExportNode = uitreenode(app.CatalogueNode);
            app.ExportNode.Text = 'Export';
            app.SeulNode = uitreenode(app.ExportNode);
            app.SeulNode.Text = 'Seul';
            app.TousNode = uitreenode(app.ExportNode);
            app.TousNode.Text = 'Tous';
            app.bImport = uibutton(app.BINDGUIFigure, 'push');
            app.bImport.ButtonPushedFcn = createCallbackFcn(app, @bImport_ButtonPushed, true);
            app.bImport.Enable = 'off';
            app.bImport.Text = 'Import';
            app.bExport = uibutton(app.BINDGUIFigure, 'push');
            app.bExport.ButtonPushedFcn = createCallbackFcn(app, @bExport_ButtonPushed, true);
            app.bExport.Enable = 'off';
            app.bExport.Text = 'Export';
            app.panelControl = uipanel(app.BINDGUIFigure);
            app.bReset = uibutton(app.panelControl, 'push');
            app.bReset.ButtonPushedFcn = createCallbackFcn(app, @bReset_ButtonPushed, true);
            app.bReset.Enable = 'off';
            app.bReset.Text = '✏️';
            app.bSupprimer = uibutton(app.panelControl, 'push');
            app.bSupprimer.ButtonPushedFcn = createCallbackFcn(app, @bSupprimer_ButtonPushed, true);
            app.bSupprimer.Enable = 'off';
            app.bSupprimer.Text = '❌';
            app.bVoir = uibutton(app.panelControl, 'push');
            app.bVoir.ButtonPushedFcn = createCallbackFcn(app, @bVoir_ButtonPushed, true);
            app.bVoir.Enable = 'off';
            app.bVoir.Text = '👁️';
            app.panelSeg = uipanel(app.BINDGUIFigure);
            app.panelSeg.Title = 'Segmentation';
            app.bgSeg = uibuttongroup(app.panelSeg);
            app.bgSeg.SelectionChangedFcn = createCallbackFcn(app, @bgSegmentation_SelectionChanged, true);
            app.rbVideo = uiradiobutton(app.bgSeg);
            app.rbVideo.Text = 'Vidéo';
            app.rbVideo.Value = true;
            app.rbConditionnelle = uiradiobutton(app.bgSeg);
            app.rbConditionnelle.Text = 'Conditionnelle';
            app.rbTemporelle = uiradiobutton(app.bgSeg);
            app.rbTemporelle.Text = 'Temporelle';
            app.tsubP = uipanel(app.panelSeg);
            app.tbtgTable = uibuttongroup(app.tsubP);
            app.tbtgTable.SelectionChangedFcn = createCallbackFcn(app, @tc_bg_SelectionChanged, true);
            app.ttbEvent = uitogglebutton(app.tbtgTable);
            app.ttbEvent.Text = 'Event';
            app.ttbEvent.Value = true;
            app.ttbSituation = uitogglebutton(app.tbtgTable);
            app.ttbSituation.Text = 'Situation';
            app.tddRef = uidropdown(app.tsubP);
            app.tddRef.Items = {''};
            app.tddRef.ValueChangedFcn = createCallbackFcn(app, @tdd_ValueChanged, true);
            app.tddRef.Value = '';
            app.lt_inf = uilabel(app.tsubP);
            app.lt_inf.Enable = 'off';
            app.lt_inf.Text = 'inf. (s)';
            app.tspnInf1 = uispinner(app.tsubP);
            app.tspnInf1.ValueChangedFcn = createCallbackFcn(app, @tspn_ValueChanged, true);
            app.tspnInf1.Enable = 'off';
            app.lt_sup = uilabel(app.tsubP);
            app.lt_sup.Enable = 'off';
            app.lt_sup.Text = 'sup. (s)';
            app.tspnSup1 = uispinner(app.tsubP);
            app.tspnSup1.ValueChangedFcn = createCallbackFcn(app, @tspn_ValueChanged, true);
            app.tspnSup1.Enable = 'off';
            app.PrsentdansLabel = uilabel(app.tsubP);
            app.PrsentdansLabel.Enable = 'off';
            app.PrsentdansLabel.Text = 'Présent dans:';
            app.tddSituation = uidropdown(app.tsubP);
            app.tddSituation.Items = {''};
            app.tddSituation.Enable = 'off';
            app.tddSituation.Value = '';
            app.lt_simple = uilabel(app.tsubP);
            app.lt_simple.Text = 'Simple';
            app.lt_sep1 = uilabel(app.tsubP);
            app.lt_sep1.Text = '___________';
            app.lt_ou = uilabel(app.tsubP);
            app.lt_ou.Text = 'ou';
            app.lt_sep2 = uilabel(app.tsubP);
            app.lt_sep2.Text = '___________';
            app.lt_double = uilabel(app.tsubP);
            app.lt_double.Text = 'Double';
            app.lt_table1 = uilabel(app.tsubP);
            app.lt_table1.Text = 'Table 1 (';
            app.tddTable1 = uidropdown(app.tsubP);
            app.tddTable1.Items = {''};
            app.tddTable1.ValueChangedFcn = createCallbackFcn(app, @tdd_ValueChanged, true);
            app.tddTable1.Value = '';
            app.tddAvant = uidropdown(app.tsubP);
            app.tddAvant.Items = {''};
            app.tddAvant.ValueChangedFcn = createCallbackFcn(app, @tdd_ValueChanged, true);
            app.tddAvant.Enable = 'off';
            app.tddAvant.Value = '';
            app.lt_offset1 = uilabel(app.tsubP);
            app.lt_offset1.Enable = 'off';
            app.lt_offset1.Text = 'Offset (s)';
            app.tspnInf2 = uispinner(app.tsubP);
            app.tspnInf2.ValueChangedFcn = createCallbackFcn(app, @tspn_ValueChanged, true);
            app.tspnInf2.Enable = 'off';
            app.lt_table2 = uilabel(app.tsubP);
            app.lt_table2.Enable = 'off';
            app.lt_table2.Text = 'Table 2 (';
            app.tddTable2 = uidropdown(app.tsubP);
            app.tddTable2.Items = {''};
            app.tddTable2.ValueChangedFcn = createCallbackFcn(app, @tdd_ValueChanged, true);
            app.tddTable2.Enable = 'off';
            app.tddTable2.Value = '';
            app.tddApres = uidropdown(app.tsubP);
            app.tddApres.Items = {''};
            app.tddApres.ValueChangedFcn = createCallbackFcn(app, @tdd_ValueChanged, true);
            app.tddApres.Enable = 'off';
            app.tddApres.Value = '';
            app.lt_offset2 = uilabel(app.tsubP);
            app.lt_offset2.Enable = 'off';
            app.lt_offset2.Text = 'Offset (s)';
            app.tspnSup2 = uispinner(app.tsubP);
            app.tspnSup2.ValueChangedFcn = createCallbackFcn(app, @tspn_ValueChanged, true);
            app.tspnSup2.Enable = 'off';
            app.tcbRepeter = uicheckbox(app.tsubP);
            app.tcbRepeter.ValueChangedFcn = createCallbackFcn(app, @tdd_ValueChanged, true);
            app.tcbRepeter.Enable = 'off';
            app.tcbRepeter.Text = 'Répéter sur les prochains segments';
            app.lt_sep3 = uilabel(app.tsubP);
            app.lt_sep3.Text = '⇣';
            app.NomdelasituationLabel = uilabel(app.tsubP);
            app.NomdelasituationLabel.Text = 'Nom de la situation :';
            app.tefNom = uieditfield(app.tsubP, 'text');
            app.tefNom.ValueChangingFcn = createCallbackFcn(app, @tefNomValueChanging, true);
            app.tcbAppliquerTrip = uicheckbox(app.tsubP);
            app.tcbAppliquerTrip.Enable = 'off';
            app.tcbAppliquerTrip.Text = 'Appliquer à la sélection';
            app.tbCreer = uibutton(app.tsubP, 'push');
            app.tbCreer.ButtonPushedFcn = createCallbackFcn(app, @tc_bCreer_ButtonPushed, true);
            app.tbCreer.Enable = 'off';
            app.tbCreer.Text = 'Créer';
            app.lt_sep3_2 = uilabel(app.tsubP);
            app.lt_sep3_2.Text = '⇣';
            app.lt_sep2_2 = uilabel(app.tsubP);
            app.lt_sep2_2.Text = '___________________________________';
            app.csubP = uipanel(app.panelSeg);
            app.lc_choix = uilabel(app.csubP);
            app.lc_choix.Text = 'Que créer ?';
            app.cddChoix = uidropdown(app.csubP);
            app.cddChoix.Items = {'Situation', 'Event'};
            app.cddChoix.ValueChangedFcn = createCallbackFcn(app, @tc_bg_SelectionChanged, true);
            app.cddChoix.Value = 'Situation';
            app.cbtgTable = uibuttongroup(app.csubP);
            app.cbtgTable.SelectionChangedFcn = createCallbackFcn(app, @tc_bg_SelectionChanged, true);
            app.ctbData = uitogglebutton(app.cbtgTable);
            app.ctbData.Text = 'Data';
            app.ctbData.Value = true;
            app.ctbEvent = uitogglebutton(app.cbtgTable);
            app.ctbEvent.Text = 'Event';
            app.lc_data = uilabel(app.csubP);
            app.lc_data.Text = ':';
            app.cddData = uidropdown(app.csubP);
            app.cddData.Items = {''};
            app.cddData.ValueChangedFcn = createCallbackFcn(app, @cdd_ValueChanged, true);
            app.cddData.Value = '';
            app.cddCond = uidropdown(app.csubP);
            app.cddCond.Items = {'', '<', '≤', '=', '≠', '>', '≥'};
            app.cddCond.ValueChangedFcn = createCallbackFcn(app, @cdd_ValueChanged, true);
            app.cddCond.Enable = 'off';
            app.cddCond.Value = '';
            app.cefNum = uieditfield(app.csubP, 'numeric');
            app.cefNum.ValueChangedFcn = createCallbackFcn(app, @tdd_ValueChanged, true);
            app.cefNum.Enable = 'off';
            app.cefText = uieditfield(app.csubP, 'text');
            app.cefText.ValueChangedFcn = createCallbackFcn(app, @cdd_ValueChanged, true);
            app.cefText.ValueChangingFcn = createCallbackFcn(app, @cefTextValueChanging, true);
            app.cefText.Enable = 'off';
            app.cbPlus = uibutton(app.csubP, 'push');
            app.cbPlus.ButtonPushedFcn = createCallbackFcn(app, @cbPlus_ButtonPushed, true);
            app.cbPlus.Enable = 'off';
            app.cbPlus.Text = '+';
            app.cbVider = uibutton(app.csubP, 'push');
            app.cbVider.ButtonPushedFcn = createCallbackFcn(app, @cbVider_ButtonPushed, true);
            app.cbVider.Text = '🗑️';
            app.clbVar = uilistbox(app.csubP);
            app.clbVar.Items = {};
            app.clbVar.Multiselect = 'on';
            app.clbVar.ValueChangedFcn = createCallbackFcn(app, @clbVarValueChanged, true);
            app.clbVar.Value = {};
            app.ccbInverser = uicheckbox(app.csubP);
            app.ccbInverser.Enable = 'off';
            app.ccbInverser.Visible = 'off';
            app.ccbInverser.Text = 'Inverser le résultat';
            app.lc_nom = uilabel(app.csubP);
            app.lc_nom.Text = 'Nom de la situation :';
            app.cefNom = uieditfield(app.csubP, 'text');
            app.cefNom.ValueChangingFcn = createCallbackFcn(app, @cefNomValueChanging, true);
            app.ccbAppliquerTrip = uicheckbox(app.csubP);
            app.ccbAppliquerTrip.Enable = 'off';
            app.ccbAppliquerTrip.Text = 'Appliquer à la sélection';
            app.cbCreer = uibutton(app.csubP, 'push');
            app.cbCreer.ButtonPushedFcn = createCallbackFcn(app, @tc_bCreer_ButtonPushed, true);
            app.cbCreer.Enable = 'off';
            app.cbCreer.Text = 'Créer';
            app.lc_variable = uilabel(app.csubP);
            app.lc_variable.Enable = 'off';
            app.lc_variable.Text = 'Variable :';
            app.cddVar = uidropdown(app.csubP);
            app.cddVar.Items = {''};
            app.cddVar.ValueChangedFcn = createCallbackFcn(app, @cdd_ValueChanged, true);
            app.cddVar.Enable = 'off';
            app.cddVar.Value = '';
            app.connecteurExemple = uilabel(app.csubP);
            app.connecteurExemple.Text = 'Ex:  NON(C1 ET C2 OU(C4 XOR (C5 ET C6)))';
            app.ConnecteurLabel = uilabel(app.csubP);
            app.ConnecteurLabel.Visible = 'off';
            app.ConnecteurLabel.Text = 'Connecteur';
            app.connecteurs = uieditfield(app.csubP, 'text');
            app.OUButton = uibutton(app.csubP, 'push');
            app.OUButton.ButtonPushedFcn = createCallbackFcn(app, @OUButtonPushed, true);
            app.OUButton.Enable = 'off';
            app.OUButton.Text = 'OU';
            app.ETButton = uibutton(app.csubP, 'push');
            app.ETButton.ButtonPushedFcn = createCallbackFcn(app, @ET_ButtonPushed, true);
            app.ETButton.Enable = 'off';
            app.ETButton.Text = 'ET';
            app.SituationLabel = uilabel(app.csubP);
            app.SituationLabel.Text = 'Situation :';
            app.cddSituation = uidropdown(app.csubP);
            app.cddSituation.Items = {''};
            app.cddSituation.Value = '';
            app.vsubP = uipanel(app.panelSeg);
            app.vbChargerProtocole = uibutton(app.vsubP, 'push');
            app.vbChargerProtocole.ButtonPushedFcn = createCallbackFcn(app, @vbChargerProtocole_ButtonPushed, true);
            app.vbChargerProtocole.Text = 'Charger protocole';
            app.vbCreerProtocole = uibutton(app.vsubP, 'push');
            app.vbCreerProtocole.ButtonPushedFcn = createCallbackFcn(app, @vbCreerProtocole_ButtonPushed, true);
            app.vbCreerProtocole.Text = 'Créer protocole';
            app.vtaNom = uitextarea(app.vsubP);
            app.vbEditerProtocole = uibutton(app.vsubP, 'push');
            app.vbEditerProtocole.ButtonPushedFcn = createCallbackFcn(app, @vbEditerProtocole_ButtonPushed, true);
            app.vbEditerProtocole.Text = 'Éditer protocole';
            app.vbLancerCodage = uibutton(app.vsubP, 'push');
            app.vbLancerCodage.ButtonPushedFcn = createCallbackFcn(app, @vbLancerCodage_ButtonPushed, true);
            app.vbLancerCodage.Text = 'Lancer codage';
            app.FiltrageEventsSituationsButton = uibutton(app.panelSeg, 'push');
            app.FiltrageEventsSituationsButton.ButtonPushedFcn = createCallbackFcn(app, @FiltrageEventsSituationsButtonPushed, true);
            app.FiltrageEventsSituationsButton.Visible = 'off';
            app.FiltrageEventsSituationsButton.Text = {'Filtrage Events'; ' Situations'};
            app.panelTrait = uipanel(app.BINDGUIFigure);
            app.panelTrait.Title = 'Traitement';
            app.panelTrait.Visible = 'off';
            app.UITable = uitable(app.panelTrait);
            app.UITable.ColumnName = {'Prévisualisation'};
            app.UITable.RowName = {};
            app.ButtonGroup = uibuttongroup(app.panelTrait);
            app.DataButton = uitogglebutton(app.ButtonGroup);
            app.DataButton.Text = 'Data';
            app.DataButton.Value = true;
            app.SituationButton = uitogglebutton(app.ButtonGroup);
            app.SituationButton.Text = 'Situation';
            app.VariableDropDownLabel = uilabel(app.panelTrait);
            app.VariableDropDownLabel.Text = 'Variable :';
            app.VariableDropDown = uidropdown(app.panelTrait);
            app.VariableDropDown.Items = {};
            app.VariableDropDown.Value = {};
            app.FonctionDropDownLabel = uilabel(app.panelTrait);
            app.FonctionDropDownLabel.Text = 'Fonction :';
            app.FonctionDropDown = uidropdown(app.panelTrait);
            app.FonctionDropDown.Items = {};
            app.FonctionDropDown.Value = {};
            app.NomEditFieldLabel = uilabel(app.panelTrait);
            app.NomEditFieldLabel.Text = 'Nom :';
            app.NomEditField = uieditfield(app.panelTrait, 'text');
            app.AppliquerlaslectionCheckBox = uicheckbox(app.panelTrait);
            app.AppliquerlaslectionCheckBox.Text = 'Appliquer à la sélection';
            app.Button = uibutton(app.panelTrait, 'push');
            app.Button.Text = '+';
            app.TextArea = uitextarea(app.panelTrait);
            app.TextArea.Editable = 'off';
            app.DropDownLabel = uilabel(app.panelTrait);
            app.DropDownLabel.Text = ':';
            app.DropDown = uidropdown(app.panelTrait);
            app.DropDown.Items = {};
            app.DropDown.Value = {};
            app.panelExport = uipanel(app.BINDGUIFigure);
            app.panelExport.Enable = 'off';
            app.panelExport.Visible = 'off';
            app.le_donnees = uilabel(app.panelExport);
            app.le_donnees.Text = '① Sélectionnez les données à ajouter à votre fichier :';
            app.elbType = uilistbox(app.panelExport);
            app.elbType.Items = {'data', 'event', 'situation'};
            app.elbType.ValueChangedFcn = createCallbackFcn(app, @elbType_ValueChanged, true);
            app.elbType.Value = {};
            app.elbTable = uilistbox(app.panelExport);
            app.elbTable.Items = {};
            app.elbTable.Multiselect = 'on';
            app.elbTable.ValueChangedFcn = createCallbackFcn(app, @elbTable_ValueChanged, true);
            app.elbTable.Value = {};
            app.elbVar = uilistbox(app.panelExport);
            app.elbVar.Items = {};
            app.elbVar.Multiselect = 'on';
            app.elbVar.Value = {};
            app.ebAjouter = uibutton(app.panelExport, 'push');
            app.ebAjouter.ButtonPushedFcn = createCallbackFcn(app, @ebAjouter_ButtonPushed, true);
            app.ebAjouter.Text = '⇩   Ajouter ces données   ⇩';
            app.elbCol = uilistbox(app.panelExport);
            app.elbCol.Items = {};
            app.elbCol.Value = {};
            app.le_fichier = uilabel(app.panelExport);
            app.le_fichier.Text = '② Votre fichier :';
            app.ebUp = uibutton(app.panelExport, 'push');
            app.ebUp.ButtonPushedFcn = createCallbackFcn(app, @ebUp_ButtonPushed, true);
            app.ebUp.Text = '▲';
            app.ebDown = uibutton(app.panelExport, 'push');
            app.ebDown.ButtonPushedFcn = createCallbackFcn(app, @ebDown_ButtonPushed, true);
            app.ebDown.Text = '▼';
            app.ebDelete = uibutton(app.panelExport, 'push');
            app.ebDelete.ButtonPushedFcn = createCallbackFcn(app, @ebDelete_ButtonPushed, true);
            app.ebDelete.Text = '❌';
            app.ls_situation = uilabel(app.panelExport);
            app.ls_situation.Text = '(③) Sélectionnez vos situations :';
            app.elbSituation = uilistbox(app.panelExport);
            app.elbSituation.Items = {};
            app.elbSituation.Multiselect = 'on';
            app.elbSituation.Value = {};
            app.le_sousEchantillon = uilabel(app.panelExport);
            app.le_sousEchantillon.Text = 'Sous-échantillonage :';
            app.espnSousEchantillon = uispinner(app.panelExport);
            app.espnSousEchantillon.Limits = [1 Inf];
            app.espnSousEchantillon.ValueDisplayFormat = '%.0f';
            app.espnSousEchantillon.Value = 1;
            app.ebPlus = uibutton(app.panelExport, 'push');
            app.ebPlus.ButtonPushedFcn = createCallbackFcn(app, @ebPlus_ButtonPushed, true);
            app.ebPlus.Text = '+';
            app.elbFichier = uilistbox(app.panelExport);
            app.elbFichier.Items = {};
            app.elbFichier.Multiselect = 'on';
            app.elbFichier.ValueChangedFcn = createCallbackFcn(app, @elbFichier_ValueChanged, true);
            app.elbFichier.Value = {};
            app.le_fichiers = uilabel(app.panelExport);
            app.le_fichiers.Text = '④ Vos fichiers :';
            app.ebExporter = uibutton(app.panelExport, 'push');
            app.ebExporter.ButtonPushedFcn = createCallbackFcn(app, @ebExporter_ButtonPushed, true);
            app.ebExporter.Text = 'Exporter Trip réf';
            app.ebExporterTous = uibutton(app.panelExport, 'push');
            app.ebExporterTous.ButtonPushedFcn = createCallbackFcn(app, @ebExporterTous_ButtonPushed, true);
            app.ebExporterTous.Text = 'Exporter Sélection';
            app.ebAnnuler = uibutton(app.panelExport, 'push');
            app.ebAnnuler.ButtonPushedFcn = createCallbackFcn(app, @ebAnnuler_ButtonPushed, true);
            app.ebAnnuler.Text = 'Annuler';
            app.ebDestination = uibutton(app.panelExport, 'push');
            app.ebDestination.ButtonPushedFcn = createCallbackFcn(app, @ebDestination_ButtonPushed, true);
            app.ebDestination.Text = 'Destination';
            app.ebSupprimer = uibutton(app.panelExport, 'push');
            app.ebSupprimer.ButtonPushedFcn = createCallbackFcn(app, @ebSupprimer_ButtonPushed, true);
            app.ebSupprimer.Enable = 'off';
            app.ebSupprimer.Text = 'Supprimer';
            app.ebRenommer = uibutton(app.panelExport, 'push');
            app.ebRenommer.ButtonPushedFcn = createCallbackFcn(app, @ebRenommer_ButtonPushed, true);
            app.ebRenommer.Enable = 'off';
            app.ebRenommer.Text = 'Renommer';
            app.ebApercu = uibutton(app.panelExport, 'push');
            app.ebApercu.ButtonPushedFcn = createCallbackFcn(app, @ebApercu_ButtonPushed, true);
            app.ebApercu.Enable = 'off';
            app.ebApercu.Text = 'Aperçu';
            app.le_format = uilabel(app.panelExport);
            app.le_format.Text = 'Format :';
            app.eddFormat = uidropdown(app.panelExport);
            app.eddFormat.Items = {'.xlsx', '.tsv', '.csv', '.txt', '.trip'};
            app.eddFormat.Value = '.xlsx';
            app.ConcatTrip = uicheckbox(app.panelExport);
            app.ConcatTrip.Text = 'Trips';
            app.ConcatSituationEvents = uicheckbox(app.panelExport);
            app.ConcatSituationEvents.Text = 'Situation/Events';
            app.ConcatnationLabel = uilabel(app.panelExport);
            app.ConcatnationLabel.Text = 'Concaténation :';
            app.Button2 = uibutton(app.panelExport, 'push');
            app.Button2.Text = 'Button2';
            app.PanelFiltrage = uipanel(app.BINDGUIFigure);
            app.PanelFiltrage.Enable = 'off';
            app.PanelFiltrage.Visible = 'off';
            app.listeFiltrage1 = uilistbox(app.PanelFiltrage);
            app.listeFiltrage1.Items = {};
            app.listeFiltrage1.ValueChangedFcn = createCallbackFcn(app, @listeFiltrageValueChanged, true);
            app.listeFiltrage1.Value = {};
            app.tableFiltrage1 = uitable(app.PanelFiltrage);
            app.tableFiltrage1.ColumnName = {'Filter'; 'Timecode'; 'Name'};
            app.tableFiltrage1.ColumnEditable = [true false false];
            app.tableFiltrage1.CellEditCallback = createCallbackFcn(app, @ignore_Value_change_table1, true);
            app.tableFiltrage1.Tooltip = {''};
            app.label = uilabel(app.PanelFiltrage);
            app.label.Text = '① Sélectionnez les événements à filtrer.';
            app.ButtonGroupFiltrage = uibuttongroup(app.PanelFiltrage);
            app.ButtonGroupFiltrage.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupFiltrageSelectionChanged, true);
            app.EventsFiltrageButton = uitogglebutton(app.ButtonGroupFiltrage);
            app.EventsFiltrageButton.Text = 'Events';
            app.EventsFiltrageButton.Value = true;
            app.SituationsFiltrageButton = uitogglebutton(app.ButtonGroupFiltrage);
            app.SituationsFiltrageButton.Text = 'Situations';
            app.AppliquerButton = uibutton(app.PanelFiltrage, 'push');
            app.AppliquerButton.ButtonPushedFcn = createCallbackFcn(app, @AppliquerButtonPushed, true);
            app.AppliquerButton.Text = 'Appliquer';
            app.listeFiltrage2 = uilistbox(app.PanelFiltrage);
            app.listeFiltrage2.Items = {};
            app.listeFiltrage2.ValueChangedFcn = createCallbackFcn(app, @listeFiltrageValueChanged, true);
            app.listeFiltrage2.Value = {};
            app.tableFiltrage2 = uitable(app.PanelFiltrage);
            app.tableFiltrage2.ColumnName = {'Filter'; 'Timecode'; 'Name'};
            app.tableFiltrage2.ColumnEditable = [true false];
            app.tableFiltrage2.CellEditCallback = createCallbackFcn(app, @ignore_Value_change_table2, true);
            app.Title_liste1 = uilabel(app.PanelFiltrage);
            app.Title_liste1.Text = 'Liste événements 1';
            app.Title_liste2 = uilabel(app.PanelFiltrage);
            app.Title_liste2.Text = 'Liste événements 2';
            app.Title_table1 = uilabel(app.PanelFiltrage);
            app.Title_table1.Text = 'Données événement 1 (seule = cas simple)';
            app.Title_table2 = uilabel(app.PanelFiltrage);
            app.Title_table2.Text = 'Données événement 2';
            app.nbpris1 = uitextarea(app.PanelFiltrage);
            app.nbpris1.Editable = 'off';
            app.nbpris2 = uitextarea(app.PanelFiltrage);
            app.nbpris2.Editable = 'off';
            app.cochertousButton = uibutton(app.PanelFiltrage, 'push');
            app.cochertousButton.ButtonPushedFcn = createCallbackFcn(app, @cochertousButtonPushed, true);
            app.cochertousButton.Text = 'cocher tous';
            app.dcochertousButton = uibutton(app.PanelFiltrage, 'push');
            app.dcochertousButton.ButtonPushedFcn = createCallbackFcn(app, @dcochertousButtonPushed, true);
            app.dcochertousButton.Text = 'décocher tous';
            app.cochertousButton_2 = uibutton(app.PanelFiltrage, 'push');
            app.cochertousButton_2.ButtonPushedFcn = createCallbackFcn(app, @cochertousButton_2Pushed, true);
            app.cochertousButton_2.Text = 'cocher tous';
            app.dcochertousButton_2 = uibutton(app.PanelFiltrage, 'push');
            app.dcochertousButton_2.ButtonPushedFcn = createCallbackFcn(app, @dcochertousButton_2Pushed, true);
            app.dcochertousButton_2.Text = 'décocher tous';
            app.PanelPluginDispos = uipanel(app.BINDGUIFigure);
            app.PanelPluginDispos.Title = 'Plugins disponnibles';
            app.ListeDispoTable = uitable(app.PanelPluginDispos);
            app.ListeDispoTable.ColumnName = {'Liste'};
            app.ListeDispoTable.RowName = {};
            app.ListBox = uilistbox(app.PanelPluginDispos);
            app.ListBox.Items = {'Magnétoscope', 'Lecteur Audio Vidéo', 'Afficher Events & Situations', 'Explorer Events & Situations', 'Vidéo synchroniseur', 'ContinentalTechnologiesViewer', 'Afficher dernier message', 'Afficher valeurs instantannées', 'Envoyer données sur le workspace', 'Envoyer données sur le réseau', 'Plotter2D (t, Y=f(t))', 'Plotter2D (t X, Y=f(X))', 'Plotter3D (X,Y,Z)', 'Tracé GPS', 'Éditer de valeurs', 'Coder d''Events'};
            app.ListBox.ItemsData = {'Magneto', 'VideoPlayer', 'EventSituationDisplay', 'EventSituationBrowser', 'VideoSynchroniser', 'ContinentalTechnologiesViewer', 'Afficher dernier message', 'Afficher valeurs instantannées', 'Envoyer données sur le workspace', 'Envoyer données sur le réseau', 'Plotter2D (tf(t))', 'Plotter2D (t Xf(X))', 'Plotter3D (XYZ)', 'Tracé GPS', 'Éditer de valeurs', 'Coder d''Events'};
            app.ListBox.ValueChangedFcn = createCallbackFcn(app, @ListBoxValueChanged, true);
            app.ListBox.Value = {};
            app.ButtonAjouter = uibutton(app.PanelPluginDispos, 'push');
            app.ButtonAjouter.Text = '+';
            app.panelVisu = uipanel(app.BINDGUIFigure);
            app.panelVisu.Title = 'Visualisation';
            app.tgVisu = uitabgroup(app.panelVisu);
            app.tabPlugins = uitab(app.tgVisu);
            app.tabPlugins.Title = ' Plugins';
            app.lbPlugins = uilistbox(app.tabPlugins);
            app.lbPlugins.Items = {};
            app.lbPlugins.Multiselect = 'on';
            app.lbPlugins.ValueChangedFcn = createCallbackFcn(app, @lbPlugins_ValueChanged, true);
            app.lbPlugins.Enable = 'off';
            app.lbPlugins.Value = {};
            app.EnregistrerenpresetButton = uibutton(app.tabPlugins, 'push');
            app.EnregistrerenpresetButton.ButtonPushedFcn = createCallbackFcn(app, @EnregistrerenpresetButtonPushed, true);
            app.EnregistrerenpresetButton.Text = 'Enregistrer en preset';
            app.EditField = uieditfield(app.tabPlugins, 'text');
            app.ChoisirunnomTextAreaLabel = uilabel(app.tabPlugins);
            app.ChoisirunnomTextAreaLabel.Text = 'Choisir un nom';
            app.ChoisirunnomTextArea = uitextarea(app.tabPlugins);
            app.ChoisirunnomTextArea.Editable = 'off';
            app.ChoisirunnomTextArea.Enable = 'off';
            app.ChoisirunnomTextArea.Visible = 'off';
            app.tabPreset = uitab(app.tgVisu);
            app.tabPreset.Title = '  Preset';
            app.VoirPreset = uibutton(app.tabPreset, 'push');
            app.VoirPreset.ButtonPushedFcn = createCallbackFcn(app, @VoirPresetButtonPushed, true);
            app.VoirPreset.Text = 'Voir tout le preset         ';
            app.lbPreset = uilistbox(app.tabPreset);
            app.lbPreset.Items = {};
            app.lbPreset.ValueChangedFcn = createCallbackFcn(app, @lbPresetValueChanged, true);
            app.lbPreset.Value = {};
            app.FermerTout = uibutton(app.tabPreset, 'push');
            app.FermerTout.ButtonPushedFcn = createCallbackFcn(app, @FermerToutButtonPushed, true);
            app.FermerTout.Text = 'Fermer tous les plugins';
            app.aLogoPict = uiaxes(app.BINDGUIFigure);
            app.aLogoPict.XTickLabel = '';
            app.aLogoPict.YTickLabel = '';
            % Show the figure after all components are created
            app.BINDGUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BIND_GUI_3onglets_PresetOKetPluginsOuvreFermeTout

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.BINDGUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.BINDGUIFigure)
        end
    end
end